classdef Comfort < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COCA_Comfort                  matlab.ui.Figure
        ISLabel                       matlab.ui.control.Label
        Slider_7                      matlab.ui.control.Slider
        Gauge                         matlab.ui.control.LinearGauge
        UpdateWeightingfactorsButton  matlab.ui.control.StateButton
        DropDownAC                    matlab.ui.control.DropDown
        DropDownHR                    matlab.ui.control.DropDown
        DropDownLR                    matlab.ui.control.DropDown
        DropDownHF                    matlab.ui.control.DropDown
        Panel_Lamps                   matlab.ui.container.Panel
        Label_V3                      matlab.ui.control.Label
        Label_V2                      matlab.ui.control.Label
        Label_V1                      matlab.ui.control.Label
        Lamp_V3                       matlab.ui.control.Lamp
        Lamp_V2                       matlab.ui.control.Lamp
        Lamp_V1                       matlab.ui.control.Lamp
        LabelAC                       matlab.ui.control.Label
        EditField_AC_2                matlab.ui.control.NumericEditField
        SliderButton_Acoustics        matlab.ui.control.Button
        EditField_AC                  matlab.ui.control.NumericEditField
        Slider_AC                     matlab.ui.control.Slider
        ResetButton                   matlab.ui.control.Button
        SaveBackButton                matlab.ui.control.Button
        GoBackButton                  matlab.ui.control.Button
        Total_Value_Comfort           matlab.ui.control.NumericEditField
        LabelHR                       matlab.ui.control.Label
        LabelLR                       matlab.ui.control.Label
        LabelHF                       matlab.ui.control.Label
        EditField_HR_2                matlab.ui.control.NumericEditField
        EditField_LR_2                matlab.ui.control.NumericEditField
        EditField_HF_2                matlab.ui.control.NumericEditField
        ComfortSHOULDLabel            matlab.ui.control.Label
        EditField_7                   matlab.ui.control.NumericEditField
        SliderButton_Headroom_r       matlab.ui.control.Button
        SliderButton_Legroom          matlab.ui.control.Button
        SliderButton_Headroom_f       matlab.ui.control.Button
        EditField_HR                  matlab.ui.control.NumericEditField
        EditField_LR                  matlab.ui.control.NumericEditField
        EditField_HF                  matlab.ui.control.NumericEditField
        ComfortLabel                  matlab.ui.control.Label
        Slider_HR                     matlab.ui.control.Slider
        Slider_LR                     matlab.ui.control.Slider
        Slider_HF                     matlab.ui.control.Slider
        Spider_UIAxes                 matlab.ui.control.UIAxes
    end

     properties (Access = private)
        CorrelationApp   % Correlation
        SLabel
        SValue
     end
     
     properties (Access = public)
        EV
        NEV
        ComparisonVehicle_1
        ComparisonVehicle_2
        ComparisonVehicle_3
        Slider7
        Value
        Label
        Pattern
        ResetPattern
        Weightings
    end

methods (Access = public)

    function UpdatefromCorrelation(app, SValue, SLabel, Pattern,ResetPattern)
     %%%Description: This function retrieves the values changed/corrected in the Correlation app and updates the spider diagramm!
     
     Unblock(app);
     
        if SValue == -1
            %It was not requested to update the values!
                        
        elseif isequal(SLabel,app.SliderButton_Headroom_f.Text)
            app.Slider_HF.Value = SValue;
            runSpiderAPP(app)
            app.EditField_HF.Value = app.Slider_HF.Value;
            app.EditField_HF_2.Value = app.Slider_HF.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
            
        elseif isequal(SLabel,app.SliderButton_Legroom.Text)
            app.Slider_LR.Value = SValue;
            runSpiderAPP(app)
            app.EditField_LR.Value = app.Slider_LR.Value;
            app.EditField_LR_2.Value = app.Slider_LR.Value;
            Weighting_Pattern(app); 
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
            
        elseif isequal(SLabel,app.SliderButton_Headroom_r.Text)
            app.Slider_HR.Value = SValue;
            runSpiderAPP(app)
            app.EditField_HR.Value = app.Slider_HR.Value;
            app.EditField_HR_2.Value = app.Slider_HR.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
            
        elseif isequal(SLabel,app.SliderButton_Acoustics.Text)
            app.Slider_AC.Value = SValue;
            runSpiderAPP(app)
            app.EditField_AC.Value = app.Slider_AC.Value;
            app.EditField_AC_2.Value = app.Slider_AC.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
                        
        end
        
    end
    
end

    methods (Access = private)
    
function  SaveComfort(app)
%%%Description: Function to save the results of the properties evaluation
    
    app.Pattern(2,3) = app.Slider_HF.Value; %Headroom front
    app.Pattern(3,6) = app.Slider_HF.Value; %Set Headroom front also for safety (where it is required as a property)        
    app.Pattern(3,3) = app.Slider_LR.Value;           
    app.Pattern(4,3) = app.Slider_HR.Value; %Headroom rear
    app.Pattern(4,6) = app.Slider_HR.Value; %Set Headroom rear also for safety (where it is required as a property)
    app.Pattern(5,3) = app.Slider_AC.Value; %Acoustics
    app.Pattern(1,3) = app.Slider_7.Value; %Total value for "COmfort"

end
        
function Block(app)
%%%%Description: Function to block some components during the weighting factors update
    
    app.Slider_HF.Enable = 'off';
    app.Slider_LR.Enable = 'off';
    app.Slider_HR.Enable = 'off';
    app.Slider_AC.Enable = 'off';
    app.Slider_7.Enable = 'off';
    
    app.SliderButton_Headroom_f.Enable = 'off';
    app.SliderButton_Legroom.Enable = 'off';
    app.SliderButton_Headroom_r.Enable = 'off';
    app.SliderButton_Acoustics.Enable = 'off';
    app.GoBackButton.Enable = 'off';
    app.SaveBackButton.Enable = 'off';
    app.ResetButton.Enable = 'off';

end
        
function Unblock(app)
%%%%Description: Function to unblock some components during the weighting factors update

    app.Slider_HF.Enable = 'on';
    app.Slider_LR.Enable = 'on';
    app.Slider_HR.Enable = 'on';
    app.Slider_AC.Enable = 'on';
    app.Slider_7.Enable = 'on';
    
    app.SliderButton_Headroom_f.Enable = 'on';
    app.SliderButton_Legroom.Enable = 'on';
    app.SliderButton_Headroom_r.Enable = 'on';
    app.SliderButton_Acoustics.Enable = 'on';
    app.GoBackButton.Enable = 'on';
    app.SaveBackButton.Enable = 'on';
    app.ResetButton.Enable = 'on';

end
        
function NameLable(app)
%%% Description: This function regulates the Label name of the Update Weighting Paneö
%% Authors: Lorenzo Nicoletti, Maximilian Bayerlein
            
    W = app.Weightings{3,2};
    
    app.LabelHF.Text = {['Headroom front x ', num2str(W(1,1))]};
    app.LabelLR.Text = {['+ Legroom rear x ', num2str(W(1,2))]};
    app.LabelHR.Text = {['+ Headroom rear x ', num2str(W(1,3))]};
    app.LabelAC.Text = {['+ Acoustics x ', num2str(W(1,4))]};
    
end

function Weighting_Pattern(app)
%%% Description: This function simply initializes the missing GUI Labels
    
    %Retrieve the values of the Gauge (if they have been changed)
    V = [app.Slider_HF.Value;app.Slider_LR.Value;app.Slider_HR.Value;app.Slider_AC.Value];  % Wertevektor
    
    %Recalculate the resulting total value for the "Comfort" properties
    W = app.Weightings{3,2};
    app.Total_Value_Comfort.Value = (W*V)/sum(W);
    app.Gauge.Value =  app.Total_Value_Comfort.Value;
        
end
        
        
function Lamps(app)
    %%% Description: This function regulates the Lamp colors for the "Compare Vehicle" Panels
    %% Authors: Lorenzo Nicoletti, Maximilian Bayerlein
    
    if ~isequal(app.ComparisonVehicle_1,'Please choose a vehicle')     
        
%         app.Lamp_V1.Color = [0 1 1];
        app.Lamp_V1.Color = [0 1 1];
        app.Label_V1.Text=app.ComparisonVehicle_1;
    end
    
    if ~isequal(app.ComparisonVehicle_2,'Please choose a vehicle')
       
%         app.Lamp_V2.Color = [0 1 0]; 
        app.Lamp_V2.Color = [227 114 34]./255; 
        app.Label_V2.Text=app.ComparisonVehicle_2;
    end
    
    if ~isequal(app.ComparisonVehicle_3,'Please choose a vehicle')
        
%         app.Lamp_V3.Color = [1 1 0];       
        app.Lamp_V3.Color = [152 198 234]./255;  
        app.Label_V3.Text=app.ComparisonVehicle_3;
    end      
           
end

function [data, lbl]  = runSpiderAPP(app)
%%% Description: This function Plots the spider Diagram in the Gui for the choosen vehicle concept and the chosen comparion vehicles
%% Author: Lorenzo Nicoletti, Maximilian Bayerlein

    num=app.EV;
    
    % Retrieve the labels for the spider diagramm -> the second level properties for the first level property "COSTS"
    idx=find(ismember(num(:,1),'Comfort'));
    idx_start=idx+1;
    lbl = [num(idx_start,1),num(idx_start+1,1),num(idx_start+2,1),num(idx_start+3,1)]';
   
    % The values for the spider diagramm
    A = [app.Slider_HF.Value app.Slider_LR.Value app.Slider_HR.Value app.Slider_AC.Value]';

    %If any Compare vehicles are chosen, retrieve their value
    [V1,V2,V3, app] = Compare_Vehicles(app);
    data =  [V1 V2 V3 A];
    
    % plot spider diagramm
    ax = app.Spider_UIAxes;
    hold on
    spiderAPP(data,lbl,ax)

end

function [V1, V2, V3, app] = Compare_Vehicles(app)
%%% Description: This function adds the plots of the Comparison vehicles on the spider Diagramm
%Author: Lorenzo Nicoletti, Maximilian Bayerlein

    num=app.EV;

    %Find the position of the evaluated subproperties of the property "COSTS"
    idx=find(ismember(num(:,1),'Comfort'));
    idx_start=idx+1;
    idx_end=idx_start+numel(app.Weightings{3,2})-1;

    %Retrieve the data for Comparision vehicle number 1
    for ii = 4:size(num,2)
    
        if isequal(app.ComparisonVehicle_1, 'Please choose a vehicle')
            
            V1 = [4 4 4 4]';
            break;
        
        elseif isequal(app.ComparisonVehicle_1, num{1,ii})
            
            V1 = cell2mat(num(idx_start:idx_end,ii));
            break; 
        end
    end

    %Retrieve the data for Comparision vehicle number 2
    for ii = 4:size(num,2)
    
        if isequal(app.ComparisonVehicle_2, 'Please choose a vehicle')
            
            V2 = [4 4 4 4]';
            break;
        
        elseif isequal(app.ComparisonVehicle_2, num{1,ii})
            
            V2 = cell2mat(num(idx_start:idx_end,ii));
            break;
            
        end
    end

    %Retrieve the data for Comparision vehicle number 3
    for ii = 4:size(num,2)
        
        if isequal(app.ComparisonVehicle_3, 'Please choose a vehicle')
            
            V3 = [4 4 4 4]';
            break;
            
        elseif isequal(app.ComparisonVehicle_3, num{1,ii})
            
            V3 = cell2mat(num(idx_start:idx_end,ii));
            break;
        end
    end
end 

function UpdateWeightingFactors(app)
%%%Description:This function allows to change the weighting factors of the second level properties of the first level property "COSTS"
%Author: Lorenzo Nicoletti, Maximilian Bayerlein

    %Assign the possible drop down values for the weighting factors
    app.DropDownHF.ItemsData = [1, 3, 4, 5, 9];
    app.DropDownHF.Items = {'1', '3', '4', '5', '9'};
    app.DropDownLR.ItemsData = [1, 3, 4, 5, 9];
    app.DropDownLR.Items = {'1', '3', '4', '5', '9'};
    app.DropDownHR.ItemsData = [1, 3, 4, 5, 9];
    app.DropDownHR.Items = {'1', '3', '4', '5', '9'};
    app.DropDownAC.ItemsData = [1, 3, 4, 5, 9];
    app.DropDownAC.Items = {'1', '3', '4', '5', '9'};

    if app.UpdateWeightingfactorsButton.Value == 0
    
        app.DropDownHF.Visible = 'off';
        app.DropDownLR.Visible = 'off';
        app.DropDownHR.Visible = 'off';
        app.DropDownAC.Visible = 'off';
        
        %Update the weighting factors
        app.Weightings{3,2} = [app.DropDownHF.Value, app.DropDownLR.Value, app.DropDownHR.Value, app.DropDownAC.Value];
        
        %Update the Labels
        NameLable(app);
        
        %Reevaluate the custormer properties with the new weighting factors
        Weighting_Pattern(app);
        
        %Unblock the components blocked before (the first time the button was pressed)
        Unblock(app); 
    
    elseif app.UpdateWeightingfactorsButton.Value == 1
    
        app.DropDownHF.Visible = 'on';
        app.DropDownLR.Visible = 'on';
        app.DropDownHR.Visible = 'on';
        app.DropDownAC.Visible = 'on';
        
        %Update the Labels with the new weighting factors
        app.LabelHF.Text = {'Headroom front x '};
        app.LabelLR.Text = {'+ Legroom rear x '};
        app.LabelHR.Text = {'+ Headroom rear x '};
        app.LabelAC.Text = {'+ Acoustics x '};
        
        %Block the components which are not required -> The User can therefore only change the weighting factors
        Block(app);    
    end
end

end %End of methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Start(app, EV, NEV, ComparisonVehicle_1, ComparisonVehicle_2, ComparisonVehicle_3, Pattern, Weightings)

        %%%%Description this function will be exectued just after the GUI "COSTS is created. The function takes the values passed by Mainapp
        %and sets Slides and edit fields accordingly to what was chosen in Mainapp
        %Author: Lorenzo Nicoletti, Maximilian Bayerlein
            
            app.EV = EV;
            app.NEV= NEV;
            app.ComparisonVehicle_1 = ComparisonVehicle_1;
            app.ComparisonVehicle_2 = ComparisonVehicle_2;
            app.ComparisonVehicle_3 = ComparisonVehicle_3;
            app.Pattern = Pattern;
            app.Weightings = Weightings;
            app.ResetPattern=Pattern;

            %Set Slider Values 
            app.Slider_HF.Value = app.Pattern(2,3); %Headroom front
            app.Slider_LR.Value = app.Pattern(3,3); %Legroom rear
            app.Slider_HR.Value = app.Pattern(4,3); %Headroom rear
            app.Slider_AC.Value = app.Pattern(5,3); %Acoustics
            app.Slider_7.Value = app.Pattern(1,3); %Total

            %Set Corresponding Edit Field Values
            app.EditField_HF.Value = app.Slider_HF.Value;
            app.EditField_LR.Value = app.Slider_LR.Value;
            app.EditField_HR.Value = app.Slider_HR.Value;
            app.EditField_AC.Value = app.Slider_AC.Value;
            app.EditField_7.Value = app.Slider_7.Value;
            app.EditField_HF_2.Value = app.Slider_HF.Value;
            app.EditField_LR_2.Value = app.Slider_LR.Value;
            app.EditField_HR_2.Value = app.Slider_HR.Value;
            app.EditField_AC_2.Value = app.Slider_AC.Value;

            %Assign missing Labels
            NameLable(app);
            
            %Runs Spider app and generate Spider Diagramm
            runSpiderAPP(app);
            
            %Weight the Properties
            Weighting_Pattern(app);      
            
            %Set the Lamp colors according to the number of selected vehicles
            Lamps(app);

            %Initialize the Weighting and the Drop down Values of the Panel "Update Weighting Factors"
            app.DropDownHF.ItemsData = [1, 3, 4, 5, 9];
            app.DropDownHF.Items = {'1', '3', '4', '5', '9'};
            app.DropDownLR.ItemsData = [1, 3, 4, 5, 9];
            app.DropDownLR.Items = {'1', '3', '4', '5', '9'};
            app.DropDownHR.ItemsData = [1, 3, 4, 5, 9];
            app.DropDownHR.Items = {'1', '3', '4', '5', '9'};
            app.DropDownAC.ItemsData = [1, 3, 4, 5, 9];
            app.DropDownAC.Items = {'1', '3', '4', '5', '9'};

            W = app.Weightings{3,2};
            app.DropDownHF.Value = W(1); app.DropDownLR.Value = W(2); app.DropDownHR.Value = W(3); app.DropDownAC.Value = W(4); 
            
            UpdateWeightingFactors(app);
            
        end

        % Value changed function: EditField_HF, Slider_HF
        function Slider_1ValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_HF.Value = app.Slider_HF.Value;
            app.EditField_HF_2.Value = app.Slider_HF.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_LR
        function Slider_LRValueChanged(app, event)
            if app.Slider_LR.Value == 4
                app.Slider_HR.Value = 4;
                app.Pattern(4,4) = 5.1;
            elseif app.Slider_LR.Value ~= 4 && app.Slider_HR.Value == 4
                app.Slider_HR.Value = 4.37;
                app.Pattern(4,4) = 5.2;
            end
            runSpiderAPP(app)
            app.EditField_LR.Value = app.Slider_LR.Value;
            app.EditField_LR_2.Value = app.Slider_LR.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_HR
        function Slider_HRValueChanged(app, event)
            if app.Slider_HR.Value == 4
                app.Slider_LR.Value = 4;
                app.Pattern(4,4) = 5.1;
            elseif app.Slider_HR.Value ~= 4 && app.Slider_LR.Value == 4
                app.Slider_LR.Value = 4.21;
                app.Pattern(4,4) = 5.2;
            end
            runSpiderAPP(app)
            app.EditField_HR.Value = app.Slider_HR.Value;
            app.EditField_HR_2.Value = app.Slider_HR.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_7.Value = app.Slider_7.Value;                       
        end

        % Button pushed function: SliderButton_Headroom_f
        function SliderButton_Headroom_fPushed(app, event)
            app.Value = app.Slider_HF.Value;
            app.Label = app.SliderButton_Headroom_f.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: SliderButton_Legroom
        function SliderButton_LegroomPushed(app, event)
            app.Value = app.Slider_LR.Value;
            app.Label = app.SliderButton_Legroom.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: SliderButton_Headroom_r
        function SliderButton_Headroom_rPushed(app, event)
            app.Value = app.Slider_HR.Value;
            app.Label = app.SliderButton_Headroom_r.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);  
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            Mainapp(app.Pattern, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Weightings);
            delete(app.CorrelationApp);
            delete(app);
        end

        % Button pushed function: SaveBackButton
        function SaveBackButtonPushed(app, event)
            SaveComfort(app);
            Mainapp(app.Pattern, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Weightings);
            delete(app.CorrelationApp);
            delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            app.Slider_HF.Value = app.ResetPattern(2,3);
            app.Slider_LR.Value = app.ResetPattern(3,3);
            app.Slider_HR.Value = app.ResetPattern(4,3);
            app.Slider_AC.Value = app.ResetPattern(5,3);
            app.Slider_7.Value = app.ResetPattern(1,3);
            app.Pattern=app.ResetPattern;
            app.Gauge.Value=app.Pattern(1,3);
            app.Total_Value_Comfort.Value=app.Pattern(1,3);
            
            app.EditField_HF.Value = app.Slider_HF.Value;
            app.EditField_LR.Value = app.Slider_LR.Value;
            app.EditField_HR.Value = app.Slider_HR.Value;
            app.EditField_AC.Value = app.Slider_AC.Value;
            app.EditField_7.Value = app.Slider_7.Value;
            
            app.EditField_HF_2.Value = app.Slider_HF.Value;
            app.EditField_LR_2.Value = app.Slider_LR.Value;
            app.EditField_HR_2.Value = app.Slider_HR.Value;  
            app.EditField_AC_2.Value = app.Slider_AC.Value;  
            
            runSpiderAPP(app);
            Weighting_Pattern(app);
        end

        % Close request function: COCA_Comfort
        function COCA_ComfortCloseRequest(app, event)
            delete(app.CorrelationApp);
            delete(app);           
        end

        % Value changed function: Slider_AC
        function Slider_ACValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_AC.Value = app.Slider_AC.Value;
            app.EditField_AC_2.Value = app.Slider_AC.Value;
            Weighting_Pattern(app);
        end

        % Button pushed function: SliderButton_Acoustics
        function SliderButton_AcousticsPushed(app, event)
            app.Value = app.Slider_AC.Value;
            app.Label = app.SliderButton_Acoustics.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);          
        end

        % Value changed function: UpdateWeightingfactorsButton
        function UpdateWeightingfactorsButtonValueChanged(app, event)
UpdateWeightingFactors(app);             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COCA_Comfort = uifigure('Visible', 'off');
            app.COCA_Comfort.Name = 'COCA - Comfort';
            app.COCA_Comfort.CloseRequestFcn = createCallbackFcn(app, @COCA_ComfortCloseRequest, true);
            app.Spider_UIAxes = uiaxes(app.COCA_Comfort);
            title(app.Spider_UIAxes, 'Title')
            xlabel(app.Spider_UIAxes, 'X')
            ylabel(app.Spider_UIAxes, 'Y')
            app.Spider_UIAxes.XLim = [0 1];
            app.Spider_UIAxes.YLim = [0 1];
            app.Spider_UIAxes.ZLim = [0 1];
            app.Spider_UIAxes.CLim = [0 1];
            app.Spider_UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Spider_UIAxes.YTick = [0 0.5 1];
            app.Spider_UIAxes.ZTick = [0 0.5 1];
            app.Slider_HF = uislider(app.COCA_Comfort);
            app.Slider_HF.Limits = [4 10];
            app.Slider_HF.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_HF.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.Slider_HF.Value = 7.5;
            app.Slider_LR = uislider(app.COCA_Comfort);
            app.Slider_LR.Limits = [4 10];
            app.Slider_LR.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_LR.ValueChangedFcn = createCallbackFcn(app, @Slider_LRValueChanged, true);
            app.Slider_LR.Value = 7.5;
            app.Slider_HR = uislider(app.COCA_Comfort);
            app.Slider_HR.Limits = [4 10];
            app.Slider_HR.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_HR.ValueChangedFcn = createCallbackFcn(app, @Slider_HRValueChanged, true);
            app.Slider_HR.Value = 7.5;
            app.ComfortLabel = uilabel(app.COCA_Comfort);
            app.ComfortLabel.Text = 'Comfort';
            app.EditField_HF = uieditfield(app.COCA_Comfort, 'numeric');
            app.EditField_HF.ValueDisplayFormat = '%2.1f';
            app.EditField_HF.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.EditField_HF.Editable = 'off';
            app.EditField_LR = uieditfield(app.COCA_Comfort, 'numeric');
            app.EditField_LR.ValueDisplayFormat = '%2.1f';
            app.EditField_LR.Editable = 'off';
            app.EditField_HR = uieditfield(app.COCA_Comfort, 'numeric');
            app.EditField_HR.ValueDisplayFormat = '%2.1f';
            app.EditField_HR.Editable = 'off';
            app.SliderButton_Headroom_f = uibutton(app.COCA_Comfort, 'push');
            app.SliderButton_Headroom_f.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_Headroom_fPushed, true);
            app.SliderButton_Headroom_f.Text = 'Headroom front';
            app.SliderButton_Legroom = uibutton(app.COCA_Comfort, 'push');
            app.SliderButton_Legroom.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_LegroomPushed, true);
            app.SliderButton_Legroom.Text = 'Legroom rear';
            app.SliderButton_Headroom_r = uibutton(app.COCA_Comfort, 'push');
            app.SliderButton_Headroom_r.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_Headroom_rPushed, true);
            app.SliderButton_Headroom_r.Text = 'Headroom rear';
            app.EditField_7 = uieditfield(app.COCA_Comfort, 'numeric');
            app.EditField_7.ValueDisplayFormat = '%2.1f';
            app.EditField_7.Editable = 'off';
            app.ComfortSHOULDLabel = uilabel(app.COCA_Comfort);
            app.ComfortSHOULDLabel.Text = 'Comfort - SHOULD';
            app.EditField_HF_2 = uieditfield(app.COCA_Comfort, 'numeric');
            app.EditField_HF_2.ValueDisplayFormat = '%2.1f';
            app.EditField_HF_2.Editable = 'off';
            app.EditField_LR_2 = uieditfield(app.COCA_Comfort, 'numeric');
            app.EditField_LR_2.ValueDisplayFormat = '%2.1f';
            app.EditField_LR_2.Editable = 'off';
            app.EditField_HR_2 = uieditfield(app.COCA_Comfort, 'numeric');
            app.EditField_HR_2.ValueDisplayFormat = '%2.1f';
            app.EditField_HR_2.Editable = 'off';
            app.LabelHF = uilabel(app.COCA_Comfort);
            app.LabelHF.Text = 'Label2';
            app.LabelLR = uilabel(app.COCA_Comfort);
            app.LabelLR.Text = 'Label2';
            app.LabelHR = uilabel(app.COCA_Comfort);
            app.LabelHR.Text = 'Label2';
            app.Total_Value_Comfort = uieditfield(app.COCA_Comfort, 'numeric');
            app.Total_Value_Comfort.ValueDisplayFormat = '%2.2f';
            app.Total_Value_Comfort.Editable = 'off';
            app.GoBackButton = uibutton(app.COCA_Comfort, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.SaveBackButton = uibutton(app.COCA_Comfort, 'push');
            app.SaveBackButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBackButtonPushed, true);
            app.SaveBackButton.Text = 'Save & Back';
            app.ResetButton = uibutton(app.COCA_Comfort, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Slider_AC = uislider(app.COCA_Comfort);
            app.Slider_AC.Limits = [4 10];
            app.Slider_AC.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_AC.ValueChangedFcn = createCallbackFcn(app, @Slider_ACValueChanged, true);
            app.Slider_AC.Value = 7.5;
            app.EditField_AC = uieditfield(app.COCA_Comfort, 'numeric');
            app.EditField_AC.ValueDisplayFormat = '%2.1f';
            app.EditField_AC.Editable = 'off';
            app.SliderButton_Acoustics = uibutton(app.COCA_Comfort, 'push');
            app.SliderButton_Acoustics.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_AcousticsPushed, true);
            app.SliderButton_Acoustics.Text = 'Acoustics';
            app.EditField_AC_2 = uieditfield(app.COCA_Comfort, 'numeric');
            app.EditField_AC_2.ValueDisplayFormat = '%2.1f';
            app.EditField_AC_2.Editable = 'off';
            app.LabelAC = uilabel(app.COCA_Comfort);
            app.LabelAC.Text = 'Label2';
            app.Panel_Lamps = uipanel(app.COCA_Comfort);
            app.Lamp_V1 = uilamp(app.Panel_Lamps);
            app.Lamp_V2 = uilamp(app.Panel_Lamps);
            app.Lamp_V3 = uilamp(app.Panel_Lamps);
            app.Label_V1 = uilabel(app.Panel_Lamps);
            app.Label_V1.Text = 'No vehicle chosen';
            app.Label_V2 = uilabel(app.Panel_Lamps);
            app.Label_V2.Text = 'No vehicle chosen';
            app.Label_V3 = uilabel(app.Panel_Lamps);
            app.Label_V3.Text = 'No vehicle chosen';
            app.DropDownHF = uidropdown(app.COCA_Comfort);
            app.DropDownLR = uidropdown(app.COCA_Comfort);
            app.DropDownHR = uidropdown(app.COCA_Comfort);
            app.DropDownAC = uidropdown(app.COCA_Comfort);
            app.UpdateWeightingfactorsButton = uibutton(app.COCA_Comfort, 'state');
            app.UpdateWeightingfactorsButton.ValueChangedFcn = createCallbackFcn(app, @UpdateWeightingfactorsButtonValueChanged, true);
            app.UpdateWeightingfactorsButton.Text = 'Update Weighting';
            app.Gauge = uigauge(app.COCA_Comfort, 'linear');
            app.Gauge.Limits = [4 10];
            app.Gauge.Value = 4;
            app.Slider_7 = uislider(app.COCA_Comfort);
            app.Slider_7.Limits = [4 10];
            app.Slider_7.MajorTicks = [];
            app.Slider_7.MajorTickLabels = {};
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.Slider_7.MinorTicks = [];
            app.Slider_7.Value = 7.5;
            app.ISLabel = uilabel(app.COCA_Comfort);
            app.ISLabel.Text = '- IS';
            % Show the figure after all components are created
            app.COCA_Comfort.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Comfort(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COCA_Comfort)

            % Execute the startup function
            runStartupFcn(app, @(app)Start(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COCA_Comfort)
        end
    end
end