classdef Correlation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COCACorrelationUIFigure  matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        Slider                   matlab.ui.control.Slider
        EditField                matlab.ui.control.NumericEditField
        Property                 matlab.ui.control.Label
        Label2                   matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        SaveCloseButton          matlab.ui.control.Button
        EditField_2              matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        CallingApp   % Main app
    end
    
    properties (Access = public)
        EV
        NEV
        ResetPattern
        ComparisonV1
        ComparisonV2
        ComparisonV3  
        Value
        Label
        Switch
        Pattern
        TDmin
        TDmax
        saveBF
    
    end
    
methods (Access = private)
        
    function plotter(app, num, ComparisonV1, ComparisonV2, ComparisonV3, Value, Label, Switch, Pattern)
    %%%% Description: This function plots the S curve for the chosen second level property.
    %% Author: Lorenzo Nicoletti, Maximilian bayerlein
    %Implementation:
    %1) Initialize the required Variables
    %2) Calculate the Values for the Comparison vehicles (to plot on the S-curve)
    %3) Calculate the Values for the chosen vehicle -> ENables generating the S-curve
    %4) If the property "number of seats" is choosen, a separate method has to be used for the calculation of the S curve
    %5) Calculate further dimensions of the vehicle (required for later)
    %6) Calculate the minimum possible Legroom (if the property "Legroom rear is selected)
    %7) Calculate the minimum possible Legroom (if the property "Headroom rear" is selected)
    %8) Calculate the maximum possible trunk volume (if the property "trunk volume" is selected)
    %9) Calculate the maximum possible number of seats: considering the limitations of the trunk volume
    %10) Calculate the overhang rear: considering the limitations of the trunk volume
    %11) Calculate the wheelbase: considering the limitations of the trunk volume 
    %12) Calculate the Ratio Heigth/Width: considering the limitations of the trunk volume         
    %13) Calculate the Ratio Length/Heigth: considering the limitations of the trunk volume     
    %14) Calculate the Wheel diameter: considering the limitations of the trunk volume     
    %15) Calculate the overhang front: considering the limitations of the trunk volume     
    %16) Plot 
        
    %NEV contains the data of the Excel Table for the Custormer Properties (not evalueted, i.e. not in the range from 4 to 10)
    NEV=app.NEV;
    NEV{1,2}='';
    NEV{1,3}='';

    % Load the Variables required to calculate the S-Curve
    [Wiki, ~] = wiki();             %Load Custormer Property List

    idx=find(ismember(Wiki(:,1),Label));    
    PropName = Wiki{idx,1};                 %Property Name
    Unit = Wiki{idx,2};                     %Property Unit
    G = cell2mat(Wiki(idx,4:5));            %Property Range values
    s = Wiki{idx,end};                      %Property slope 

    % Set figure Labels
    f = app.UIAxes;
    title(f,['Correlation function for Property ', PropName]);
    xlabel(f,[PropName, ' in ', Unit]);
    f.YLabel.String = 'Custormer value';
    app.Property.Text=PropName;
    
    %...............................................................
    %% 2) Calculate the Values for the Comparison vehicles (to plot on the S-curve)
    %Variable to store the results of the comparison vehicles
    ComparisonV = {ComparisonV1, 0, 0;ComparisonV2, 0, 0;ComparisonV3, 0, 0};

    %Store in COmparisonV the Value of the Property (not evaluated)           
    for i=1:3
        
        if ~isequal(ComparisonV{i,1}, 'Please choose a vehicle')
        
            col=find(ismember(NEV(1,:),ComparisonV{i,1}));
            row=find(ismember(NEV(:,1), PropName));
            ComparisonV{i,2}=NEV{row,col};
            ComparisonV{i,3}=num{row,col};
    
            %The cost have to be divided by 1000 in order for the model to work 
            if isequal(PropName,'Asset Costs')
            
                    ComparisonV{i,2} = ComparisonV{i,2}/1000; %in 1000 euros
    
            end
        end
    end 
        
    % Initialize Variable for the S-curve calculation
    go = 10;                        % upper limit
    gu = 4;                         % lower limit
    g1 = G(1,1);
    g2 = G(1,2);
    w = (g1-g2)/2;
    s = s*(100/(g1-g2))*0.12;
    X = linspace(g2*0.8,g1*1.2,200);
    
    %...............................................................
    %% 3) Calculate the Values for the chosen vehicle -> ENables generating the S-curve
    
    %Value: The value from 4 to 10 for the chosen property
    %TD: The corresponding Technical data, for example Legroom in mm
    
    %Calculate the S Curve
    Y=(go-gu)./(1+exp(-s.*(X-w-g2)))+gu;
    
    %Calculate TD and Value:
    if Switch == 0 %The User did not change the value of the property-> Calculate TD from Value
        
        % Funktion Pattern, Eingang: KW
        TD = (log((go-gu)/(Value-gu)-1)/-s)+w+g2;
    
    else %The User changed The value of the property -> for example he entered a new weight -> Calculate Value from TD
        
        %New Technical specification given by the User for the property
        TD = app.EditField.Value;
        
        %Calculate corresponding Value from 4 to 10
        Value = (go-gu)/(1+exp(-s*(TD-w-g2)))+gu;
       
        if isequal(Label,'Number of seats') %Only for the Property number of seats the range has to be corrected
            switch TD   %num of seat of the vehicle contained in the selected col of EV
                case 9
                    Value=10;
                case 8
                    Value=9;
                case 7
                    Value=8;
                case 5
                    Value=7;
                case 4
                    Value=6;
                case 2
                    Value=5;
                case 1
                    Value=4;
            end
        end        
    end
    
    %...............................................................
    %% 4) If the property "number of seats" is choosen, a separate method has to be used for the calculation of the S curve
    % The resulting TD may not be an integer, it has to be corrected (a number of seats of 5.4 does not make sense!)
    %Furthermore, the S-curve has to be corrected as well, following the sepcial range for the property "number of seats"
    
    if isequal(Label,'Number of seats')
        
        Y_seats = linspace(4,10,200);
        Y_seats(1,201) = Value;
        X_seats=zeros(1,201);
     
        for zz = 1:201
            
            if Y_seats(zz) <= 4.3
                X_seats(zz) = 1;
                
            elseif Y_seats(zz) <= 5.6    
                X_seats(zz) = 2;
                
            elseif Y_seats(zz) <= 6.6
                X_seats(zz) = 4;
                
            elseif Y_seats(zz) <= 7.6           
                X_seats(zz) = 5;
                
            elseif Y_seats(zz) <= 8.6
                X_seats(zz) = 7;
                
            elseif Y_seats(zz) <= 9.6         
                X_seats(zz) = 8;
                
            else
                X_seats(zz) = 9;
            end
        end
    
    Pattern(4,4)=Value;    %Update THe Pattern property
    TD = X_seats(1,201);   %Reassign the TD (which will be an intger now, and therefore correct!)
    X = X_seats(1,1:200);  %Coordinate X for the S curve (corrected for the property "number of seats")
    Y = Y_seats(1,1:200);  %Coordinate Y for the S curve (corrected for the property "number of seats")
    end
    
    %...............................................................
    %% 5) Calculate further dimensions of the vehicle (required for later)
    
    Grenze= cell2mat(Wiki(:,4:5));
    Wendepunkt= (Grenze(:,1)-Grenze(:,2))./2;
    Steigung=cell2mat(Wiki(:,6));
    Steigung = Steigung.*(100./(Grenze(:,1)-Grenze(:,2))).*0.12;

    %Calculate Vehicle Dimensions
    L101 = (log((go-gu)/(Pattern(4,5)-gu)-1)/-Steigung(19))+Wendepunkt(19)+Grenze(19,2);       % Wheelbase in mm
    L104 = (log((go-gu)/(Pattern(2,5)-gu)-1)/-Steigung(17))+Wendepunkt(17)+Grenze(17,2);       % Front Overhang in mm
    L105 = (log((go-gu)/(Pattern(3,5)-gu)-1)/-Steigung(18))+Wendepunkt(18)+Grenze(18,2);       % Rear Overhang in mm

    H_B = (log((go-gu)/(Pattern(5,5)-gu)-1)/-Steigung(20))+Wendepunkt(20)+Grenze(20,2);        % Ratio Height to Width
    L_H = (log((go-gu)/(Pattern(6,5)-gu)-1)/-Steigung(21))+Wendepunkt(21)+Grenze(21,2);        % Ratio Length to Height
    D_w = (log((go-gu)/(Pattern(7,5)-gu)-1)/-Steigung(22))+Wendepunkt(22)+Grenze(22,2);        %Diameter wheel in mm

    Total_length = L101 + L104 + L105;                                                         %Total vehicle length in mm
    Total_height = Total_length / L_H;                                                         %Total Vehicle height in mm
    Total_width = Total_height / H_B;                                                          %Total vehicle width in mm
    
    %Calculate Trunk volume and Legroom
    Trunk_vol = ((log((go-gu)/(Pattern(2,4)-gu)-1)/-Steigung(13))+Wendepunkt(13)+Grenze(13,2)); %trunk volume in l
    Legroom = (log((go-gu)/(Pattern(3,3)-gu)-1)/-Steigung(10))+Wendepunkt(10)+Grenze(10,2);     %Legroom at the second row of seats in mm
    
    if Legroom < 0 %Do not accept value smaller than 0
        Legroom = 0;
    end
    
    %Calculate the number of seats: 
    if Pattern(4,4) <= 4.3
        Num_seats = 1;           
    elseif Pattern(4,4) <= 5.6           
        Num_seats = 2;           
    elseif Pattern(4,4) <= 6.6           
        Num_seats = 4;            
    elseif Pattern(4,4) <= 7.6
        Num_seats = 5;           
    elseif Pattern(4,4)  <= 8.6
        Num_seats = 7;            
    elseif Pattern(4,4)  <= 9.6
        Num_seats = 8;           
    else 
    Num_seats = 9;
    end
    
    %From this point some properties, which impact on each other and on the trunk volume, have to be further modeled
    switch Label
        
    %...............................................................
    %% 6) Calculate the minimum and maximum possible Legroom (if the property "Legroom rear is selected)
    % Adjust the Legroom for the second seatrow: The Legroom should be comprised in a value between 605 and 0.5 Wheelbase
        case 'Legroom rear'
    
            if TD == 0 || Value == 4 %A Legroom rear of 4 corresponds to no legroom rear, i.e. the corresponding TD is 0
            
                TD = 0; 
                Value = double(4);
    
            elseif TD < 650 %If the Legroom is different from 0, it needs to have a minimum value of 650 mm
            
                TD = 650;
                Value = (go-gu)/(1+exp(-s*(TD-w-g2)))+gu;
                
            elseif TD > 0.5*L101
                
                TD = 0.5*L101;
                Value = (go-gu)/(1+exp(-s*(TD-w-g2)))+gu;           
            end
            
            %Calculate and plot line of MINIMAL POSSIBLE LEGROOM at (650 mm)
            Limit_line_min_X = linspace(g2*0.8,g1*1.2,200);                       %in mm              
            Limit_line_min_Y = ones(1,200)* (go-gu)/(1+exp(-s*(650-w-g2)))+gu;    %FIxed estreme value (from 4 to 10)
            txtmin='Minimum Legroom';
            
            %Calculate the actual trunk volume (considering the amout of seatrows!
            if Num_seats >= 7 %3 seatrows
                Trunk_vol_is = (L105+0.5*L101-(2*TD+200)-550) * (Total_width-300) * (Total_height-D_w-400)/1000000;
                
                %Calculate the maximum possible Legroom, considering the required tunk volume
                TDmax = 0.5*(L105+0.5*L101-750 - Trunk_vol/((Total_width-300)*(Total_height-D_w-400)/1000000));    
            else %2 Seatrows
                Trunk_vol_is = (L105+0.5*L101-TD-550) * (Total_width-300) * (Total_height-D_w-400)/1000000;   
                
                %Calculate the maximum possible Legroom, considering the required tunk volume
                TDmax = L105+0.5*L101-550 - Trunk_vol/((Total_width-300)*(Total_height-D_w-400)/1000000);
            end
    
            TDmax=min(TDmax,0.5*L101);
            
            %Calculate and plot line of MAXIMAL POSSIBLE LEGROOM  (considering the limitations of the trunk volume)
            Limit_line_max_X = linspace(g2*0.8,g1*1.2,200);
            Limit_line_max_Y = ones(1,200)*(go-gu)/(1+exp(-s*(TDmax-w-g2)))+gu;
            txtmax='Maximal legroom: limited by trunk volume';
    
            if Trunk_vol_is < Trunk_vol %The possible trunk volume is smaller than the required one!  
                %Adjust The Legroom so, that it has a value compatible with the required trunk volume
                TD=min(TD,TDmax); 
                Value = (go-gu)/(1+exp(-s*(TD-w-g2)))+gu;
            end

            %Adjust the number of seats value (if required) according to the Legroom, to ensure that the parameter set number and Legroom are compatible
            if TD == 0 && app.Pattern(4,4) > 5.6 %The Legroom variable indicates no 2nd seatrow, but the Pattern indicates at least 4 seats
                app.Pattern(4,4) = 5.6;       %Adjust the Pattern variable!
                app.Pattern(4,3) = 4;         %Legroom rear
            elseif TD ~= 0 && app.Pattern(4,4) <= 5.6 %The variable legroom is >0 (there is a second seatrow) but the variable number of seats indicates less than 4 seats
                app.Pattern(4,4) = 6.6;       %Adjust number of seats
                app.Pattern(4,3) = 4.3;       %Legroom rear
            end

        %...............................................................
        %% 7) Calculate the minimum possible Headroom (if the property "Headroom rear" is selected)
        case 'Headroom rear'
    
            if TD == 0 || Value == 4 %0 headroom corresponds to a value of 4     
                TD = 0;
                Value = 4; %Means there is no second seat row!
           
            elseif TD < 890 %Minimum possible headroom
            
                TD = 890;
                Value = (go-gu)/(1+exp(-s*(TD-w-g2)))+gu;
            end

            %Calcuate Limit line for minimal headroom
            Limit_line_min_X = linspace(g2*0.8,g1*1.2,200);
            Limit_line_min_Y = ones(1,200)*(go-gu)/(1+exp(-s*(890-w-g2)))+gu; 
            txtmin='Minimum headroom';
        
            %Adjust the number of seats value (if required) according to the Headroom, to ensure that the parameter set number and headroom are compatible
            if TD == 0 && app.Pattern(4,4) > 5.6 %The headroom rear is 0 (no second seatrow) but the parameter for the seatrow variable indicates at least 4 seats
                app.Pattern(4,4) = 5.6; %Correct the number of seats value
                app.Pattern(3,3) = 4;   %set 4 (minimum value) to the headroom rear
            elseif TD ~= 0 && app.Pattern(4,4) <= 5.6 %The headroom rear is higher than 0 (there is a second seatrow) but the seat row variable indicates only 2 seats
                app.Pattern(4,4) = 6.6; %Correct the number of seats value
                app.Pattern(3,3) = 4.21;
            end
        
        %...............................................................
        %% 8) Calculate the maximum possible trunk volume (if the property "trunk volume" is selected)
        case 'Trunk volume'
        
            %Calculate the actual trunk volume (considering the amout of seatrows!
            if Num_seats >= 7 %3 seatrows
                Trunk_vol_is = (L105+0.5*L101-(2*Legroom+200)-550) * (Total_width-300) * (Total_height-D_w-400)/1000000;    
            else %2 Seatrows
                Trunk_vol_is = (L105+0.5*L101-Legroom-550) * (Total_width-300) * (Total_height-D_w-400)/1000000;   
            end
        
            TDmax = Trunk_vol_is;
            
            if Trunk_vol_is < TD %The possible trunk volume is smaller than the required one
                TD = TDmax;  %Adjust the trunk volume to the maximum possible volume
                Value = (go-gu)/(1+exp(-s*(TD-w-g2)))+gu;
            end
        
            if Trunk_vol_is <= 0 %The trunk volume is 0 i.e. no trunk volume is possible!
                TD = 0;
                Value = (go-gu)/(1+exp(-s*(TD-w-g2)))+gu;
            end    

            %Calcuate and plot the MAXIMAL LIMIT LINE for the trunk volume
            Limit_line_max_X = linspace(g2*0.8,g1*1.2,200);
            Limit_line_max_Y = ones(1,200)*(go-gu)/(1+exp(-s*(TDmax-w-g2)))+gu;
            txtmax='Maximal trunk volume: limited by vehicle height, legroom and total width';
            
         %...............................................................
        %% 9) Calculate the maximum possible number of seats: considering the limitations of the trunk volume
%         case 'Number of seats'
%             
%             if Num_seats>2
%                 
%                 %Simulate the maximum possible number of seats and calculate the resulting trunk volume
%                 TDmax = 9;  %9 seats
%                 ValueM = 10; %Corresponing level of Fullfilment (in the 4 to 10 range)
%                 
%                 %To simulate the max number of passenger the Legroom has to be corrected as if there were 3 seatrows
%                 Trunk_volMod = (L105+0.5*L101-(2*Legroom+200)-550) * (Total_width-300) * (Total_height-D_w-400)/1000000;
%                 
%                 
%                 if Trunk_volMod < Trunk_vol %The trunk volume with 9 passengers is too small!
%                 
%                     %Recalculate which trunk volume would be possible with only 2 seatrows
%                     Trunk_volMod = (L105+0.5*L101-Legroom-550) * (Total_width-300) * (Total_height-D_w-400)/1000000;
%                     
%                     if Trunk_volMod < Trunk_vol %The trunk volume with 2 seatrow is ssmaller than the required one
%                     
%                         if Total_width < 1500 %If the vehicle is very thin 1 Passenger on the front row
%                         TDmax = 1; %Maximum possible number of passengers
%                             ValueM = 4.3;
%                         else %Otherwise 2 passengers
%                             TDmax = 2; %Maximum possible number of passnegers
%                             ValueM = 5.6;
%                         end
%                 
%                     else %The trunk volume with 2 seatrow is bigger or equal than the required one
%                     
%                         if Total_width <= 1600
%                             TDmax =  4; %Maximum number of passengers
%                             ValueM = 6.6;
%                         else
%                             TDmax = 5; %Maximum number of passengers
%                             ValueM = 7.6;
%                         end
%                     end
%                 
%                     %Make sure that TD and Value are not higher than the maximum possible TD and values
%                     TD=min(TD,TDmax);
%                     Value=min(Value,ValueM);     
%                     
%                 end
%                 
%                 %Calcuate and plot the MAXIMAL LIMIT LINE for the number of seats
%                 Limit_line_max_X = linspace(g2*0.8,g1*1.2,200);
%                 Limit_line_max_Y = ones(1,200)*ValueM; 
%                 txtmax='Maximal Number of seats: limited by Trunk volume, vehicle length etc.';
%                 
%             else
%                 
%                 TD=Num_seats;
%             
%             end
%             
%             %Update the Pattern Values 
%             if TD <= 2
%                 app.Pattern(3,3) = 4.1;
%                 app.Pattern(4,3) = 4.1;
%             elseif TD > 3 && app.Pattern(3,3) < 4.21
%                 app.Pattern(3,3) = 4.21;
%                 app.Pattern(4,3) = 4.37;
%             end    

        %...............................................................
%         %% 10) Calculate the overhang rear: considering the limitations of the trunk volume
%         case 'Overhang rear'
%         
%             syms TD1
%             %Calculate the actual trunk volume (considering the amout of seatrows! with the actual rear overhang
%             if Num_seats >= 7 %3 seatrows
%                 Trunk_vol_is = (TD+0.5*L101-(2*Legroom+200)-550) * ((((TD+L101+L104)/L_H)/H_B)-300) * (((TD+L101+L104)/L_H)-D_w-400)/1000000;
%                 eqn = (TD1+0.5*L101-(2*Legroom+200)-550) * ((((TD1+L101+L104)/L_H)/H_B)-300) * (((TD1+L101+L104)/L_H)-D_w-400)/1000000 == Trunk_vol;
%             else %2 Seatrows
%                 Trunk_vol_is = (TD+0.5*L101-Legroom-550) * ((((TD+L101+L104)/L_H)/H_B)-300) * (((TD+L101+L104)/L_H)-D_w-400)/1000000;
%                 eqn = (TD1+0.5*L101-Legroom-550) * ((((TD1+L101+L104)/L_H)/H_B)-300) * (((TD1+L101+L104)/L_H)-D_w-400)/1000000 == Trunk_vol;
%             end
%     
%             %calculate the minimum Overhang required for the required trunk volume
%             TDs = vpa(solve(eqn,TD1));
%             TDmin = max(real(TDs));
%     
%             if Trunk_vol_is < Trunk_vol %Trunk_vol_is is smaller than the required trunk volume -> Adjust the overhang!
%     
%                 TD=max(TD,TDmin);
%                 Value = (go-gu)/(1+exp(-s*(TD-w-g2)))+gu;
%             end
%             
%             %Calcuate and plot the MINIM REAR OVERHANG line
%             Limit_line_min_X = linspace(g2*0.8,g1*1.2,200);
%             Limit_line_min_Y = ones(1,200)*(go-gu)/(1+exp(-s*(TDmin-w-g2)))+gu; 
%             txtmin='Minimum overhang rear: limited by the tunk volume';

%         %...............................................................
%         %% 11) Calculate the wheelbase: considering the limitations of the trunk volume                
%         case 'Wheelbase'
%         
%             syms TD1
%             %Calculate the actual trunk volume (considering the amout of seatrows! with the actual wheelbase
%             if Num_seats >= 7 %3 seatrows
%                 Trunk_vol_is = (L105+0.5*TD-(2*Legroom+200)-550) * ((((TD+L104+L105)/L_H)/H_B)-300) * (((TD+L104+L105)/L_H)-D_w-400)/1000000;
%                 eqn = (L105+0.5*TD1-(2*Legroom+200)-550) * ((((L105+TD1+L104)/L_H)/H_B)-300) * (((L105+TD1+L104)/L_H)-D_w-400)/1000000 == Trunk_vol; 
%             else %2 Seatrows
%                 Trunk_vol_is = (L105+0.5*TD-Legroom-550) * ((((TD+L104+L105)/L_H)/H_B)-300) * (((TD+L104+L105)/L_H)-D_w-400)/1000000;
%                 eqn = (L105+0.5*TD1-Legroom-550) * ((((L105+TD1+L104)/L_H)/H_B)-300) * (((L105+TD1+L104)/L_H)-D_w-400)/1000000 == Trunk_vol; 
%             end
%             
%             %calculate the minimum wheelbase required for the required trunk volume
%             TDs = vpa(solve(eqn,TD1));
%             TDmin = max(real(TDs));
%             
%             if Trunk_vol_is < Trunk_vol %Possible trunk volume smaller than required one!
%                 
%                 TD=max(TD,TDmin);
%                 Value = (go-gu)/(1+exp(-s*(TD-w-g2)))+gu;
%             end
%             
%             %Calcuate and plot the MINIM WHEELBASE line
%             Limit_line_min_X = linspace(g2*0.8,g1*1.2,200);
%             Limit_line_min_Y = ones(1,200)*(go-gu)/(1+exp(-s*(TDmin-w-g2)))+gu; 
%             txtmin='Minimal wheelbase: limited by the trunk volume';

        %...............................................................
        %% 12) Calculate the Ratio Height/Width: considering the limitations of the trunk volume             
%         case 'Ratio Height/Width'
%         
%             %Calculate the actual trunk volume (considering the amout of seatrows! with the actual Ratio Height/Width'
%             if Num_seats >= 7 %3 seatrows
%                 Trunk_vol_is = (L105+0.5*L101-(2*Legroom+200)-550) * ((Total_height/TD)-300) * (Total_height-D_w-400)/1000000; 
%                 TDmin = Total_height / ((Trunk_vol/((L105+0.5*L101-(2*Legroom+200)-550) * (Total_height-D_w-400)/1000000))+300);
%             else %2 Seatrows
%                 Trunk_vol_is = (L105+0.5*L101-Legroom-550) * ((Total_height/TD)-300) * (Total_height-D_w-400)/1000000; 
%                 TDmin = Total_height / ((Trunk_vol/((L105+0.5*L101-Legroom-550) * (Total_height-D_w-400)/1000000))+300);
%             end
%                      
%             if Trunk_vol_is < Trunk_vol %Possible trunk volume smaller than required one!
%                 
%                 if TDmin < TD
%                     TD = TDmin;
%                 end
%                 Value = (go-gu)/(1+exp(-s*(TD-w-g2)))+gu; 
%             end
% 
%             %Calcuate and plot the MINIM H/W Ratio line
%             Limit_line_min_X = linspace(g2*0.8,g1*1.2,200);
%             Limit_line_min_Y = ones(1,200)*(go-gu)/(1+exp(-s*(TDmin-w-g2)))+gu; 
%             txtmin='Minimal ratio H/W: limited by the trunk volume';
        
        %...............................................................
        %% 13) Calculate the Ratio Length/Heigth: considering the limitations of the trunk volume              
%         case'Ratio Length/Height'
%         
%             syms TD1
%             %Calculate the actual trunk volume (considering the amout of seatrows! with the actual Ratio Length/Height'
%             if Num_seats >= 7 %3 seatrows
%                 Trunk_vol_is = (L105+0.5*L101-(2*Legroom+200)-550) * (((Total_length/TD)/H_B)-300) * ((Total_length/TD)-D_w-400)/1000000;  
%                 eqn = (L105+0.5*L101-(2*Legroom+200)-550) * (((Total_length/TD1)/H_B)-300) * ((Total_length/TD1)-D_w-400)/1000000 == Trunk_vol;            
%             else %2 Seatrows
%                 Trunk_vol_is = (L105+0.5*L101-Legroom-550) * (((Total_length/TD)/H_B)-300) * ((Total_length/TD)-D_w-400)/1000000;  
%                 eqn = (L105+0.5*L101-(2*Legroom+200)-550) * (((Total_length/TD1)/H_B)-300) * ((Total_length/TD1)-D_w-400)/1000000 == Trunk_vol;            
%             end
%       
%             TDs = vpa(solve(eqn,TD1));
%             TDmax = max(real(TDs));
% 
%             if Trunk_vol_is < Trunk_vol %Possible trunk volume smaller than required one!
%                 TD=max(TD,TDmax);
%                 Value = (go-gu)/(1+exp(-s*(TD-w-g2)))+gu;
%             end
% 
%             %Calcuate and plot the MINIM L/H Ratio line
%             Limit_line_max_X = linspace(g2*0.8,g1*1.2,200);
%             Limit_line_max_Y = ones(1,200)*(go-gu)/(1+exp(-s*(TDmax-w-g2)))+gu; 
%             txtmax='Maximales Verhältnis Länge/Höhe, begrenzt Kofferraumvolumen';
           
        %...............................................................
        %% 14) Calculate the Wheel diameter: considering the limitations of the trunk volume   
%         case 'Wheel diameter'
%             
%                 %Calculate the actual trunk volume (considering the amout of seatrows! with the actual wheel diameter'
%                 if Num_seats >= 7 %3 seatrows
%                     Trunk_vol_is = (L105+0.5*L101-(2*Legroom+200)-550) * (Total_width-300) * (Total_height-TD-400)/1000000;            
%                     TDmax = (1000000*(Trunk_vol + 0.000001*(Total_height - 400)*(Total_width - 300)*(Legroom - 0.5*L101 - L105 + 550)))/((Total_width - 300)*((2*Legroom+200) - 0.5*L101 - L105 + 550));
%                 else %2 Seatrows
%                     Trunk_vol_is = (L105+0.5*L101-Legroom-550) * (Total_width-300) * (Total_height-TD-400)/1000000;            
%                     TDmax = (1000000*(Trunk_vol + 0.000001*(Total_height - 400)*(Total_width - 300)*(Legroom - 0.5*L101 - L105 + 550)))/((Total_width - 300)*(Legroom - 0.5*L101 - L105 + 550));
%                 end
%                 
%                 if Trunk_vol_is < Trunk_vol % Gefordertes Gepäckvolumen größe als möchliches Gepäckvolumen
%                     
%                     if TDmax < TD
%                         TD = TDmax;
%                     end
%                     
%                     Value = (go-gu)/(1+exp(-s*(TD-w-g2)))+gu;
%     
%                 end
% 
%                 %Calcuate and plot the MAXIMUM WHEEL DIAMERTER line
%                 Limit_line_max_X = linspace(g2*0.8,g1*1.2,200);
%                 Limit_line_max_Y = ones(200,1)*(go-gu)/(1+exp(-s*(TDmax-w-g2)))+gu; 
%                 txtmax='Maximal Wheel diameter: limited by the trunk volume';
                
%         %...............................................................
%         %% 15) Calculate the overhang front: considering the limitations of the trunk volume     
%         case 'Overhang front'
%       
%                 syms TD1
%                 %Calculate the actual trunk volume (considering the amout of seatrows! with the actual Overhang front'
%                 if Num_seats >= 7 %3 seatrows
%                     Trunk_vol_is = (L105+0.5*L101-(2*Legroom+200)-550) * ((((L101+TD+L105)/L_H)/H_B)-300) * (((L101+TD+L105)/L_H)-D_w-400)/1000000;
%                     eqn = (L105+0.5*L101-(2*Legroom+200)-550) * ((((L101 + TD1 + L105) / L_H)/H_B)-300) * (((L101 + TD1 + L105) / L_H)-D_w-400)/1000000 == Trunk_vol;  
%                 else %2 Seatrows
%                     Trunk_vol_is = (L105+0.5*L101-Legroom-550) * (Total_width-300) * (Total_height-TD-400)/1000000;            
%                     eqn = (L105+0.5*L101-Legroom-550) * ((((L101 + TD1 + L105) / L_H)/H_B)-300) * (((L101 + TD1 + L105) / L_H)-D_w-400)/1000000 == Trunk_vol;  
%                 end
%     
%                 TDs = solve(eqn,TD1);
%                 TDs = vpa(TDs);
%                 TDmin = max(real(TDs));
%     
%                 if Trunk_vol_is < Trunk_vol % Gefordertes Gepäckvolumen größe als möchliches Gepäckvolumen
%                     if TDmin > TD
%                         TD = TDmin;
%                     end
%                     
%                     Value = (go-gu)/(1+exp(-s*(TD-w-g2)))+gu;
%                 end
%    
%                 %Calcuate and plot the MINIMAL OVERHANG FRONT line
%                 Limit_line_min_X = linspace(g2*0.8,g1*1.2,200);
%                 Limit_line_min_Y = ones(1,200)*(go-gu)/(1+exp(-s*(TDmin-w-g2)))+gu; 
%                 txtmin='Minimal overhang front: limit by trunk volume';
    end

    %...............................................................
    %% 16) Plot 


    plot(f,X,Y,'color',[0 0 0],'linewidth',1.5);
    hold(f, 'on');
    
    if exist('Limit_line_min_X')
        plot(f,Limit_line_min_X,Limit_line_min_Y,'color','r','linewidth',0.5);
        text(f,Limit_line_min_X(1),Limit_line_min_Y(1)+0.1,txtmin,'fontsize',8);
    end
    
    if exist('Limit_line_max_X')
        plot(f,Limit_line_max_X,Limit_line_max_Y,'color','r','linewidth',0.5);
        text(f,Limit_line_max_X(1),Limit_line_max_Y(1)-0.1,txtmax,'fontsize',8);
    end
    
    %Plot values of First COmpareison Vehicle
    if ~isequal(ComparisonV1,'Please choose a vehicle')
        if isequal(PropName, 'Acceleration') && strcmp(ComparisonV{1,2},'-')
            text(f,12.2,9.6,ComparisonV{1,1},'fontsize',11);
            text(f,11.8,9.8,'The vehicle is not able to reach 100 km/h','FontWeight','bold','fontsize',11);
            plot(f,12,9.57,'^','color',[162 173 0]./255);    
        elseif strcmp(ComparisonV{1,2},'-')
            text(f,12.2,9.6,ComparisonV{1,1},'fontsize',11);
            text(f,11.8,9.8,'No data for this property for the vehicle:','FontWeight','bold','fontsize',11);
            plot(f,12,9.57,'^','color',[162 173 0]./255);    
        else
            text(f,ComparisonV{1,2}+0.3,ComparisonV{1,3}+0.1,ComparisonV{1,1},'fontsize',11);
            plot(f,ComparisonV{1,2},ComparisonV{1,3},'^','color',[162 173 0]./255);
        end
    end
    
    %Plot Values of second Comparison Vehicle
    if ~isequal(ComparisonV2,'Please choose a vehicle') 
        if isequal(PropName, 'Acceleration') && strcmp(ComparisonV{2,2},'-')
            text(f,12.2,9.37,ComparisonV{2,1},'fontsize',11);
            text(f,11.8,9.8,'The vehicle is not able to reach 100 km/h','FontWeight','bold','fontsize',11);
            plot(f,12,9.37,'o','color',[227 114 34]./255);
        elseif strcmp(ComparisonV{2,2},'-')
            text(f,12.2,9.37,ComparisonV{2,1},'fontsize',11);
            text(f,11.8,9.8,'No data for this property for the vehicle:','FontWeight','bold','fontsize',11);
            plot(f,12,9.37,'o','color',[227 114 34]./255);
        else
            text(f,ComparisonV{2,2}+0.3,ComparisonV{2,3}+0.1,ComparisonV{2,1},'fontsize',11);
            plot(f,ComparisonV{2,2},ComparisonV{2,3},'o','color',[227 114 34]./255);
        end
    end
            
    %Plot Values of third COmparison Vehicle
    if ~isequal(ComparisonV3,'Please choose a vehicle')
        if isequal(PropName, 'Acceleration') && strcmp(ComparisonV{3,2},'-')
            text(f,12.2,9.14,ComparisonV{3,1},'fontsize',11);
            text(f,11.8,9.8,'The vehicle is not able to reach 100 km/h','FontWeight','bold','fontsize',11);
            plot(f,12,9.14,'v','color',[152 198 234]./255);   
        elseif strcmp(ComparisonV{3,2},'-')
            text(f,12.2,9.14,ComparisonV{3,1},'fontsize',11);
            text(f,11.8,9.8,'No data for this property for the vehicle:','FontWeight','bold','fontsize',11);
            plot(f,12,9.14,'v','color',[152 198 234]./255);   
        else
            text(f,ComparisonV{3,2}+0.3,ComparisonV{3,3}+0.1,ComparisonV{3,1},'fontsize',11);
            plot(f,ComparisonV{3,2},ComparisonV{3,3},'v','color',[152 198 234]./255);
        end
    end

    if isequal(PropName, 'Acceleration') && app.Pattern(3,1) < 4.5496678484953055
        text(f,12.2,8.9,'Actual Vehicle Concept','FontWeight','bold','fontsize',11);
        text(f,11.8,9.8,'The vehicle is not able to reach 100 km/h''FontWeight','bold','fontsize',11);
        plot(f,12,8.9,'*','color',[0 101 189]./255);
        TD = 0;
        Value = 4;
    else
        plot(f,TD,Value,'*','color',[0 101 189]./255);
        text(f,TD+0.3,Value+0.1,'Actual Vehicle Concept','FontWeight','bold','fontsize',11);
    end

    Value = double(Value);
    TD = double(TD);
    app.Slider.Value = Value;
    app.EditField.Value = TD;
    app.Label2.Text = Unit;
    app.EditField_2.Value = Value;
    app.Value = Value;
    
    hold(f, 'off');
    
    if isequal(Label, 'Top speed')
       app.Pattern(3,1) = Value;
    end

end %End of functions

end %End of methods


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, EV, NEV, ComparisonV1, ComparisonV2, ComparisonV3, Value, Label, Pattern, ResetPattern)
        %%%% Description: This function is callded once the Correlation app is started
        %%Author: lorenzo Nicoletti, Maximilian Bayerlein
        
            %Initialize Input
            app.CallingApp = mainapp;
            app.EV = EV;
            app.NEV = NEV;
            app.ComparisonV1 = ComparisonV1;
            app.ComparisonV2 = ComparisonV2;
            app.ComparisonV3 = ComparisonV3;
            app.Value = Value;
            app.Label = Label;
            app.Switch = 0;
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
            
            %Plot the S curve
            plotter(app, app.EV, app.ComparisonV1, app.ComparisonV2, app.ComparisonV3, app.Value, app.Label, app.Switch, app.Pattern);
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Value = app.Slider.Value;
            if app.Value==4
                app.Value=4.1; %The exact value 4 creates values of TD of -Inf and is therefore not appropriate
            end
            if app.Value==10
               app.Value=9.9; 
            end
            plotter(app, app.EV, app.ComparisonV1, app.ComparisonV2, app.ComparisonV3, app.Value, app.Label, app.Switch, app.Pattern);            
        end

        % Button pushed function: SaveCloseButton
        function SaveCloseButtonPushed(app, event)
            UpdatefromCorrelation(app.CallingApp, app.Value, app.Label, app.Pattern, app.ResetPattern);          
            delete(app);             
        end

        % Close request function: COCACorrelationUIFigure
        function COCACorrelationUIFigureCloseRequest(app, event)
            app.Value = -1;
            UpdatefromCorrelation(app.CallingApp, app.Value, app.Label, app.Pattern);          
            delete(app);            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.Slider.Value = app.EditField_2.Value;
            app.SliderValueChanged(event);            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.Switch = 1;
            plotter(app, app.EV, app.ComparisonV1, app.ComparisonV2, app.ComparisonV3, app.Value, app.Label, app.Switch, app.Pattern);            
            app.Switch = 0;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COCACorrelationUIFigure = uifigure('Visible', 'off');
            app.COCACorrelationUIFigure.Name = 'COCA -Correlation';
            app.COCACorrelationUIFigure.CloseRequestFcn = createCallbackFcn(app, @COCACorrelationUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.COCACorrelationUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Slider = uislider(app.COCACorrelationUIFigure);
            app.Slider.Limits = [4 10];
            app.Slider.MajorTicks = [4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 7;
            app.EditField = uieditfield(app.COCACorrelationUIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueDisplayFormat = '%.1f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Property = uilabel(app.COCACorrelationUIFigure);
            app.Label2 = uilabel(app.COCACorrelationUIFigure);
            app.Label2.Text = 'Label2';
            app.Label_3 = uilabel(app.COCACorrelationUIFigure);
            app.Label_3.Text = '=';
            app.SaveCloseButton = uibutton(app.COCACorrelationUIFigure, 'push');
            app.SaveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCloseButtonPushed, true);
            app.SaveCloseButton.Text = 'Save & Close';
            app.EditField_2 = uieditfield(app.COCACorrelationUIFigure, 'numeric');
            app.EditField_2.Limits = [4 10];
            app.EditField_2.ValueDisplayFormat = '%.1f';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 4;
            % Show the figure after all components are created
            app.COCACorrelationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Correlation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COCACorrelationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COCACorrelationUIFigure)
        end
    end
end