classdef Costs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COCA_Costs                matlab.ui.Figure
        Spider_UIAxes             matlab.ui.control.UIAxes
        Slider_Costs              matlab.ui.control.Slider
        Slider_Weight             matlab.ui.control.Slider
        Slider_Consumption        matlab.ui.control.Slider
        CostsLabel                matlab.ui.control.Label
        EditField_Costs           matlab.ui.control.NumericEditField
        EditField_Weight          matlab.ui.control.NumericEditField
        EditField_Consumption     matlab.ui.control.NumericEditField
        SliderButton_Costs        matlab.ui.control.Button
        SliderButton_Weight       matlab.ui.control.Button
        SliderButton_Consumption  matlab.ui.control.Button
        EditField_Costsfinal      matlab.ui.control.NumericEditField
        Cost_should               matlab.ui.control.Label
        EditField_Costs_2         matlab.ui.control.NumericEditField
        EditField_Weight_2        matlab.ui.control.NumericEditField
        EditField_Consumption_2   matlab.ui.control.NumericEditField
        Label_Cost                matlab.ui.control.Label
        Label_Weight              matlab.ui.control.Label
        Label_Consumption         matlab.ui.control.Label
        Total_Value_Costs         matlab.ui.control.NumericEditField
        GoBack                    matlab.ui.control.Button
        SaveBackButton            matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        Lamp_V1                   matlab.ui.control.Lamp
        Lamp_V2                   matlab.ui.control.Lamp
        Lamp_V3                   matlab.ui.control.Lamp
        Label_V1                  matlab.ui.control.Label
        Label_V2                  matlab.ui.control.Label
        Label_V3                  matlab.ui.control.Label
        DropDown_Costs            matlab.ui.control.DropDown
        DropDown_Weight           matlab.ui.control.DropDown
        DropDown_Consumption      matlab.ui.control.DropDown
        UpdateWeightingButton     matlab.ui.control.StateButton
        ISLabel                   matlab.ui.control.Label
        Gauge                     matlab.ui.control.LinearGauge
        Slider_7                  matlab.ui.control.Slider
    end

     properties (Access = private)
        CorrelationApp   % Correlation
        SLabel
        SValue
     end
     
     properties (Access = public)
        EV
        NEV
        ComparisonVehicle_1
        ComparisonVehicle_2
        ComparisonVehicle_3
        Slider7
        Value
        Label
        Pattern
        ResetPattern;
        Weightings
     end
     
methods (Access = public)

    function UpdatefromCorrelation(app, SValue, SLabel, Pattern,ResetPattern)
    %%%Description: This function retrieves the values changed/corrected in the Correlation app and updates the spider diagramm!
    
    Unblock(app);
    
        if SValue == -1
        %It was not requested to update the values!
                    
        elseif isequal(SLabel,app.SliderButton_Costs.Text)
            app.Slider_Costs.Value = SValue;
            runSpiderAPP(app);
            app.EditField_Costs.Value = app.Slider_Costs.Value;
            app.EditField_Costs_2.Value = app.Slider_Costs.Value;
            Weighting_Pattern(app); 
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
        
        elseif isequal(SLabel,app.SliderButton_Weight.Text)
            app.Slider_Weight.Value = SValue;
            runSpiderAPP(app);
            app.EditField_Weight.Value = app.Slider_Weight.Value;
            app.EditField_Weight_2.Value = app.Slider_Weight.Value;
            Weighting_Pattern(app); 
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
            
        elseif isequal(SLabel,app.SliderButton_Consumption.Text)
            app.Slider_Consumption.Value = SValue;
            runSpiderAPP(app);
            app.EditField_Consumption.Value = app.Slider_Consumption.Value;
            app.EditField_Consumption_2.Value = app.Slider_Consumption.Value;
            Weighting_Pattern(app); 
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
                    
        end
    end
end

methods (Access = private)
    
function  SaveCosts(app)
    %%%Description: Function to save the results of the properties evaluation
         
    app.Pattern(2,2) = app.Slider_Costs.Value;
    app.Pattern(3,2) = app.Slider_Weight.Value;
    app.Pattern(4,2) = app.Slider_Consumption.Value;
    app.Pattern(1,2) = app.Slider_7.Value;
            
end
        
function Block(app)
%%%%Description: Function to block some components during the weighting factors update
    
    app.Slider_Costs.Enable = 'off';
    app.Slider_Weight.Enable = 'off';
    app.Slider_Consumption.Enable = 'off';
    app.Slider_7.Enable = 'off';
    
    app.SliderButton_Costs.Enable = 'off';
    app.SliderButton_Weight.Enable = 'off';
    app.SliderButton_Consumption.Enable = 'off';
    app.GoBack.Enable = 'off';
    app.SaveBackButton.Enable = 'off';
    app.ResetButton.Enable = 'off';
    
end

function Unblock(app)
%%%%Description: Function to unblock some components during the weighting factors update
    
    app.Slider_Costs.Enable = 'on';
    app.Slider_Weight.Enable = 'on';
    app.Slider_Consumption.Enable = 'on';
    app.Slider_7.Enable = 'on';
    
    app.SliderButton_Costs.Enable = 'on';
    app.SliderButton_Weight.Enable = 'on';
    app.SliderButton_Consumption.Enable = 'on';
    app.GoBack.Enable = 'on';
    app.SaveBackButton.Enable = 'on';
    app.ResetButton.Enable = 'on';
    
end
        
function NameLable(app)
%%% Description: This function regulates the Label name of the Update Weighting Paneö
%% Authors: Lorenzo Nicoletti, Maximilian Bayerlein
    
    W = app.Weightings{2,2};
    
    app.Label_Cost.Text = {['Asset Costs x ', num2str(W(1,1))]};
    app.Label_Weight.Text = {['+ Weight x ', num2str(W(1,2))]};
    app.Label_Consumption.Text = {['+ Consumption x ', num2str(W(1,3))]};
    
end
      
function Weighting_Pattern(app)   
%%% Description: This function simply updates the weightings
    
    %Retrieve the values of the Gauge (if they have been changed)
    V = [app.Slider_Costs.Value;app.Slider_Weight.Value;app.Slider_Consumption.Value];
    
    %Recalculate the resulting total value for the "Costs" properties
    W = app.Weightings{2,2};
    app.Total_Value_Costs.Value = (W*V)/sum(W);
    app.Gauge.Value =  app.Total_Value_Costs.Value;
        
end
        
function Lamps(app)
%%% Description: This function regulates the Lamp colors for the "Compare Vehicle" Panels
%% Authors: Lorenzo Nicoletti, Maximilian Bayerlein
    
        
    if ~isequal(app.ComparisonVehicle_1,'Please choose a vehicle')     
        
%         app.Lamp_V1.Color = [0 1 1];
        app.Lamp_V1.Color = [162 173 0]./255;
        app.Label_V1.Text=app.ComparisonVehicle_1;
    end
    
    if ~isequal(app.ComparisonVehicle_2,'Please choose a vehicle')
       
%         app.Lamp_V2.Color = [0 1 0];         
        app.Lamp_V2.Color = [227 114 34]./255; 
        app.Label_V2.Text=app.ComparisonVehicle_2;
    end
    
    if ~isequal(app.ComparisonVehicle_3,'Please choose a vehicle')
        
%         app.Lamp_V3.Color = [1 1 0];       
        app.Lamp_V3.Color = [152 198 234]./255;  
        app.Label_V3.Text=app.ComparisonVehicle_3;
    end      
           
end
        
function [data, lbl] = runSpiderAPP(app)
%%% Description: This function Plots the spider Diagram in the Gui for the choosen vehicle concept and the chosen comparion vehicles
%% Author: Lorenzo Nicoletti, Maximilian Bayerlein
    
    num=app.EV;

    % Retrieve the labels for the spider diagramm -> the second level properties for the first level property "COSTS"
    idx=find(ismember(num(:,1),'Costs'));
    idx_start=idx+1;
    lbl = [num(idx_start,1),num(idx_start+1,1),num(idx_start+2,1)]';
    
    % The values for the spider diagramm
    A = [app.Slider_Costs.Value app.Slider_Weight.Value app.Slider_Consumption.Value]';
    
    %If any Compare vehicles are chosen, retrieve their value
    [V1,V2,V3, app] = Compare_Vehicles(app);
    data =  [V1 V2 V3 A];
    
    ax = app.Spider_UIAxes;
    hold on
    
    % plot spider diagramm
    spiderAPP(data,lbl,ax)

end

function [V1, V2, V3, app] = Compare_Vehicles(app)  
%%% Description: This function adds the plots of the Comparison vehicles on the spider Diagramm
%Author: Lorenzo Nicoletti, Maximilian Bayerlein
    num=app.EV;

    %Find the position of the evaluated subproperties of the property "COSTS"
    idx=find(ismember(num(:,1),'Costs'));
    idx_start=idx+1;
    idx_end=idx_start+numel(app.Weightings{2,2})-1;

    %Retrieve the data for Comparision vehicle number 1
    for ii = 4:size(num,2)
    
        if isequal(app.ComparisonVehicle_1, 'Please choose a vehicle')
            
            V1 = [4 4 4]';
            break;
        
        elseif isequal(app.ComparisonVehicle_1, num{1,ii})
            
            V1 = cell2mat(num(idx_start:idx_end,ii));
            break; 
        end
    end

    %Retrieve the data for Comparision vehicle number 2
    for ii = 4:size(num,2)
    
        if isequal(app.ComparisonVehicle_2, 'Please choose a vehicle')
            
            V2 = [4 4 4]';
            break;
        
        elseif isequal(app.ComparisonVehicle_2, num{1,ii})
            
            V2 = cell2mat(num(idx_start:idx_end,ii));
            break;
            
        end
    end

    %Retrieve the data for Comparision vehicle number 3
    for ii = 4:size(num,2)
        
        if isequal(app.ComparisonVehicle_3, 'Please choose a vehicle')
            
            V3 = [4 4 4]';
            break;
            
        elseif isequal(app.ComparisonVehicle_3, num{1,ii})
            
            V3 = cell2mat(num(idx_start:idx_end,ii));
            break;
        end
    end
end 

function UpdateWeightingFactors(app) 
%%%Description:This function allows to change the weighting factors of the second level properties of the first level property "COSTS"
%Author: Lorenzo Nicoletti, Maximilian Bayerlein

    %Assign the possible drop down values for the weighting factors
    app.DropDown_Costs.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_Costs.Items = {'1', '3', '4', '5', '9'};
    app.DropDown_Weight.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_Weight.Items = {'1', '3', '4', '5', '9'};
    app.DropDown_Consumption.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_Consumption.Items = {'1', '3', '4', '5', '9'};
    
    if app.UpdateWeightingButton.Value == 0 %Button to change the weighting has been pressed for the second time (or not pressed at all)
        
        app.DropDown_Costs.Visible = 'off';
        app.DropDown_Weight.Visible = 'off';
        app.DropDown_Consumption.Visible = 'off';

        %Update the weighting factors
        app.Weightings{2,2} = [app.DropDown_Costs.Value, app.DropDown_Weight.Value, app.DropDown_Consumption.Value];
        
        %Update the Labels
        NameLable(app);
        
        %Reevaluate the custormer properties with the new weighting factors
        Weighting_Pattern(app);
        
        %Unblock the components blocked before (the first time the button was pressed)
        Unblock(app); 
    
    elseif app.UpdateWeightingButton.Value == 1 %Button to change the weighting has been pressed for the first time
        
        app.DropDown_Costs.Visible = 'on';
        app.DropDown_Weight.Visible = 'on';
        app.DropDown_Consumption.Visible = 'on';
    
        %Update the Labels with the new weighting factors
        app.Label_Cost.Text = {'Asset Costs x '};
        app.Label_Weight.Text = {'+ Weight x '};
        app.Label_Consumption.Text = {'+ Consumption x '};
        
        %Block the components which are not required -> The User can therefore only change the weighting factors
        Block(app);  
        
    end

end

end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Start(app, EV, NEV, ComparisonVehicle_1, ComparisonVehicle_2, ComparisonVehicle_3, Pattern, Weightings)
        %%%%Description this function will be exectued just after the GUI "COSTS is created. The function takes the values passed by Mainapp
        %and sets Slides and edit fields accordingly to what was chosen in Mainapp
        %Author: Lorenzo Nicoletti, Maximilian Bayerlein
            
            app.EV = EV;
            app.NEV= NEV;
            app.ComparisonVehicle_1 = ComparisonVehicle_1;
            app.ComparisonVehicle_2 = ComparisonVehicle_2;
            app.ComparisonVehicle_3 = ComparisonVehicle_3;
            app.Pattern = Pattern;
            app.Weightings = Weightings;
            app.ResetPattern=Pattern;
            
            %Set Slider Values 
            app.Slider_Costs.Value = app.Pattern(2,2);
            app.Slider_Weight.Value = app.Pattern(3,2);
            app.Slider_Consumption.Value = app.Pattern(4,2);
            app.Slider_7.Value = app.Pattern(1,2);
            
            %Set Corresponding Edit Field Values
            app.EditField_Costs.Value = app.Slider_Costs.Value;
            app.EditField_Weight.Value = app.Slider_Weight.Value;
            app.EditField_Consumption.Value = app.Slider_Consumption.Value;
            app.EditField_Costs_2.Value = app.Slider_Costs.Value;
            app.EditField_Weight_2.Value = app.Slider_Weight.Value;
            app.EditField_Consumption_2.Value = app.Slider_Consumption.Value;
            app.EditField_Costsfinal.Value = app.Slider_7.Value;
            
            %Assign missing Labels
            NameLable(app);
            
            %Runs Spider app and generate Spider Diagramm
            runSpiderAPP(app);
            
            %Weight the Properties
            Weighting_Pattern(app);      
            
            %Set the Lamp colors according to the number of selected vehicles
            Lamps(app);
            
            %Initialize the Weighting and the Drop down Values of the Panel "Update Weighting Factors"
            app.DropDown_Costs.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_Costs.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_Weight.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_Weight.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_Consumption.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_Consumption.Items = {'1', '3', '4', '5', '9'};
            
            W = app.Weightings{2,2};
            app.DropDown_Costs.Value = W(1); app.DropDown_Weight.Value = W(2); app.DropDown_Consumption.Value = W(3);     
            
            UpdateWeightingFactors(app);             

        end

        % Value changed function: EditField_Costs, Slider_Costs
        function Slider_1ValueChanged(app, event)
            runSpiderAPP(app);
            app.EditField_Costs.Value = app.Slider_Costs.Value;
            app.EditField_Costs_2.Value = app.Slider_Costs.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_Weight
        function Slider_WeightValueChanged(app, event)
            runSpiderAPP(app);
            app.EditField_Weight.Value = app.Slider_Weight.Value;
            app.EditField_Weight_2.Value = app.Slider_Weight.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_Consumption
        function Slider_ConsumptionValueChanged(app, event)
            runSpiderAPP(app);
            app.EditField_Consumption.Value = app.Slider_Consumption.Value;
            app.EditField_Consumption_2.Value = app.Slider_Consumption.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
            runSpiderAPP(app);     
            app.EditField_Costsfinal.Value = app.Slider_7.Value;
        end

        % Button pushed function: SliderButton_Costs
        function SliderButton_CostsPushed(app, event)
            app.Value = app.Slider_Costs.Value;
            app.Label = app.SliderButton_Costs.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: SliderButton_Weight
        function SliderButton_WeightPushed(app, event)
            app.Value = app.Slider_Weight.Value;
            app.Label = app.SliderButton_Weight.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: SliderButton_Consumption
        function SliderButton_ConsumptionPushed(app, event)
            app.Value = app.Slider_Consumption.Value;
            app.Label = app.SliderButton_Consumption.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: GoBack
        function GoBackPushed(app, event)
            Mainapp(app.Pattern, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Weightings);
            delete(app.CorrelationApp);
            delete(app);
        end

        % Button pushed function: SaveBackButton
        function SaveBackButtonPushed(app, event)
            SaveCosts(app);
            Mainapp(app.Pattern, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Weightings);
            delete(app.CorrelationApp);
            delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            app.Slider_Costs.Value = app.ResetPattern(2,2);
            app.Slider_Weight.Value = app.ResetPattern(3,2);
            app.Slider_Consumption.Value = app.ResetPattern(4,2);
            app.Slider_7.Value = app.ResetPattern(1,2);
            app.Pattern=app.ResetPattern;
            app.Gauge.Value=app.ResetPattern(1,2);
            app.Total_Value_Costs.Value=app.ResetPattern(1,2);
            
            app.EditField_Costs.Value = app.Slider_Costs.Value;
            app.EditField_Weight.Value = app.Slider_Weight.Value;
            app.EditField_Consumption.Value = app.Slider_Consumption.Value;
            app.EditField_Costsfinal.Value = app.Slider_7.Value;
            
            app.EditField_Costs_2.Value = app.Slider_Costs.Value;
            app.EditField_Weight_2.Value = app.Slider_Weight.Value;
            app.EditField_Consumption_2.Value = app.Slider_Consumption.Value;  
            
            runSpiderAPP(app);
            Weighting_Pattern(app);
        end

        % Close request function: COCA_Costs
        function COCA_CostsCloseRequest(app, event)
            delete(app.CorrelationApp);
            delete(app);           
        end

        % Value changed function: UpdateWeightingButton
        function UpdateWeightingButtonValueChanged(app, event)
            UpdateWeightingFactors(app);             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COCA_Costs = uifigure('Visible', 'off');
            app.COCA_Costs.Name = 'COCA -Costs';
            app.COCA_Costs.CloseRequestFcn = createCallbackFcn(app, @COCA_CostsCloseRequest, true);
            app.Spider_UIAxes = uiaxes(app.COCA_Costs);
            title(app.Spider_UIAxes, 'Title')
            xlabel(app.Spider_UIAxes, 'X')
            ylabel(app.Spider_UIAxes, 'Y')
            app.Spider_UIAxes.XLim = [0 1];
            app.Spider_UIAxes.YLim = [0 1];
            app.Spider_UIAxes.ZLim = [0 1];
            app.Spider_UIAxes.CLim = [0 1];
            app.Spider_UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Spider_UIAxes.YTick = [0 0.5 1];
            app.Spider_UIAxes.ZTick = [0 0.5 1];
            app.Slider_Costs = uislider(app.COCA_Costs);
            app.Slider_Costs.Limits = [4 10];
            app.Slider_Costs.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_Costs.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.Slider_Costs.Value = 7.5;
            app.Slider_Weight = uislider(app.COCA_Costs);
            app.Slider_Weight.Limits = [4 10];
            app.Slider_Weight.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_Weight.ValueChangedFcn = createCallbackFcn(app, @Slider_WeightValueChanged, true);
            app.Slider_Weight.Value = 7.5;
            app.Slider_Consumption = uislider(app.COCA_Costs);
            app.Slider_Consumption.Limits = [4 10];
            app.Slider_Consumption.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_Consumption.ValueChangedFcn = createCallbackFcn(app, @Slider_ConsumptionValueChanged, true);
            app.Slider_Consumption.Value = 7.5;
            app.CostsLabel = uilabel(app.COCA_Costs);
            app.CostsLabel.Text = 'Costs';
            app.EditField_Costs = uieditfield(app.COCA_Costs, 'numeric');
            app.EditField_Costs.ValueDisplayFormat = '%2.1f';
            app.EditField_Costs.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.EditField_Costs.Editable = 'off';
            app.EditField_Weight = uieditfield(app.COCA_Costs, 'numeric');
            app.EditField_Weight.ValueDisplayFormat = '%2.1f';
            app.EditField_Weight.Editable = 'off';
            app.EditField_Consumption = uieditfield(app.COCA_Costs, 'numeric');
            app.EditField_Consumption.ValueDisplayFormat = '%2.1f';
            app.EditField_Consumption.Editable = 'off';
            app.SliderButton_Costs = uibutton(app.COCA_Costs, 'push');
            app.SliderButton_Costs.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_CostsPushed, true);
            app.SliderButton_Costs.Text = 'Asset Costs';
            app.SliderButton_Weight = uibutton(app.COCA_Costs, 'push');
            app.SliderButton_Weight.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_WeightPushed, true);
            app.SliderButton_Weight.Text = 'Combined Operational Costs';
            app.SliderButton_Consumption = uibutton(app.COCA_Costs, 'push');
            app.SliderButton_Consumption.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_ConsumptionPushed, true);
            app.SliderButton_Consumption.Text = 'Consumption';
            app.EditField_Costsfinal = uieditfield(app.COCA_Costs, 'numeric');
            app.EditField_Costsfinal.ValueDisplayFormat = '%2.1f';
            app.EditField_Costsfinal.Editable = 'off';
            app.Cost_should = uilabel(app.COCA_Costs);
            app.Cost_should.Text = 'Costs - SHOULD';
            app.EditField_Costs_2 = uieditfield(app.COCA_Costs, 'numeric');
            app.EditField_Costs_2.ValueDisplayFormat = '%2.1f';
            app.EditField_Costs_2.Editable = 'off';
            app.EditField_Weight_2 = uieditfield(app.COCA_Costs, 'numeric');
            app.EditField_Weight_2.ValueDisplayFormat = '%2.1f';
            app.EditField_Weight_2.Editable = 'off';
            app.EditField_Consumption_2 = uieditfield(app.COCA_Costs, 'numeric');
            app.EditField_Consumption_2.ValueDisplayFormat = '%2.1f';
            app.EditField_Consumption_2.Editable = 'off';
            app.Label_Cost = uilabel(app.COCA_Costs);
            app.Label_Cost.Text = 'Label2';
            app.Label_Weight = uilabel(app.COCA_Costs);
            app.Label_Weight.Text = 'Label2';
            app.Label_Consumption = uilabel(app.COCA_Costs);
            app.Label_Consumption.Text = 'Label2';
            app.Total_Value_Costs = uieditfield(app.COCA_Costs, 'numeric');
            app.Total_Value_Costs.ValueDisplayFormat = '%2.2f';
            app.Total_Value_Costs.Editable = 'off';
            app.GoBack = uibutton(app.COCA_Costs, 'push');
            app.GoBack.ButtonPushedFcn = createCallbackFcn(app, @GoBackPushed, true);
            app.GoBack.Text = 'Go Back';
            app.SaveBackButton = uibutton(app.COCA_Costs, 'push');
            app.SaveBackButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBackButtonPushed, true);
            app.SaveBackButton.Text = 'Save & Back';
            app.ResetButton = uibutton(app.COCA_Costs, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = {'Reset'; ''};
            app.Panel = uipanel(app.COCA_Costs);
            app.Lamp_V1 = uilamp(app.Panel);
            app.Lamp_V2 = uilamp(app.Panel);
            app.Lamp_V3 = uilamp(app.Panel);
            app.Label_V1 = uilabel(app.Panel);
            app.Label_V1.Text = 'No vehicle chosen';
            app.Label_V2 = uilabel(app.Panel);
            app.Label_V2.Text = 'No vehicle chosen';
            app.Label_V3 = uilabel(app.Panel);
            app.Label_V3.Text = 'No vehicle chosen';
            app.DropDown_Costs = uidropdown(app.COCA_Costs);
            app.DropDown_Weight = uidropdown(app.COCA_Costs);
            app.DropDown_Consumption = uidropdown(app.COCA_Costs);
            app.UpdateWeightingButton = uibutton(app.COCA_Costs, 'state');
            app.UpdateWeightingButton.ValueChangedFcn = createCallbackFcn(app, @UpdateWeightingButtonValueChanged, true);
            app.UpdateWeightingButton.Text = 'Update Weighting';
            app.ISLabel = uilabel(app.COCA_Costs);
            app.ISLabel.Text = '- IS';
            app.Gauge = uigauge(app.COCA_Costs, 'linear');
            app.Gauge.Limits = [4 10];
            app.Gauge.Value = 4;
            app.Slider_7 = uislider(app.COCA_Costs);
            app.Slider_7.Limits = [4 10];
            app.Slider_7.MajorTicks = [];
            app.Slider_7.MajorTickLabels = {};
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.Slider_7.MinorTicks = [];
            app.Slider_7.Value = 7.5;
            % Show the figure after all components are created
            app.COCA_Costs.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Costs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COCA_Costs)

            % Execute the startup function
            runStartupFcn(app, @(app)Start(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COCA_Costs)
        end
    end
end