classdef Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COCA_Design                 matlab.ui.Figure
        DesignISLabel               matlab.ui.control.Label
        Slider_7                    matlab.ui.control.Slider
        Gauge                       matlab.ui.control.LinearGauge
        UpdateweightingButton       matlab.ui.control.StateButton
        DropDown_WD                 matlab.ui.control.DropDown
        DropDown_LH                 matlab.ui.control.DropDown
        DropDown_HB                 matlab.ui.control.DropDown
        DropDown_WB                 matlab.ui.control.DropDown
        DropDown_OR                 matlab.ui.control.DropDown
        DropDown_OF                 matlab.ui.control.DropDown
        Panel                       matlab.ui.container.Panel
        Label_V3                    matlab.ui.control.Label
        Label_V2                    matlab.ui.control.Label
        Label_V1                    matlab.ui.control.Label
        Lamp_V3                     matlab.ui.control.Lamp
        Lamp_V2                     matlab.ui.control.Lamp
        Lamp_V1                     matlab.ui.control.Lamp
        LabelWD                     matlab.ui.control.Label
        EditField_WD                matlab.ui.control.NumericEditField
        SliderButton_Wheeldiameter  matlab.ui.control.Button
        EditField_6                 matlab.ui.control.NumericEditField
        Slider_WD                   matlab.ui.control.Slider
        LabelLH                     matlab.ui.control.Label
        EditField_LH                matlab.ui.control.NumericEditField
        SliderButton_Ratio_LH       matlab.ui.control.Button
        EditField_5                 matlab.ui.control.NumericEditField
        Slider_LH                   matlab.ui.control.Slider
        LabelHB                     matlab.ui.control.Label
        EditField_HB                matlab.ui.control.NumericEditField
        SliderButton_RatioHW        matlab.ui.control.Button
        EditField_4                 matlab.ui.control.NumericEditField
        Slider_HW                   matlab.ui.control.Slider
        ResetButton                 matlab.ui.control.Button
        SaveBackButton              matlab.ui.control.Button
        GoBackButton                matlab.ui.control.Button
        EditField_14                matlab.ui.control.NumericEditField
        LabelWB                     matlab.ui.control.Label
        LabelOR                     matlab.ui.control.Label
        LabelOF                     matlab.ui.control.Label
        EditField_WB                matlab.ui.control.NumericEditField
        EditField_OR                matlab.ui.control.NumericEditField
        EditField_OF                matlab.ui.control.NumericEditField
        DesignSHOULDLabel           matlab.ui.control.Label
        EditField_7                 matlab.ui.control.NumericEditField
        SliderButton_Wheelbase      matlab.ui.control.Button
        SliderButton_Overhang_r     matlab.ui.control.Button
        SliderButton_Overhang_f     matlab.ui.control.Button
        EditField_3                 matlab.ui.control.NumericEditField
        EditField_2                 matlab.ui.control.NumericEditField
        EditField_1                 matlab.ui.control.NumericEditField
        DesignLabel                 matlab.ui.control.Label
        Slider_WB                   matlab.ui.control.Slider
        Slider_OR                   matlab.ui.control.Slider
        Slider_OF                   matlab.ui.control.Slider
        Spider_UIAxes               matlab.ui.control.UIAxes
    end

     properties (Access = private)
        CorrelationApp   % Correlation
        SLabel
        SValue
     end
     
     properties (Access = public)
        EV
        NEV
        ComparisonVehicle_1
        ComparisonVehicle_2
        ComparisonVehicle_3
        Slider7
        Value
        Label
        Pattern
        Weightings
        ResetPattern;
    end

methods (Access = public)
    
    function UpdatefromCorrelation(app, SValue, SLabel, Pattern,ResetPattern)
    %%%Description: This function retrieves the values changed/corrected in the Correlation app and updates the spider diagramm!
        
    Unblock(app);
    
        if SValue == -1
            %It was not requested to update the values!
            
                        
        elseif isequal(SLabel,app.SliderButton_Overhang_f.Text)
            app.Slider_OF.Value = SValue;
            runSpiderAPP(app)
            app.EditField_1.Value = app.Slider_OF.Value;
            app.EditField_OF.Value = app.Slider_OF.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
            
        elseif isequal(SLabel,app.SliderButton_Overhang_r.Text)
            app.Slider_OR.Value = SValue;
            runSpiderAPP(app)
            app.EditField_2.Value = app.Slider_OR.Value;
            app.EditField_OR.Value = app.Slider_OR.Value;
            Weighting_Pattern(app); 
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
            
        elseif isequal(SLabel,app.SliderButton_Wheelbase.Text)
            app.Slider_WB.Value = SValue;
            runSpiderAPP(app)
            app.EditField_3.Value = app.Slider_WB.Value;
            app.EditField_WB.Value = app.Slider_WB.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
            
        elseif isequal(SLabel,app.SliderButton_RatioHW.Text)
            app.Slider_HW.Value = SValue;
            runSpiderAPP(app)
            app.EditField_4.Value = app.Slider_HW.Value;
            app.EditField_HB.Value = app.Slider_HW.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
            
        elseif isequal(SLabel,app.SliderButton_Ratio_LH.Text)
            app.Slider_LH.Value = SValue;
            runSpiderAPP(app)
            app.EditField_5.Value = app.Slider_LH.Value;
            app.EditField_LH.Value = app.Slider_LH.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
                 
        elseif isequal(SLabel,app.SliderButton_Wheeldiameter.Text)
            app.Slider_WD.Value = SValue;
            runSpiderAPP(app)
            app.EditField_6.Value = app.Slider_WD.Value;
            app.EditField_WD.Value = app.Slider_WD.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
                 
        end
    end
end

methods (Access = private)
    
function  SaveDesign(app)
%%Description: Function to save the results of the properties evaluation

    app.Pattern(2,5) = app.Slider_OF.Value; %Front Overhang
    app.Pattern(2,6) = app.Slider_OF.Value; %The Front Overhang is also required for "Safety"   
    app.Pattern(3,5) = app.Slider_OR.Value; %rear Overhang
    app.Pattern(4,5) = app.Slider_WB.Value; %Wheelbase
    app.Pattern(5,5) = app.Slider_HW.Value; %Ratio Height/Width
    app.Pattern(6,5) = app.Slider_LH.Value; %Ratio Length/Width
    app.Pattern(7,5) = app.Slider_WD.Value; %Wheel Diameter
    app.Pattern(1,5) = app.Slider_7.Value;  %Final Value of the first level Propert Design

end
        
function Block(app)
%%%%Description: Function to block some components during the weighting factors update

    app.Slider_OF.Enable = 'off';
    app.Slider_OR.Enable = 'off';
    app.Slider_WB.Enable = 'off';
    app.Slider_HW.Enable = 'off';
    app.Slider_LH.Enable = 'off';
    app.Slider_WD.Enable = 'off';
    app.Slider_7.Enable = 'off';
    
    app.SliderButton_Overhang_f.Enable = 'off';
    app.SliderButton_Overhang_r.Enable = 'off';
    app.SliderButton_Wheelbase.Enable = 'off';
    app.SliderButton_RatioHW.Enable = 'off';
    app.SliderButton_Ratio_LH.Enable = 'off';
    app.SliderButton_Wheeldiameter.Enable = 'off';
    app.GoBackButton.Enable = 'off';
    app.SaveBackButton.Enable = 'off';
    app.ResetButton.Enable = 'off';
    
end
        
function Unblock(app)
%%%%Description: Function to unblock some components during the weighting factors update

    app.Slider_OF.Enable = 'on';
    app.Slider_OR.Enable = 'on';
    app.Slider_WB.Enable = 'on';
    app.Slider_HW.Enable = 'on';
    app.Slider_LH.Enable = 'on';
    app.Slider_WD.Enable = 'on';
    app.Slider_7.Enable = 'on';
    
    app.SliderButton_Overhang_f.Enable = 'on';
    app.SliderButton_Overhang_r.Enable = 'on';
    app.SliderButton_Wheelbase.Enable = 'on';
    app.SliderButton_RatioHW.Enable = 'on';
    app.SliderButton_Ratio_LH.Enable = 'on';
    app.SliderButton_Wheeldiameter.Enable = 'on';
    app.GoBackButton.Enable = 'on';
    app.SaveBackButton.Enable = 'on';
    app.ResetButton.Enable = 'on';

end
        
function NameLable(app)
    %%% Description: This function regulates the Label name of the Update Weighting Paneö
    %% Authors: Lorenzo Nicoletti, Maximilian Bayerlein
    
    W = app.Weightings{5,2};   

    %Update the label Names
    app.LabelOF.Text = {['Overhang front x ', num2str(W(1,1))]};
    app.LabelOR.Text = {['+ overhang rear x ', num2str(W(1,2))]};
    app.LabelWB.Text = {['+ wheelbase x ', num2str(W(1,3))]};
    app.LabelHB.Text = {['+ ratio Height/Width x ', num2str(W(1,4))]};
    app.LabelLH.Text = {['+ ratio Length/heigth x ', num2str(W(1,5))]};
    app.LabelWD.Text = {['+ wheel diameter x ', num2str(W(1,6))]};
    
end
      

function Weighting_Pattern(app)
%%% Description: This function simply initializes the missing GUI Labels           

    %Retrieve the values of the Gauge (if they have been changed)            
    V = [app.Slider_OF.Value;app.Slider_OR.Value;app.Slider_WB.Value;app.Slider_HW.Value;app.Slider_LH.Value;app.Slider_WD.Value];            
    
    %Recalculate the resulting total value for the "Design" properties
    W = app.Weightings{5,2};
    app.EditField_14.Value = (W*V)/sum(W);
    app.Gauge.Value =  app.EditField_14.Value;

end
    
function Lamps(app)          
%%% Description: This function regulates the Lamp colors for the "Compare Vehicle" Panels
%% Authors: Lorenzo Nicoletti, Maximilian Bayerlein
        
    if ~isequal(app.ComparisonVehicle_1,'Please choose a vehicle')     
        
%         app.Lamp_V1.Color = [0 1 1];
        app.Lamp_V1.Color = [162 173 0]./255;
        app.Label_V1.Text=app.ComparisonVehicle_1;
    end
    
    if ~isequal(app.ComparisonVehicle_2,'Please choose a vehicle')
       
%         app.Lamp_V2.Color = [0 1 0]; 
        app.Lamp_V2.Color = [227 114 34]./255; 
        app.Label_V2.Text=app.ComparisonVehicle_2;
    end
    
    if ~isequal(app.ComparisonVehicle_3,'Please choose a vehicle')
        
%         app.Lamp_V3.Color = [1 1 0];       
        app.Lamp_V3.Color = [152 198 234]./255;  
        app.Label_V3.Text=app.ComparisonVehicle_3;
    end                                        
end
       
function [data, lbl] = runSpiderAPP(app)
%%% Description: This function Plots the spider Diagram in the Gui for the choosen vehicle concept and the chosen comparion vehicles
%% Author: Lorenzo Nicoletti, Maximilian Bayerlein
        
    num=app.EV;

    % Retrieve the labels for the spider diagramm -> the second level properties for the first level property "COSTS"
    idx=find(ismember(num(:,1),'Design'));
    idx_start=idx+1;
    lbl = [num(idx_start,1),num(idx_start+1,1),num(idx_start+2,1),num(idx_start+3,1),num(idx_start+4,1),num(idx_start+5,1)]';
    
    % The values for the spider diagramm
    A = [app.Slider_OF.Value app.Slider_OR.Value app.Slider_WB.Value app.Slider_HW.Value app.Slider_LH.Value app.Slider_WD.Value]';

    %If any Compare vehicles are chosen, retrieve their value
    [V1,V2,V3, app] = Compare_Vehicles(app);
    data =  [V1 V2 V3 A];

    ax = app.Spider_UIAxes;
    hold on
    
    % plot spider diagramm
    spiderAPP(data,lbl,ax)

end

function [V1, V2, V3, app] = Compare_Vehicles(app)
%%% Description: This function adds the plots of the Comparison vehicles on the spider Diagramm
%Author: Lorenzo Nicoletti, Maximilian Bayerlein
    num=app.EV;
    
    %Find the position of the evaluated subproperties of the property "COSTS"
    idx=find(ismember(num(:,1),'Design'));
    idx_start=idx+1;
    idx_end=idx_start+numel(app.Weightings{5,2})-1;
    
     %Retrieve the data for Comparision vehicle number 1
    for ii = 4:size(num,2)
        if isequal(app.ComparisonVehicle_1, 'Please choose a vehicle')
            
            V1 =[4 4 4 4 4 4]';
            break;
        
        elseif isequal(app.ComparisonVehicle_1, num{1,ii})
            
            V1 = cell2mat(num(idx_start:idx_end,ii));
            break; 
        end
    end

    %Retrieve the data for Comparision vehicle number 2
    for ii = 4:size(num,2)
        if isequal(app.ComparisonVehicle_2, 'Please choose a vehicle')
            
            V2 = [4 4 4 4 4 4]';
            break;
        
        elseif isequal(app.ComparisonVehicle_2, num{1,ii})
            
            V2 = cell2mat(num(idx_start:idx_end,ii));
            break;
            
        end
    end

    %Retrieve the data for Comparision vehicle number 3
    for ii = 4:size(num,2)   
        if isequal(app.ComparisonVehicle_3, 'Please choose a vehicle')
            
            V3 = [4 4 4 4 4 4]';
            break;
            
        elseif isequal(app.ComparisonVehicle_3, num{1,ii})
            
            V3 = cell2mat(num(idx_start:idx_end,ii));
            break;
        end
    end   
end 

function UpdateWeightingFactors(app)
%%%Description:This function allows to change the weighting factors of the second level properties of the first level property "COSTS"
%Author: Lorenzo Nicoletti, Maximilian Bayerlein   
    
    %Assign the possible drop down values for the weighting factors
    app.DropDown_OF.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_OF.Items = {'1', '3', '4', '5', '9'};
    app.DropDown_OR.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_OR.Items = {'1', '3', '4', '5', '9'};
    app.DropDown_WB.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_WB.Items = {'1', '3', '4', '5', '9'};
    app.DropDown_HB.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_HB.Items = {'1', '3', '4', '5', '9'};
    app.DropDown_LH.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_LH.Items = {'1', '3', '4', '5', '9'};
    app.DropDown_WD.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_WD.Items = {'1', '3', '4', '5', '9'};
    
    if app.UpdateweightingButton.Value == 0 %Button to change the weighting has been pressed for the second time (or not pressed at all)
    
        app.DropDown_OF.Visible = 'off';
        app.DropDown_OR.Visible = 'off';
        app.DropDown_WB.Visible = 'off';
        app.DropDown_HB.Visible = 'off';
        app.DropDown_LH.Visible = 'off';
        app.DropDown_WD.Visible = 'off';
        
        %Update the weighting factors
        app.Weightings{5,2} = [app.DropDown_OF.Value, app.DropDown_OR.Value, app.DropDown_WB.Value, app.DropDown_HB.Value, app.DropDown_LH.Value, app.DropDown_WD.Value];
        
        %Update the Labels
        NameLable(app);
        
        %Reevaluate the custormer properties with the new weighting factors
        Weighting_Pattern(app);
        
        %Unblock the components blocked before (the first time the button was pressed)
        Unblock(app);
    
    elseif app.UpdateweightingButton.Value == 1
    
        app.DropDown_OF.Visible = 'on';
        app.DropDown_OR.Visible = 'on';
        app.DropDown_WB.Visible = 'on';
        app.DropDown_HB.Visible = 'on';
        app.DropDown_LH.Visible = 'on';
        app.DropDown_WD.Visible = 'on';
        
        %Update the Labels with the new weighting factors
        app.LabelOF.Text = {'Overhang front x '};
        app.LabelOR.Text = {'+ overhang rear x '};
        app.LabelWB.Text = {'+ wheelbase x '};
        app.LabelHB.Text = {'+ ratio Height/Width x '};
        app.LabelLH.Text = {'+ ratio Length/heigth x '};
        app.LabelWD.Text = {'+ wheel diameter x '};
        
        %Block the components which are not required -> The User can therefore only change the weighting factors
        Block(app);  
    end
end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Start(app, EV, NEV, ComparisonVehicle_1, ComparisonVehicle_2, ComparisonVehicle_3, Pattern, Weightings)
        %%%%Description this function will be exectued just after the GUI "COSTS is created. The function takes the values passed by Mainapp
        %and sets Slides and edit fields accordingly to what was chosen in Mainapp
        %Author: Lorenzo Nicoletti, Maximilian Bayerlein
        
            app.EV = EV;
            app.NEV= NEV;
            app.ComparisonVehicle_1 = ComparisonVehicle_1;
            app.ComparisonVehicle_2 = ComparisonVehicle_2;
            app.ComparisonVehicle_3 = ComparisonVehicle_3;
            app.Pattern = Pattern;
            app.Weightings = Weightings;
            app.ResetPattern=Pattern;
            
            %Set Slider Values 
            app.Slider_OF.Value = app.Pattern(2,5);
            app.Slider_OR.Value = app.Pattern(3,5);
            app.Slider_WB.Value = app.Pattern(4,5);
            app.Slider_HW.Value = app.Pattern(5,5);
            app.Slider_LH.Value = app.Pattern(6,5);
            app.Slider_WD.Value = app.Pattern(7,5);
            app.Slider_7.Value = app.Pattern(1,5);
            
            %Set Corresponding Edit Field Values
            app.EditField_1.Value = app.Slider_OF.Value;
            app.EditField_2.Value = app.Slider_OR.Value;
            app.EditField_3.Value = app.Slider_WB.Value;
            app.EditField_4.Value = app.Slider_HW.Value;
            app.EditField_5.Value = app.Slider_LH.Value;
            app.EditField_6.Value = app.Slider_WD.Value;
            app.EditField_7.Value = app.Slider_7.Value;
            app.EditField_OF.Value = app.Slider_OF.Value;
            app.EditField_OR.Value = app.Slider_OR.Value;
            app.EditField_WB.Value = app.Slider_WB.Value;
            app.EditField_HB.Value = app.Slider_HW.Value;
            app.EditField_LH.Value = app.Slider_LH.Value;
            app.EditField_WD.Value = app.Slider_WD.Value;
            
            %Assign missing Labels
            NameLable(app);
            
            %Runs Spider app and generate Spider Diagramm
            runSpiderAPP(app);
            
            %Weight the Properties
            Weighting_Pattern(app);   
            
            %Set the Lamp colors according to the number of selected vehicles
            Lamps(app);
            
            %Initialize the Weighting and the Drop down Values of the Panel "Update Weighting Factors"
            app.DropDown_OF.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_OF.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_OR.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_OR.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_WB.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_WB.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_HB.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_HB.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_LH.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_LH.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_WD.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_WD.Items = {'1', '3', '4', '5', '9'};
            
            W = app.Weightings{5,2};
            app.DropDown_OF.Value = W(1); app.DropDown_OR.Value = W(2); app.DropDown_WB.Value = W(3); app.DropDown_HB.Value = W(4); app.DropDown_LH.Value = W(5);  app.DropDown_WD.Value = W(6);           
            
            UpdateWeightingFactors(app);
        end

        % Value changed function: EditField_1, Slider_OF
        function Slider_1ValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_1.Value = app.Slider_OF.Value;
            app.EditField_OF.Value = app.Slider_OF.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_OR
        function Slider_ORValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_2.Value = app.Slider_OR.Value;
            app.EditField_OR.Value = app.Slider_OR.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_WB
        function Slider_WBValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_3.Value = app.Slider_WB.Value;
            app.EditField_WB.Value = app.Slider_WB.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_7.Value = app.Slider_7.Value;                       
        end

        % Button pushed function: SliderButton_Overhang_f
        function SliderButton_Overhang_fPushed(app, event)
            app.Value = app.Slider_OF.Value;
            app.Label = app.SliderButton_Overhang_f.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: SliderButton_Overhang_r
        function SliderButton_Overhang_rPushed(app, event)
            app.Value = app.Slider_OR.Value;
            app.Label = app.SliderButton_Overhang_r.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: SliderButton_Wheelbase
        function SliderButton_WheelbasePushed(app, event)
            app.Value = app.Slider_WB.Value;
            app.Label = app.SliderButton_Wheelbase.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            Mainapp(app.Pattern, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Weightings);
            delete(app.CorrelationApp);
            delete(app);
        end

        % Button pushed function: SaveBackButton
        function SaveBackButtonPushed(app, event)
            SaveDesign(app);
            Mainapp(app.Pattern, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Weightings);
            delete(app.CorrelationApp);
            delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            app.Slider_OF.Value = app.ResetPattern(2,5);
            app.Slider_OR.Value = app.ResetPattern(3,5);
            app.Slider_WB.Value = app.ResetPattern(4,5);
            app.Slider_HW.Value = app.ResetPattern(5,5);
            app.Slider_LH.Value = app.ResetPattern(6,5);
            app.Slider_WD.Value = app.ResetPattern(7,5);
            app.Slider_7.Value = app.ResetPattern(1,5);
            app.Pattern=app.ResetPattern;
            app.Gauge.Value=app.ResetPattern(1,5);
            app.EditField_14.Value=app.ResetPattern(1,5);
            
            app.EditField_1.Value = app.Slider_OF.Value;
            app.EditField_2.Value = app.Slider_OR.Value;
            app.EditField_3.Value = app.Slider_WB.Value;
            app.EditField_4.Value = app.Slider_HW.Value;
            app.EditField_5.Value = app.Slider_LH.Value;
            app.EditField_6.Value = app.Slider_WD.Value;
            app.EditField_7.Value = app.Slider_7.Value;
            
            app.EditField_OF.Value = app.Slider_OF.Value;
            app.EditField_OR.Value = app.Slider_OR.Value;
            app.EditField_WB.Value = app.Slider_WB.Value;  
            app.EditField_HB.Value = app.Slider_HW.Value;  
            app.EditField_LH.Value = app.Slider_LH.Value;  
            app.EditField_WD.Value = app.Slider_WD.Value;  
            
            runSpiderAPP(app);
            Weighting_Pattern(app);
        end

        % Close request function: COCA_Design
        function COCA_DesignCloseRequest(app, event)
            delete(app.CorrelationApp);
            delete(app);           
        end

        % Value changed function: Slider_HW
        function Slider_HWValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_4.Value = app.Slider_HW.Value;
            app.EditField_HB.Value = app.Slider_HW.Value;
            Weighting_Pattern(app);
        end

        % Button pushed function: SliderButton_RatioHW
        function SliderButton_RatioHWPushed(app, event)
            app.Value = app.Slider_HW.Value;
            app.Label = app.SliderButton_RatioHW.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Value changed function: Slider_LH
        function Slider_LHValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_5.Value = app.Slider_LH.Value;
            app.EditField_LH.Value = app.Slider_LH.Value;
            Weighting_Pattern(app);         
        end

        % Button pushed function: SliderButton_Ratio_LH
        function SliderButton_Ratio_LHPushed(app, event)
            app.Value = app.Slider_LH.Value;
            app.Label = app.SliderButton_Ratio_LH.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Value changed function: Slider_WD
        function Slider_WDValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_6.Value = app.Slider_WD.Value;
            app.EditField_WD.Value = app.Slider_WD.Value;
            Weighting_Pattern(app);      
        end

        % Button pushed function: SliderButton_Wheeldiameter
        function SliderButton_WheeldiameterPushed(app, event)
            app.Value = app.Slider_WD.Value;
            app.Label = app.SliderButton_Wheeldiameter.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Value changed function: UpdateweightingButton
        function UpdateweightingButtonValueChanged(app, event)
            UpdateWeightingFactors(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COCA_Design = uifigure('Visible', 'off');
            app.COCA_Design.Name = 'COCA - Design';
            app.COCA_Design.CloseRequestFcn = createCallbackFcn(app, @COCA_DesignCloseRequest, true);
            app.Spider_UIAxes = uiaxes(app.COCA_Design);
            title(app.Spider_UIAxes, 'Title')
            xlabel(app.Spider_UIAxes, 'X')
            ylabel(app.Spider_UIAxes, 'Y')
            app.Spider_UIAxes.XLim = [0 1];
            app.Spider_UIAxes.YLim = [0 1];
            app.Spider_UIAxes.ZLim = [0 1];
            app.Spider_UIAxes.CLim = [0 1];
            app.Spider_UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Spider_UIAxes.YTick = [0 0.5 1];
            app.Spider_UIAxes.ZTick = [0 0.5 1];
            app.Slider_OF = uislider(app.COCA_Design);
            app.Slider_OF.Limits = [4 10];
            app.Slider_OF.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_OF.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.Slider_OF.Value = 7.5;
            app.Slider_OR = uislider(app.COCA_Design);
            app.Slider_OR.Limits = [4 10];
            app.Slider_OR.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_OR.ValueChangedFcn = createCallbackFcn(app, @Slider_ORValueChanged, true);
            app.Slider_OR.Value = 7.5;
            app.Slider_WB = uislider(app.COCA_Design);
            app.Slider_WB.Limits = [4 10];
            app.Slider_WB.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_WB.ValueChangedFcn = createCallbackFcn(app, @Slider_WBValueChanged, true);
            app.Slider_WB.Value = 7.5;
            app.DesignLabel = uilabel(app.COCA_Design);
            app.DesignLabel.Text = 'Design';
            app.EditField_1 = uieditfield(app.COCA_Design, 'numeric');
            app.EditField_1.ValueDisplayFormat = '%2.1f';
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.EditField_1.Editable = 'off';
            app.EditField_2 = uieditfield(app.COCA_Design, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%2.1f';
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.COCA_Design, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%2.1f';
            app.EditField_3.Editable = 'off';
            app.SliderButton_Overhang_f = uibutton(app.COCA_Design, 'push');
            app.SliderButton_Overhang_f.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_Overhang_fPushed, true);
            app.SliderButton_Overhang_f.Text = 'Overhang front';
            app.SliderButton_Overhang_r = uibutton(app.COCA_Design, 'push');
            app.SliderButton_Overhang_r.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_Overhang_rPushed, true);
            app.SliderButton_Overhang_r.Text = 'Overhang rear';
            app.SliderButton_Wheelbase = uibutton(app.COCA_Design, 'push');
            app.SliderButton_Wheelbase.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_WheelbasePushed, true);
            app.SliderButton_Wheelbase.Text = 'Wheelbase';
            app.EditField_7 = uieditfield(app.COCA_Design, 'numeric');
            app.EditField_7.ValueDisplayFormat = '%2.1f';
            app.EditField_7.Editable = 'off';
            app.DesignSHOULDLabel = uilabel(app.COCA_Design);
            app.DesignSHOULDLabel.Text = 'Design SHOULD';
            app.EditField_OF = uieditfield(app.COCA_Design, 'numeric');
            app.EditField_OF.ValueDisplayFormat = '%2.1f';
            app.EditField_OF.Editable = 'off';
            app.EditField_OR = uieditfield(app.COCA_Design, 'numeric');
            app.EditField_OR.ValueDisplayFormat = '%2.1f';
            app.EditField_OR.Editable = 'off';
            app.EditField_WB = uieditfield(app.COCA_Design, 'numeric');
            app.EditField_WB.ValueDisplayFormat = '%2.1f';
            app.EditField_WB.Editable = 'off';
            app.LabelOF = uilabel(app.COCA_Design);
            app.LabelOF.Text = 'Label2';
            app.LabelOR = uilabel(app.COCA_Design);
            app.LabelOR.Text = 'Label2';
            app.LabelWB = uilabel(app.COCA_Design);
            app.LabelWB.Text = 'Label2';
            app.EditField_14 = uieditfield(app.COCA_Design, 'numeric');
            app.EditField_14.ValueDisplayFormat = '%2.2f';
            app.EditField_14.Editable = 'off';
            app.GoBackButton = uibutton(app.COCA_Design, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.SaveBackButton = uibutton(app.COCA_Design, 'push');
            app.SaveBackButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBackButtonPushed, true);
            app.SaveBackButton.Text = 'Save & Back';
            app.ResetButton = uibutton(app.COCA_Design, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Slider_HW = uislider(app.COCA_Design);
            app.Slider_HW.Limits = [4 10];
            app.Slider_HW.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_HW.ValueChangedFcn = createCallbackFcn(app, @Slider_HWValueChanged, true);
            app.Slider_HW.Value = 7.5;
            app.EditField_4 = uieditfield(app.COCA_Design, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%2.1f';
            app.EditField_4.Editable = 'off';
            app.SliderButton_RatioHW = uibutton(app.COCA_Design, 'push');
            app.SliderButton_RatioHW.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_RatioHWPushed, true);
            app.SliderButton_RatioHW.Text = 'Ratio Height/Width';
            app.EditField_HB = uieditfield(app.COCA_Design, 'numeric');
            app.EditField_HB.ValueDisplayFormat = '%2.1f';
            app.EditField_HB.Editable = 'off';
            app.LabelHB = uilabel(app.COCA_Design);
            app.LabelHB.Text = 'Label2';
            app.Slider_LH = uislider(app.COCA_Design);
            app.Slider_LH.Limits = [4 10];
            app.Slider_LH.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_LH.ValueChangedFcn = createCallbackFcn(app, @Slider_LHValueChanged, true);
            app.Slider_LH.Value = 7.5;
            app.EditField_5 = uieditfield(app.COCA_Design, 'numeric');
            app.EditField_5.ValueDisplayFormat = '%2.1f';
            app.EditField_5.Editable = 'off';
            app.SliderButton_Ratio_LH = uibutton(app.COCA_Design, 'push');
            app.SliderButton_Ratio_LH.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_Ratio_LHPushed, true);
            app.SliderButton_Ratio_LH.Text = 'Ratio Length/Height';
            app.EditField_LH = uieditfield(app.COCA_Design, 'numeric');
            app.EditField_LH.ValueDisplayFormat = '%2.1f';
            app.EditField_LH.Editable = 'off';
            app.LabelLH = uilabel(app.COCA_Design);
            app.LabelLH.Text = 'Label2';
            app.Slider_WD = uislider(app.COCA_Design);
            app.Slider_WD.Limits = [4 10];
            app.Slider_WD.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_WD.ValueChangedFcn = createCallbackFcn(app, @Slider_WDValueChanged, true);
            app.Slider_WD.Value = 7.5;
            app.EditField_6 = uieditfield(app.COCA_Design, 'numeric');
            app.EditField_6.ValueDisplayFormat = '%2.1f';
            app.EditField_6.Editable = 'off';
            app.SliderButton_Wheeldiameter = uibutton(app.COCA_Design, 'push');
            app.SliderButton_Wheeldiameter.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_WheeldiameterPushed, true);
            app.SliderButton_Wheeldiameter.Text = 'Wheel diameter';
            app.EditField_WD = uieditfield(app.COCA_Design, 'numeric');
            app.EditField_WD.ValueDisplayFormat = '%2.1f';
            app.EditField_WD.Editable = 'off';
            app.LabelWD = uilabel(app.COCA_Design);
            app.LabelWD.Text = 'Label2';
            app.Panel = uipanel(app.COCA_Design);
            app.Lamp_V1 = uilamp(app.Panel);
            app.Lamp_V2 = uilamp(app.Panel);
            app.Lamp_V3 = uilamp(app.Panel);
            app.Label_V1 = uilabel(app.Panel);
            app.Label_V1.Text = 'No vehicle chosen';
            app.Label_V2 = uilabel(app.Panel);
            app.Label_V2.Text = 'No vehicle chosen';
            app.Label_V3 = uilabel(app.Panel);
            app.Label_V3.Text = 'No vehicle chosen';
            app.DropDown_OF = uidropdown(app.COCA_Design);
            app.DropDown_OR = uidropdown(app.COCA_Design);
            app.DropDown_WB = uidropdown(app.COCA_Design);
            app.DropDown_HB = uidropdown(app.COCA_Design);
            app.DropDown_LH = uidropdown(app.COCA_Design);
            app.DropDown_WD = uidropdown(app.COCA_Design);
            app.UpdateweightingButton = uibutton(app.COCA_Design, 'state');
            app.UpdateweightingButton.ValueChangedFcn = createCallbackFcn(app, @UpdateweightingButtonValueChanged, true);
            app.UpdateweightingButton.Text = 'Update Weightings';
            app.Gauge = uigauge(app.COCA_Design, 'linear');
            app.Gauge.Limits = [4 10];
            app.Gauge.Value = 4;
            app.Slider_7 = uislider(app.COCA_Design);
            app.Slider_7.Limits = [4 10];
            app.Slider_7.MajorTicks = [];
            app.Slider_7.MajorTickLabels = {};
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.Slider_7.MinorTicks = [];
            app.Slider_7.Value = 7.5;
            app.DesignISLabel = uilabel(app.COCA_Design);
            app.DesignISLabel.Text = '- Design IS';
            % Show the figure after all components are created
            app.COCA_Design.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Design(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COCA_Design)

            % Execute the startup function
            runStartupFcn(app, @(app)Start(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COCA_Design)
        end
    end
end