classdef Safety < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COCA_Safety             matlab.ui.Figure
        Slider_7                matlab.ui.control.Slider
        Gauge                   matlab.ui.control.LinearGauge
        ISLabel                 matlab.ui.control.Label
        UpdateWeightingsButton  matlab.ui.control.StateButton
        DropDown_3              matlab.ui.control.DropDown
        DropDown_2              matlab.ui.control.DropDown
        DropDown                matlab.ui.control.DropDown
        Panel                   matlab.ui.container.Panel
        Label_V3                matlab.ui.control.Label
        Label_V2                matlab.ui.control.Label
        Label_V1                matlab.ui.control.Label
        Lamp_V3                 matlab.ui.control.Lamp
        Lamp_V2                 matlab.ui.control.Lamp
        Lamp_V1                 matlab.ui.control.Lamp
        ResetButton             matlab.ui.control.Button
        SaveBackButton          matlab.ui.control.Button
        GoBackButton            matlab.ui.control.Button
        EditField_14            matlab.ui.control.NumericEditField
        Label_HR                matlab.ui.control.Label
        Label_HF                matlab.ui.control.Label
        Label_OF                matlab.ui.control.Label
        EditField_HR_2          matlab.ui.control.NumericEditField
        EditField_HF_2          matlab.ui.control.NumericEditField
        EditField_OF_2          matlab.ui.control.NumericEditField
        SafetySHOULDLabel       matlab.ui.control.Label
        EditField_7             matlab.ui.control.NumericEditField
        SliderButton_HR         matlab.ui.control.Button
        SliderButton_HF         matlab.ui.control.Button
        SliderButton_OF         matlab.ui.control.Button
        EditField_HR            matlab.ui.control.NumericEditField
        EditField_HF            matlab.ui.control.NumericEditField
        EditField_OF            matlab.ui.control.NumericEditField
        SafetyLabel             matlab.ui.control.Label
        Slider_HR               matlab.ui.control.Slider
        Slider_HF               matlab.ui.control.Slider
        Slider_OF               matlab.ui.control.Slider
        WeiterzuKomfortButton   matlab.ui.control.Button
        Spider_UIAxes           matlab.ui.control.UIAxes
    end

    
     properties (Access = public)
        EV
        NEV
        ComparisonVehicle_1
        ComparisonVehicle_2
        ComparisonVehicle_3
        Slider7
        Value
        Label
        Pattern
        ResetPattern
        Weightings
    end

methods (Access = private)
              
function NameLable(app)
%%% Description: This function regulates the Label name of the Update Weighting Paneö
%% Authors: Lorenzo Nicoletti, Maximilian Bayerlein

    W = app.Weightings{6,2};
    
    app.Label_OF.Text = {['Overhang front x ', num2str(W(1,1))]};
    app.Label_HF.Text = {['+ headroom front x ', num2str(W(1,2))]};
    app.Label_HR.Text = {['+ headroom rear x ', num2str(W(1,3))]};

end
      

function Weighting_Pattern(app)
%%% Description: This function simply updates the weightings
    
    %Retrieve the values of the Gauge (if they have been changed)
    V = [app.Slider_OF.Value;app.Slider_HF.Value;app.Slider_HR.Value];
    
    %Recalculate the resulting total value for the "Safety" properties
    W = app.Weightings{6,2};
    app.EditField_14.Value = (W*V)/sum(W);
    app.Gauge.Value =  app.EditField_14.Value;
    
end
        
function Lamps(app)
%%% Description: This function regulates the Lamp colors for the "Compare Vehicle" Panels
%% Authors: Lorenzo Nicoletti, Maximilian Bayerlein
        
    if ~isequal(app.ComparisonVehicle_1,'Please choose a vehicle')     
        
%         app.Lamp_V1.Color = [0 1 1];
        app.Lamp_V1.Color = [162 173 0]./255;
        app.Label_V1.Text=app.ComparisonVehicle_1;
    end
    
    if ~isequal(app.ComparisonVehicle_2,'Please choose a vehicle')
       
%         app.Lamp_V2.Color = [0 1 0]; 
        app.Lamp_V2.Color = [227 114 34]./255; 
        app.Label_V2.Text=app.ComparisonVehicle_2;
    end
    
    if ~isequal(app.ComparisonVehicle_3,'Please choose a vehicle')
        
%         app.Lamp_V3.Color = [1 1 0];       
        app.Lamp_V3.Color = [152 198 234]./255;  
        app.Label_V3.Text=app.ComparisonVehicle_3;
    end               
end
        
function [data, lbl] = runSpiderAPP(app)
%%% Description: This function Plots the spider Diagram in the Gui for the choosen vehicle concept and the chosen comparion vehicles
%% Author: Lorenzo Nicoletti, Maximilian Bayerlein

    num=app.EV;

    % Retrieve the labels for the spider diagramm -> the second level properties for the first level property "COSTS"
    idx=find(ismember(num(:,1),'Safety'));
    idx_start=idx+1;
    lbl = [num(idx_start,1),num(idx_start+1,1),num(idx_start+2,1)]';

    % The values for the spider diagramm
    A = [app.Slider_OF.Value app.Slider_HF.Value app.Slider_HR.Value]';
    
    %If any Compare vehicles are chosen, retrieve their value
    [V1,V2,V3, app] = Compare_Vehicles(app);
    data =  [V1 V2 V3 A];
    
    ax = app.Spider_UIAxes;
    hold on
    
    % plot spider diagramm
    spiderAPP(data,lbl,ax)

end

function [V1,V2,V3, app] = Compare_Vehicles(app)
%%% Description: This function adds the plots of the Comparison vehicles on the spider Diagramm
%Author: Lorenzo Nicoletti, Maximilian Bayerlein

    num=app.EV;

    %Find the position of the evaluated subproperties of the property "COSTS"
    idx=find(ismember(num(:,1),'Safety'));
    idx_start=idx+1;
    idx_end=idx_start+numel(app.Weightings{6,2})-1;

    %Retrieve the data for Comparision vehicle number 1
    for ii = 4:size(num,2)
    
        if isequal(app.ComparisonVehicle_1, 'Please choose a vehicle')
            
            V1 = [4 4 4]';
            break;
        
        elseif isequal(app.ComparisonVehicle_1, num{1,ii})
            
            V1 = cell2mat(num(idx_start:idx_end,ii));
            break; 
        end
    end

    %Retrieve the data for Comparision vehicle number 2
    for ii = 4:size(num,2)
    
        if isequal(app.ComparisonVehicle_2, 'Please choose a vehicle')
            
            V2 = [4 4 4]';
            break;
        
        elseif isequal(app.ComparisonVehicle_2, num{1,ii})
            
            V2 = cell2mat(num(idx_start:idx_end,ii));
            break;
            
        end
    end

    %Retrieve the data for Comparision vehicle number 3
    for ii = 4:size(num,2)
        
        if isequal(app.ComparisonVehicle_3, 'Please choose a vehicle')
            
            V3 = [4 4 4]';
            break;
            
        elseif isequal(app.ComparisonVehicle_3, num{1,ii})
            
            V3 = cell2mat(num(idx_start:idx_end,ii));
            break;
        end
    end
   
end 

function UpdateWeightingFactors(app)
%%%Description:This function allows to change the weighting factors of the second level properties of the first level property "COSTS"
%Author: Lorenzo Nicoletti, Maximilian Bayerlein

    %Assign the possible drop down values for the weighting factors
    app.DropDown.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown.Items = {'1', '3', '4', '5', '9'};
    app.DropDown_2.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_2.Items = {'1', '3', '4', '5', '9'};
    app.DropDown_3.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_3.Items = {'1', '3', '4', '5', '9'};
    
    app.DropDown.Visible = 'off';
    app.DropDown_2.Visible = 'off';
    app.DropDown_3.Visible = 'off';
    
    %Update the weighting factors
    app.Weightings{2,6} = [app.DropDown.Value, app.DropDown_2.Value, app.DropDown_3.Value];
    
    %Update the Labels
    NameLable(app);
    
    %Reevaluate the custormer properties with the new weighting factors
    Weighting_Pattern(app);

end

end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Start(app, EV, NEV, ComparisonVehicle_1, ComparisonVehicle_2, ComparisonVehicle_3, Pattern, Weightings)
        %%%%Description this function will be exectued just after the GUI "COSTS is created. The function takes the values passed by Mainapp
        %and sets Slides and edit fields accordingly to what was chosen in Mainapp
        %Author: Lorenzo Nicoletti, Maximilian Bayerlein

            app.EV = EV;
            app.NEV = NEV;
            app.ComparisonVehicle_1 = ComparisonVehicle_1;
            app.ComparisonVehicle_2 = ComparisonVehicle_2;
            app.ComparisonVehicle_3 = ComparisonVehicle_3;
            app.Pattern = Pattern;
            app.Weightings = Weightings;
            app.ResetPattern=Pattern;
            
            %Set Slider Values 
            app.Slider_OF.Value = app.Pattern(2,6);
            app.Slider_HF.Value = app.Pattern(3,6);
            app.Slider_HR.Value = app.Pattern(4,6);
            app.Slider_7.Value = app.Pattern(1,6);
            
            %Set Corresponding Edit Field Values
            app.EditField_OF.Value = app.Slider_OF.Value;
            app.EditField_HF.Value = app.Slider_HF.Value;
            app.EditField_HR.Value = app.Slider_HR.Value;
            app.EditField_7.Value = app.Slider_7.Value;
            app.EditField_OF_2.Value = app.Slider_OF.Value;
            app.EditField_HF_2.Value = app.Slider_HF.Value;
            app.EditField_HR_2.Value = app.Slider_HR.Value;
            
            %Assign missing Labels
            NameLable(app);
            
            %Runs Spider app and generate Spider Diagramm
            runSpiderAPP(app);
            
            %Weight the Properties
            Weighting_Pattern(app);      
            
            %Set the Lamp colors according to the number of selected vehicles
            Lamps(app);
            
            %Assign the possible drop down values for the weighting factors
            app.DropDown.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_2.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_2.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_3.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_3.Items = {'1', '3', '4', '5', '9'};
            
            W = app.Weightings{2,2};
            app.DropDown.Value = W(1); app.DropDown_2.Value = W(2); app.DropDown_3.Value = W(3);     
            
            UpdateWeightingFactors(app);             
        end

        % Button pushed function: SliderButton_OF
        function SliderButton_OFPushed(app, event)
            Design(app.EV,app.NEV,app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Pattern, app.Weightings);                       
            delete(app);
        end

        % Button pushed function: SliderButton_HF
        function SliderButton_HFPushed(app, event)
            Comfort(app.EV,app.NEV,app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Pattern, app.Weightings);   
            delete(app);
        end

        % Button pushed function: SliderButton_HR
        function SliderButton_HRPushed(app, event)
            Comfort(app.EV,app.NEV,app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Pattern, app.Weightings); 
            delete(app);
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            Mainapp(app.Pattern, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Weightings);
            delete(app);
        end

        % Close request function: COCA_Safety
        function COCA_SafetyCloseRequest(app, event)
            delete(app);           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COCA_Safety = uifigure('Visible', 'off');
            app.COCA_Safety.Name = 'COCA - Safety';
            app.COCA_Safety.CloseRequestFcn = createCallbackFcn(app, @COCA_SafetyCloseRequest, true);
            app.Spider_UIAxes = uiaxes(app.COCA_Safety);
            title(app.Spider_UIAxes, 'Title')
            xlabel(app.Spider_UIAxes, 'X')
            ylabel(app.Spider_UIAxes, 'Y')
            app.Spider_UIAxes.XLim = [0 1];
            app.Spider_UIAxes.YLim = [0 1];
            app.Spider_UIAxes.ZLim = [0 1];
            app.Spider_UIAxes.CLim = [0 1];
            app.Spider_UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Spider_UIAxes.YTick = [0 0.5 1];
            app.Spider_UIAxes.ZTick = [0 0.5 1];
            app.WeiterzuKomfortButton = uibutton(app.COCA_Safety, 'push');
            app.WeiterzuKomfortButton.Enable = 'off';
            app.WeiterzuKomfortButton.Visible = 'off';
            app.WeiterzuKomfortButton.Text = 'Weiter zu Komfort';
            app.Slider_OF = uislider(app.COCA_Safety);
            app.Slider_OF.Limits = [4 10];
            app.Slider_OF.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_OF.Enable = 'off';
            app.Slider_OF.Value = 7.5;
            app.Slider_HF = uislider(app.COCA_Safety);
            app.Slider_HF.Limits = [4 10];
            app.Slider_HF.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_HF.Enable = 'off';
            app.Slider_HF.Value = 7.5;
            app.Slider_HR = uislider(app.COCA_Safety);
            app.Slider_HR.Limits = [4 10];
            app.Slider_HR.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_HR.Enable = 'off';
            app.Slider_HR.Value = 7.5;
            app.SafetyLabel = uilabel(app.COCA_Safety);
            app.SafetyLabel.Text = 'Safety';
            app.EditField_OF = uieditfield(app.COCA_Safety, 'numeric');
            app.EditField_OF.ValueDisplayFormat = '%2.1f';
            app.EditField_OF.Editable = 'off';
            app.EditField_OF.Enable = 'off';
            app.EditField_HF = uieditfield(app.COCA_Safety, 'numeric');
            app.EditField_HF.ValueDisplayFormat = '%2.1f';
            app.EditField_HF.Editable = 'off';
            app.EditField_HF.Enable = 'off';
            app.EditField_HR = uieditfield(app.COCA_Safety, 'numeric');
            app.EditField_HR.ValueDisplayFormat = '%2.1f';
            app.EditField_HR.Editable = 'off';
            app.EditField_HR.Enable = 'off';
            app.SliderButton_OF = uibutton(app.COCA_Safety, 'push');
            app.SliderButton_OF.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_OFPushed, true);
            app.SliderButton_OF.Text = 'Overhang front';
            app.SliderButton_HF = uibutton(app.COCA_Safety, 'push');
            app.SliderButton_HF.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_HFPushed, true);
            app.SliderButton_HF.Text = 'Headroom front';
            app.SliderButton_HR = uibutton(app.COCA_Safety, 'push');
            app.SliderButton_HR.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_HRPushed, true);
            app.SliderButton_HR.Text = 'Headroom rear';
            app.EditField_7 = uieditfield(app.COCA_Safety, 'numeric');
            app.EditField_7.ValueDisplayFormat = '%2.1f';
            app.EditField_7.Editable = 'off';
            app.SafetySHOULDLabel = uilabel(app.COCA_Safety);
            app.SafetySHOULDLabel.Text = 'Safety -SHOULD';
            app.EditField_OF_2 = uieditfield(app.COCA_Safety, 'numeric');
            app.EditField_OF_2.ValueDisplayFormat = '%2.1f';
            app.EditField_OF_2.Editable = 'off';
            app.EditField_HF_2 = uieditfield(app.COCA_Safety, 'numeric');
            app.EditField_HF_2.ValueDisplayFormat = '%2.1f';
            app.EditField_HF_2.Editable = 'off';
            app.EditField_HR_2 = uieditfield(app.COCA_Safety, 'numeric');
            app.EditField_HR_2.ValueDisplayFormat = '%2.1f';
            app.EditField_HR_2.Editable = 'off';
            app.Label_OF = uilabel(app.COCA_Safety);
            app.Label_OF.Text = 'Label2';
            app.Label_HF = uilabel(app.COCA_Safety);
            app.Label_HF.Text = 'Label2';
            app.Label_HR = uilabel(app.COCA_Safety);
            app.Label_HR.Text = 'Label2';
            app.EditField_14 = uieditfield(app.COCA_Safety, 'numeric');
            app.EditField_14.ValueDisplayFormat = '%2.2f';
            app.EditField_14.Editable = 'off';
            app.GoBackButton = uibutton(app.COCA_Safety, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.SaveBackButton = uibutton(app.COCA_Safety, 'push');
            app.SaveBackButton.Enable = 'off';
            app.SaveBackButton.Text = 'Save & Back';
            app.ResetButton = uibutton(app.COCA_Safety, 'push');
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.Panel = uipanel(app.COCA_Safety);
            app.Lamp_V1 = uilamp(app.Panel);
            app.Lamp_V2 = uilamp(app.Panel);
            app.Lamp_V3 = uilamp(app.Panel);
            app.Label_V1 = uilabel(app.Panel);
            app.Label_V1.Text = 'No vehicle chosen';
            app.Label_V2 = uilabel(app.Panel);
            app.Label_V2.Text = 'No vehicle chosen';
            app.Label_V3 = uilabel(app.Panel);
            app.Label_V3.Text = 'No vehicle chosen';
            app.DropDown = uidropdown(app.COCA_Safety);
            app.DropDown.Enable = 'off';
            app.DropDown_2 = uidropdown(app.COCA_Safety);
            app.DropDown_2.Enable = 'off';
            app.DropDown_3 = uidropdown(app.COCA_Safety);
            app.DropDown_3.Enable = 'off';
            app.UpdateWeightingsButton = uibutton(app.COCA_Safety, 'state');
            app.UpdateWeightingsButton.Enable = 'off';
            app.UpdateWeightingsButton.Text = 'Update Weightings';
            app.ISLabel = uilabel(app.COCA_Safety);
            app.ISLabel.Text = '- IS';
            app.Gauge = uigauge(app.COCA_Safety, 'linear');
            app.Gauge.Limits = [4 10];
            app.Gauge.Enable = 'off';
            app.Gauge.Value = 4;
            app.Slider_7 = uislider(app.COCA_Safety);
            app.Slider_7.Limits = [4 10];
            app.Slider_7.MajorTicks = [];
            app.Slider_7.MajorTickLabels = {};
            app.Slider_7.MinorTicks = [];
            app.Slider_7.Enable = 'off';
            app.Slider_7.Value = 7.5;
            % Show the figure after all components are created
            app.COCA_Safety.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Safety(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COCA_Safety)

            % Execute the startup function
            runStartupFcn(app, @(app)Start(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COCA_Safety)
        end
    end
end