classdef Vehicle_Dynamics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COCA_Vehicle_Dynamics       matlab.ui.Figure
        ISLabel                     matlab.ui.control.Label
        Slider_7                    matlab.ui.control.Slider
        Gauge                       matlab.ui.control.LinearGauge
        UpdateWeightingsButton      matlab.ui.control.StateButton
        DropDown_TC                 matlab.ui.control.DropDown
        DropDown_SA                 matlab.ui.control.DropDown
        DropDown_CA                 matlab.ui.control.DropDown
        DropDown_TS                 matlab.ui.control.DropDown
        DropDown_AC                 matlab.ui.control.DropDown
        Panel                       matlab.ui.container.Panel
        Label_V3                    matlab.ui.control.Label
        Label_V2                    matlab.ui.control.Label
        Label_V1                    matlab.ui.control.Label
        Lamp_V3                     matlab.ui.control.Lamp
        Lamp_V2                     matlab.ui.control.Lamp
        Lamp_V1                     matlab.ui.control.Lamp
        ResetButton                 matlab.ui.control.Button
        SaveBackButton              matlab.ui.control.Button
        GoBackButton                matlab.ui.control.Button
        EditField_14                matlab.ui.control.NumericEditField
        Label2_TC                   matlab.ui.control.Label
        Label2_SA                   matlab.ui.control.Label
        Label2_CA                   matlab.ui.control.Label
        Label2_TS                   matlab.ui.control.Label
        Label_AC                    matlab.ui.control.Label
        EditField_CA_2              matlab.ui.control.NumericEditField
        EditField_TS_2              matlab.ui.control.NumericEditField
        EditField_AC_2              matlab.ui.control.NumericEditField
        EditField_SA_2              matlab.ui.control.NumericEditField
        EditField_TC_2              matlab.ui.control.NumericEditField
        VehicleDynamicsSHOULDLabel  matlab.ui.control.Label
        EditField_7                 matlab.ui.control.NumericEditField
        SliderButton_TC             matlab.ui.control.Button
        SliderButton_SA             matlab.ui.control.Button
        SliderButton_CA             matlab.ui.control.Button
        SliderButton_TS             matlab.ui.control.Button
        SliderButton_AC             matlab.ui.control.Button
        EditField_TC                matlab.ui.control.NumericEditField
        EditField_SA                matlab.ui.control.NumericEditField
        EditField_CA                matlab.ui.control.NumericEditField
        EditField_TS                matlab.ui.control.NumericEditField
        EditField_AC                matlab.ui.control.NumericEditField
        VehicleDynamicsLabel        matlab.ui.control.Label
        Slider_SA                   matlab.ui.control.Slider
        Slider_TC                   matlab.ui.control.Slider
        Slider_CA                   matlab.ui.control.Slider
        Slider_TS                   matlab.ui.control.Slider
        Slider_AC                   matlab.ui.control.Slider
        Spider_UIAxes               matlab.ui.control.UIAxes
    end

properties (Access = private)
    CorrelationApp   % Correlation
end
     
properties (Access = public)
    EV
    NEV
    ComparisonVehicle_1
    ComparisonVehicle_2
    ComparisonVehicle_3
    Slider7
    Value
    Label
    Pattern
    Weightings
    ResetPattern
end

methods (Access = public)
    
    function UpdatefromCorrelation(app, SValue, SLabel, Pattern, ResetPattern)
    %%%Description: This function retrieves the values changed/corrected in the Correlation app and updates the spider diagramm!
    
    Unblock(app);
        
        if SValue == -1
            %It was not requested to update the values!
        
            
        elseif isequal(SLabel,app.SliderButton_AC.Text)
            app.Slider_AC.Value = SValue;
            runSpiderAPP(app)
            app.EditField_AC.Value = app.Slider_AC.Value;
            app.EditField_AC_2.Value = app.Slider_AC.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
        
        elseif isequal(SLabel,app.SliderButton_TS.Text)
            app.Slider_TS.Value = SValue;
            runSpiderAPP(app)
            app.EditField_TS.Value = app.Slider_TS.Value;
            app.EditField_TS_2.Value = app.Slider_TS.Value;
            Weighting_Pattern(app); 
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
        
        elseif isequal(SLabel,app.SliderButton_CA.Text)
            app.Slider_CA.Value = SValue;
            runSpiderAPP(app)
            app.EditField_CA.Value = app.Slider_CA.Value;
            app.EditField_CA_2.Value = app.Slider_CA.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
        
        elseif isequal(SLabel,app.SliderButton_SA.Text)
            app.Slider_SA.Value = SValue;
            runSpiderAPP(app)
            app.EditField_SA.Value = app.Slider_SA.Value;
            app.EditField_SA_2.Value = app.Slider_SA.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
        
        elseif  isequal(SLabel,app.SliderButton_TC.Text)
            app.Slider_TC.Value = SValue;
            runSpiderAPP(app)
            app.EditField_TC.Value = app.Slider_TC.Value;
            app.EditField_TC_2.Value = app.Slider_TC.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
        
        end
    
    end
end

methods (Access = private)
    
function  SaveVD(app)
%%%Description: Function to save the results of the properties evaluation

    app.Pattern(2,1) = app.Slider_AC.Value;
    app.Pattern(3,1) = app.Slider_TS.Value;
    app.Pattern(4,1) = app.Slider_CA.Value;
    app.Pattern(5,1) = app.Slider_SA.Value;
    app.Pattern(6,1) = app.Slider_TC.Value;
    app.Pattern(1,1) = app.Slider_7.Value;

end
    
function NameLable(app)
%%% Description: This function regulates the Label name of the Update Weighting Paneö
%% Authors: Lorenzo Nicoletti, Maximilian Bayerlein

    W = app.Weightings{1,2};
    
    app.Label_AC.Text = {['Acceleration x ', num2str(W(1,1))]};
    app.Label2_TS.Text = {['+ top speed x ', num2str(W(1,2))]};
    app.Label2_CA.Text = {['+ climbing ability x ', num2str(W(1,3))]};
    app.Label2_SA.Text = {['+ side acceleration x ', num2str(W(1,4))]};
    app.Label2_TC.Text = {['+ turning cycle x ', num2str(W(1,5))]};

end
      
        
function Block(app)
%%%%Description: Function to block some components during the weighting factors update
    app.Slider_AC.Enable = 'off';
    app.Slider_TS.Enable = 'off';
    app.Slider_CA.Enable = 'off';
    app.Slider_SA.Enable = 'off';
    app.Slider_TC.Enable = 'off';
    app.Slider_7.Enable = 'off';
    
    app.SliderButton_AC.Enable = 'off';
    app.SliderButton_TS.Enable = 'off';
    app.SliderButton_CA.Enable = 'off';
    app.SliderButton_SA.Enable = 'off';
    app.SliderButton_TC.Enable = 'off';
    app.GoBackButton.Enable = 'off';
    app.SaveBackButton.Enable = 'off';
    app.ResetButton.Enable = 'off';

end
        
function Unblock(app)
%%%%Description: Function to unblock some components during the weighting factors update
    app.Slider_AC.Enable = 'on';
    app.Slider_TS.Enable = 'on';
    app.Slider_CA.Enable = 'on';
    app.Slider_SA.Enable = 'on';
    app.Slider_TC.Enable = 'on';
    app.Slider_7.Enable = 'on';
    
    app.SliderButton_AC.Enable = 'on';
    app.SliderButton_TS.Enable = 'on';
    app.SliderButton_CA.Enable = 'on';
    app.SliderButton_SA.Enable = 'on';
    app.SliderButton_TC.Enable = 'on';
    app.GoBackButton.Enable = 'on';
    app.SaveBackButton.Enable = 'on';
    app.ResetButton.Enable = 'on';

end

function Weighting_Pattern(app)
%%% Description: This function simply updates the weightings

    %Retrieve the values of the Gauge (if they have been changed)
    V = [app.Slider_AC.Value; app.Slider_TS.Value; app.Slider_CA.Value; app.Slider_SA.Value; app.Slider_TC.Value];
    
    %Recalculate the resulting total value for the "Vehicle dynamics" properties
    W = app.Weightings{1,2};
    app.EditField_14.Value = (W*V)/sum(W);
    app.Gauge.Value =  app.EditField_14.Value;

end
    
       
function Lamps(app)
%%% Description: This function regulates the Lamp colors for the "Compare Vehicle" Panels
%% Authors: Lorenzo Nicoletti, Maximilian Bayerlein
        
    if ~isequal(app.ComparisonVehicle_1,'Please choose a vehicle')     
        
%         app.Lamp_V1.Color = [0 1 1];
        app.Lamp_V1.Color = [162 173 0]./255;
        app.Label_V1.Text=app.ComparisonVehicle_1;
    end
    
    if ~isequal(app.ComparisonVehicle_2,'Please choose a vehicle')
       
%         app.Lamp_V2.Color = [0 1 0]; 
        app.Lamp_V2.Color = [227 114 34]./255; 
        app.Label_V2.Text=app.ComparisonVehicle_2;
    end
    
    if ~isequal(app.ComparisonVehicle_3,'Please choose a vehicle')
        
%         app.Lamp_V3.Color = [1 1 0];       
        app.Lamp_V3.Color = [152 198 234]./255;  
        app.Label_V3.Text=app.ComparisonVehicle_3;
    end      

end
    
function [data, lbl] = runSpiderAPP(app)
%%% Description: This function Plots the spider Diagram in the Gui for the choosen vehicle concept and the chosen comparion vehicles
%% Author: Lorenzo Nicoletti, Maximilian Bayerlein

    num=app.EV;

    % Retrieve the labels for the spider diagramm -> the second level properties for the first level property "COSTS"
    idx=find(ismember(num(:,1),'Vehicle dynamics'));
    idx_start=idx+1;
    lbl = [num(idx_start,1),num(idx_start+1,1),num(idx_start+2,1),num(idx_start+3,1),num(idx_start+4,1)]';
    

    % The values for the spider diagramm
    A = [app.Slider_AC.Value app.Slider_TS.Value app.Slider_CA.Value app.Slider_SA.Value app.Slider_TC.Value]';

    %If any Compare vehicles are chosen, retrieve their value
    [V1,V2,V3, app] = Compare_Vehicles(app);
    data =  [V1 V2 V3 A];
    
    ax = app.Spider_UIAxes;
    hold on
    
    % plot spider diagramm
    spiderAPP(data,lbl,ax)

end

function [V1, V2, V3, app] = Compare_Vehicles(app)
%%% Description: This function adds the plots of the Comparison vehicles on the spider Diagramm
%Author: Lorenzo Nicoletti, Maximilian Bayerlein

    num=app.EV;

    %Find the position of the evaluated subproperties of the property "COSTS"
    idx=find(ismember(num(:,1),'Vehicle dynamics'));
    idx_start=idx+1;
    idx_end=idx_start+numel(app.Weightings{2,1})-1;

    %Retrieve the data for Comparision vehicle number 1
    for ii = 4:size(num,2)
    
        if isequal(app.ComparisonVehicle_1, 'Please choose a vehicle')
            
            V1 = [4 4 4 4 4]';
            break;
        
        elseif isequal(app.ComparisonVehicle_1, num{1,ii})
            
            V1 = cell2mat(num(idx_start:idx_end,ii));
            break; 
        end
    end

    %Retrieve the data for Comparision vehicle number 2
    for ii = 4:size(num,2)
    
        if isequal(app.ComparisonVehicle_2, 'Please choose a vehicle')
            
            V2 = [4 4 4 4 4]';
            break;
        
        elseif isequal(app.ComparisonVehicle_2, num{1,ii})
            
            V2 = cell2mat(num(idx_start:idx_end,ii));
            break;
            
        end
    end

    %Retrieve the data for Comparision vehicle number 3
    for ii = 4:size(num,2)
        
        if isequal(app.ComparisonVehicle_3, 'Please choose a vehicle')
            
            V3 = [4 4 4 4 4]';
            break;
            
        elseif isequal(app.ComparisonVehicle_3, num{1,ii})
            
            V3 = cell2mat(num(idx_start:idx_end,ii));
            break;
        end
    end
end   

function UpdateWeightingFactors(app)
%%%Description:This function allows to change the weighting factors of the second level properties of the first level property "COSTS"
%Author: Lorenzo Nicoletti, Maximilian Bayerlein
            
    if app.UpdateWeightingsButton.Value == 0
    
        app.DropDown_AC.Visible = 'off';
        app.DropDown_TS.Visible = 'off';
        app.DropDown_CA.Visible = 'off';
        app.DropDown_SA.Visible = 'off';
        app.DropDown_TC.Visible = 'off';
        
        app.Weightings{1,2} = [app.DropDown_AC.Value, app.DropDown_TS.Value, app.DropDown_CA.Value, app.DropDown_SA.Value, app.DropDown_TC.Value];
        
        %Update the Labels
        NameLable(app);
        
        %Reevaluate the custormer properties with the new weighting factors
        Weighting_Pattern(app);
        
        %Unblock the components blocked before (the first time the button was pressed)
        Unblock(app);
            
    elseif app.UpdateWeightingsButton.Value == 1
    
        app.DropDown_AC.Visible = 'on';
        app.DropDown_TS.Visible = 'on';
        app.DropDown_CA.Visible = 'on';
        app.DropDown_SA.Visible = 'on';
        app.DropDown_TC.Visible = 'on';
        
        %Update the Labels with the new weighting factors
        app.Label_AC.Text = {'Acceleration x '};
        app.Label2_TS.Text = {'+ top speed x '};
        app.Label2_CA.Text = {'+ climbing ability x '};
        app.Label2_SA.Text = {'+ side acceleration x '};
        app.Label2_TC.Text = {'+ turning cycle x '};
        
        %Block the components which are not required -> The User can therefore only change the weighting factors
        Block(app);
    end
end

end %End of methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Start(app, EV, NEV, ComparisonVehicle_1, ComparisonVehicle_2, ComparisonVehicle_3, Pattern, Weightings)
            
            app.EV = EV;
            app.NEV= NEV;
            app.ComparisonVehicle_1 = ComparisonVehicle_1;
            app.ComparisonVehicle_2 = ComparisonVehicle_2;
            app.ComparisonVehicle_3 = ComparisonVehicle_3;
            app.Pattern = Pattern;
            app.Weightings = Weightings;
            app.ResetPattern=Pattern;

            %Set Slider Values 
            app.Slider_AC.Value = app.Pattern(2,1);
            app.Slider_TS.Value = app.Pattern(3,1);
            app.Slider_CA.Value = app.Pattern(4,1);
            app.Slider_SA.Value = app.Pattern(5,1);
            app.Slider_TC.Value = app.Pattern(6,1);
            app.Slider_7.Value = app.Pattern(1,1);
            
            %Set Corresponding Edit Field Values
            app.EditField_AC.Value = app.Slider_AC.Value;
            app.EditField_TS.Value = app.Slider_TS.Value;
            app.EditField_CA.Value = app.Slider_CA.Value;
            app.EditField_SA.Value = app.Slider_SA.Value;
            app.EditField_TC.Value = app.Slider_TC.Value;
            app.EditField_7.Value = app.Slider_7.Value;
            app.EditField_AC_2.Value = app.Slider_AC.Value;
            app.EditField_TS_2.Value = app.Slider_TS.Value;
            app.EditField_CA_2.Value = app.Slider_CA.Value;
            app.EditField_SA_2.Value = app.Slider_SA.Value;
            app.EditField_TC_2.Value = app.Slider_TC.Value;
            
            %Assign missing Labels
            NameLable(app);
            
            %Runs Spider app and generate Spider Diagramm
            runSpiderAPP(app);
            
            %Weight the Properties
            Weighting_Pattern(app);      
            
            %Set the Lamp colors according to the number of selected vehicles
            Lamps(app);

            %Initialize the Weighting and the Drop down Values of the Panel "Update Weighting Factors"
            app.DropDown_AC.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_AC.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_TS.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_TS.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_CA.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_CA.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_SA.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_SA.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_TC.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_TC.Items = {'1', '3', '4', '5', '9'};


            W = app.Weightings{1,2};
            app.DropDown_AC.Value = W(1); app.DropDown_TS.Value = W(2); app.DropDown_CA.Value = W(3); app.DropDown_SA.Value = W(4); app.DropDown_TC.Value = W(5);         

            UpdateWeightingFactors(app);             
        end

        % Value changed function: EditField_AC, Slider_AC
        function Slider_1ValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_AC.Value = app.Slider_AC.Value;
            app.EditField_AC_2.Value = app.Slider_AC.Value;
            app.Pattern(2,1) = app.Slider_AC.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_TS
        function Slider_TSValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_TS.Value = app.Slider_TS.Value;
            app.EditField_TS_2.Value = app.Slider_TS.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_CA
        function Slider_CAValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_CA.Value = app.Slider_CA.Value;
            app.EditField_CA_2.Value = app.Slider_CA.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_TC
        function Slider_TCValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_TC.Value = app.Slider_TC.Value;
            app.EditField_TC_2.Value = app.Slider_TC.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_SA
        function Slider_SAValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_SA.Value = app.Slider_SA.Value;
            app.EditField_SA_2.Value = app.Slider_SA.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
            runSpiderAPP(app)
            app.EditField_7.Value = app.Slider_7.Value;                       
        end

        % Button pushed function: SliderButton_AC
        function SliderButton_ACPushed(app, event)
            app.Value = app.Slider_AC.Value;
            app.Label = app.SliderButton_AC.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: SliderButton_TS
        function SliderButton_TSPushed(app, event)
            app.Value = app.Slider_TS.Value;
            app.Label = app.SliderButton_TS.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: SliderButton_CA
        function SliderButton_CAPushed(app, event)
            app.Value = app.Slider_CA.Value;
            app.Label = app.SliderButton_CA.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern, app.ResetPattern);
        end

        % Button pushed function: SliderButton_SA
        function SliderButton_SAPushed(app, event)
            app.Value = app.Slider_SA.Value;
            app.Label = app.SliderButton_SA.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: SliderButton_TC
        function SliderButton_TCPushed(app, event)
            app.Value = app.Slider_TC.Value;
            app.Label = app.SliderButton_TC.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            Mainapp(app.Pattern, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Weightings);
            delete(app.CorrelationApp);
            delete(app);
        end

        % Button pushed function: SaveBackButton
        function SaveBackButtonPushed(app, event)
            SaveVD(app);
            Mainapp(app.Pattern, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Weightings);
            delete(app.CorrelationApp);
            delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                       
            app.Slider_AC.Value = app.ResetPattern(2,1);
            app.Slider_TS.Value = app.ResetPattern(3,1);
            app.Slider_CA.Value = app.ResetPattern(4,1);
            app.Slider_SA.Value = app.ResetPattern(5,1);
            app.Slider_TC.Value = app.ResetPattern(6,1);
            app.Slider_7.Value  = app.ResetPattern(1,1);
            app.Pattern=app.ResetPattern;
            app.Gauge.Value=app.Pattern(1,1);
            app.EditField_14.Value=app.Pattern(1,1);
            
            app.EditField_AC.Value = app.Slider_AC.Value;
            app.EditField_TS.Value = app.Slider_TS.Value;
            app.EditField_CA.Value = app.Slider_CA.Value;
            app.EditField_SA.Value = app.Slider_SA.Value;
            app.EditField_TC.Value = app.Slider_TC.Value;
            app.EditField_7.Value = app.Slider_7.Value;
            
            app.EditField_AC_2.Value = app.Slider_AC.Value;
            app.EditField_TS_2.Value = app.Slider_TS.Value;
            app.EditField_CA_2.Value = app.Slider_CA.Value;
            app.EditField_TC_2.Value = app.Slider_SA.Value;
            app.EditField_SA_2.Value = app.Slider_TC.Value;  
            
            runSpiderAPP(app)
            NameLable(app);
        end

        % Close request function: COCA_Vehicle_Dynamics
        function COCA_Vehicle_DynamicsCloseRequest(app, event)
            delete(app.CorrelationApp);
            delete(app);           
        end

        % Value changed function: UpdateWeightingsButton
        function UpdateWeightingsButtonValueChanged(app, event)
            UpdateWeightingFactors(app);             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COCA_Vehicle_Dynamics = uifigure('Visible', 'off');
            app.COCA_Vehicle_Dynamics.Name = 'COCA - Vehicle Dynamics';
            app.COCA_Vehicle_Dynamics.CloseRequestFcn = createCallbackFcn(app, @COCA_Vehicle_DynamicsCloseRequest, true);
            app.Spider_UIAxes = uiaxes(app.COCA_Vehicle_Dynamics);
            title(app.Spider_UIAxes, 'Title')
            xlabel(app.Spider_UIAxes, 'X')
            ylabel(app.Spider_UIAxes, 'Y')
            app.Spider_UIAxes.XLim = [0 1];
            app.Spider_UIAxes.YLim = [0 1];
            app.Spider_UIAxes.ZLim = [0 1];
            app.Spider_UIAxes.CLim = [0 1];
            app.Spider_UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Spider_UIAxes.YTick = [0 0.5 1];
            app.Spider_UIAxes.ZTick = [0 0.5 1];
            app.Slider_AC = uislider(app.COCA_Vehicle_Dynamics);
            app.Slider_AC.Limits = [4 10];
            app.Slider_AC.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.Slider_AC.Value = 7.5;
            app.Slider_TS = uislider(app.COCA_Vehicle_Dynamics);
            app.Slider_TS.Limits = [4 10];
            app.Slider_TS.ValueChangedFcn = createCallbackFcn(app, @Slider_TSValueChanged, true);
            app.Slider_TS.Value = 7.5;
            app.Slider_CA = uislider(app.COCA_Vehicle_Dynamics);
            app.Slider_CA.Limits = [4 10];
            app.Slider_CA.ValueChangedFcn = createCallbackFcn(app, @Slider_CAValueChanged, true);
            app.Slider_CA.Value = 7.5;
            app.Slider_TC = uislider(app.COCA_Vehicle_Dynamics);
            app.Slider_TC.Limits = [4 10];
            app.Slider_TC.ValueChangedFcn = createCallbackFcn(app, @Slider_TCValueChanged, true);
            app.Slider_TC.Value = 7.5;
            app.Slider_SA = uislider(app.COCA_Vehicle_Dynamics);
            app.Slider_SA.Limits = [4 10];
            app.Slider_SA.ValueChangedFcn = createCallbackFcn(app, @Slider_SAValueChanged, true);
            app.Slider_SA.Value = 7.5;
            app.VehicleDynamicsLabel = uilabel(app.COCA_Vehicle_Dynamics);
            app.VehicleDynamicsLabel.Text = 'Vehicle Dynamics';
            app.EditField_AC = uieditfield(app.COCA_Vehicle_Dynamics, 'numeric');
            app.EditField_AC.ValueDisplayFormat = '%2.1f';
            app.EditField_AC.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.EditField_AC.Editable = 'off';
            app.EditField_TS = uieditfield(app.COCA_Vehicle_Dynamics, 'numeric');
            app.EditField_TS.ValueDisplayFormat = '%2.1f';
            app.EditField_TS.Editable = 'off';
            app.EditField_CA = uieditfield(app.COCA_Vehicle_Dynamics, 'numeric');
            app.EditField_CA.ValueDisplayFormat = '%2.1f';
            app.EditField_CA.Editable = 'off';
            app.EditField_SA = uieditfield(app.COCA_Vehicle_Dynamics, 'numeric');
            app.EditField_SA.ValueDisplayFormat = '%2.1f';
            app.EditField_SA.Editable = 'off';
            app.EditField_TC = uieditfield(app.COCA_Vehicle_Dynamics, 'numeric');
            app.EditField_TC.ValueDisplayFormat = '%2.1f';
            app.EditField_TC.Editable = 'off';
            app.SliderButton_AC = uibutton(app.COCA_Vehicle_Dynamics, 'push');
            app.SliderButton_AC.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_ACPushed, true);
            app.SliderButton_AC.Text = 'Acceleration';
            app.SliderButton_TS = uibutton(app.COCA_Vehicle_Dynamics, 'push');
            app.SliderButton_TS.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_TSPushed, true);
            app.SliderButton_TS.Text = 'Top speed';
            app.SliderButton_CA = uibutton(app.COCA_Vehicle_Dynamics, 'push');
            app.SliderButton_CA.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_CAPushed, true);
            app.SliderButton_CA.Text = 'Climbing ability';
            app.SliderButton_SA = uibutton(app.COCA_Vehicle_Dynamics, 'push');
            app.SliderButton_SA.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_SAPushed, true);
            app.SliderButton_SA.Text = 'Lateral acceleration';
            app.SliderButton_TC = uibutton(app.COCA_Vehicle_Dynamics, 'push');
            app.SliderButton_TC.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_TCPushed, true);
            app.SliderButton_TC.Text = 'Turning cycle';
            app.EditField_7 = uieditfield(app.COCA_Vehicle_Dynamics, 'numeric');
            app.EditField_7.ValueDisplayFormat = '%2.1f';
            app.EditField_7.Editable = 'off';
            app.VehicleDynamicsSHOULDLabel = uilabel(app.COCA_Vehicle_Dynamics);
            app.VehicleDynamicsSHOULDLabel.Text = 'Vehicle Dynamics -SHOULD';
            app.EditField_TC_2 = uieditfield(app.COCA_Vehicle_Dynamics, 'numeric');
            app.EditField_TC_2.ValueDisplayFormat = '%2.1f';
            app.EditField_TC_2.Editable = 'off';
            app.EditField_SA_2 = uieditfield(app.COCA_Vehicle_Dynamics, 'numeric');
            app.EditField_SA_2.ValueDisplayFormat = '%2.1f';
            app.EditField_SA_2.Editable = 'off';
            app.EditField_AC_2 = uieditfield(app.COCA_Vehicle_Dynamics, 'numeric');
            app.EditField_AC_2.ValueDisplayFormat = '%2.1f';
            app.EditField_AC_2.Editable = 'off';
            app.EditField_TS_2 = uieditfield(app.COCA_Vehicle_Dynamics, 'numeric');
            app.EditField_TS_2.ValueDisplayFormat = '%2.1f';
            app.EditField_TS_2.Editable = 'off';
            app.EditField_CA_2 = uieditfield(app.COCA_Vehicle_Dynamics, 'numeric');
            app.EditField_CA_2.ValueDisplayFormat = '%2.1f';
            app.EditField_CA_2.Editable = 'off';
            app.Label_AC = uilabel(app.COCA_Vehicle_Dynamics);
            app.Label_AC.Text = 'Label2';
            app.Label2_TS = uilabel(app.COCA_Vehicle_Dynamics);
            app.Label2_TS.Text = 'Label2';
            app.Label2_CA = uilabel(app.COCA_Vehicle_Dynamics);
            app.Label2_CA.Text = 'Label2';
            app.Label2_SA = uilabel(app.COCA_Vehicle_Dynamics);
            app.Label2_SA.Text = 'Label2';
            app.Label2_TC = uilabel(app.COCA_Vehicle_Dynamics);
            app.Label2_TC.Text = 'Label2';
            app.EditField_14 = uieditfield(app.COCA_Vehicle_Dynamics, 'numeric');
            app.EditField_14.ValueDisplayFormat = '%2.2f';
            app.EditField_14.Editable = 'off';
            app.GoBackButton = uibutton(app.COCA_Vehicle_Dynamics, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.SaveBackButton = uibutton(app.COCA_Vehicle_Dynamics, 'push');
            app.SaveBackButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBackButtonPushed, true);
            app.SaveBackButton.Text = 'Save & Back';
            app.ResetButton = uibutton(app.COCA_Vehicle_Dynamics, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Panel = uipanel(app.COCA_Vehicle_Dynamics);
            app.Lamp_V1 = uilamp(app.Panel);
            app.Lamp_V2 = uilamp(app.Panel);
            app.Lamp_V3 = uilamp(app.Panel);
            app.Label_V1 = uilabel(app.Panel);
            app.Label_V1.Text = 'No vehicle chosen';
            app.Label_V2 = uilabel(app.Panel);
            app.Label_V2.Text = 'No vehicle Chosen';
            app.Label_V3 = uilabel(app.Panel);
            app.Label_V3.Text = 'No vehicle chosen';
            app.DropDown_AC = uidropdown(app.COCA_Vehicle_Dynamics);
            app.DropDown_TS = uidropdown(app.COCA_Vehicle_Dynamics);
            app.DropDown_CA = uidropdown(app.COCA_Vehicle_Dynamics);
            app.DropDown_SA = uidropdown(app.COCA_Vehicle_Dynamics);
            app.DropDown_TC = uidropdown(app.COCA_Vehicle_Dynamics);
            app.UpdateWeightingsButton = uibutton(app.COCA_Vehicle_Dynamics, 'state');
            app.UpdateWeightingsButton.ValueChangedFcn = createCallbackFcn(app, @UpdateWeightingsButtonValueChanged, true);
            app.UpdateWeightingsButton.Text = 'Update Weightings';
            app.Gauge = uigauge(app.COCA_Vehicle_Dynamics, 'linear');
            app.Gauge.Limits = [4 10];
            app.Gauge.Value = 4;
            app.Slider_7 = uislider(app.COCA_Vehicle_Dynamics);
            app.Slider_7.Limits = [4 10];
            app.Slider_7.MajorTicks = [];
            app.Slider_7.MajorTickLabels = {};
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.Slider_7.MinorTicks = [];
            app.Slider_7.Value = 7.5;
            app.ISLabel = uilabel(app.COCA_Vehicle_Dynamics);
            app.ISLabel.Text = '- IS';
            % Show the figure after all components are created
            app.COCA_Vehicle_Dynamics.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vehicle_Dynamics(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COCA_Vehicle_Dynamics)

            % Execute the startup function
            runStartupFcn(app, @(app)Start(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COCA_Vehicle_Dynamics)
        end
    end
end