classdef Vehicle_Portfolio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COCAVehiclePortfolioUIFigure   matlab.ui.Figure
        MeasurementValuesLabel         matlab.ui.control.Label
        Panel_Vehicle_Dynamics         matlab.ui.container.Panel
        Turning_Cycle                  matlab.ui.control.NumericEditField
        Maximum_Speed                  matlab.ui.control.NumericEditField
        Acceleration                   matlab.ui.control.NumericEditField
        Acceleration_Label             matlab.ui.control.Label
        TurningCycle_Label             matlab.ui.control.Label
        Maximumspeed_Label             matlab.ui.control.Label
        VehicleDynamicsLabel           matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        kmhLabel                       matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        LateralAcceleration_Label      matlab.ui.control.Label
        Lateral_Acceleration           matlab.ui.control.NumericEditField
        gLabel                         matlab.ui.control.Label
        ClimbingAbility_Label          matlab.ui.control.Label
        Climbing_Ability               matlab.ui.control.NumericEditField
        Label_31                       matlab.ui.control.Label
        Panel_Suitability              matlab.ui.container.Panel
        TrunkvolumeLabel               matlab.ui.control.Label
        RangeLabel                     matlab.ui.control.Label
        NumberofseatsLabel             matlab.ui.control.Label
        PayloadLabel                   matlab.ui.control.Label
        Trunk_volume                   matlab.ui.control.NumericEditField
        EditField_Range                matlab.ui.control.NumericEditField
        EditField_noseat               matlab.ui.control.NumericEditField
        EditField_payload              matlab.ui.control.NumericEditField
        SuitabilityLabel               matlab.ui.control.Label
        LiterLabel                     matlab.ui.control.Label
        kmLabel                        matlab.ui.control.Label
        Label_17                       matlab.ui.control.Label
        kgLabel                        matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        LengthHeightLabel              matlab.ui.control.Label
        HeightWidthLabel               matlab.ui.control.Label
        Overhang_fWheelbaseLabel       matlab.ui.control.Label
        Overhang_rWheelbaseLabel       matlab.ui.control.Label
        WheelbaseWheeldiamLabel        matlab.ui.control.Label
        EditField_L_vs_H               matlab.ui.control.NumericEditField
        EditField_H_vs_W               matlab.ui.control.NumericEditField
        EditField_Of_vs_W              matlab.ui.control.NumericEditField
        EditField_Or_vs_W              matlab.ui.control.NumericEditField
        EditField_W_vs_Dw              matlab.ui.control.NumericEditField
        DesignLabel                    matlab.ui.control.Label
        Label_26                       matlab.ui.control.Label
        Label_27                       matlab.ui.control.Label
        Label_30                       matlab.ui.control.Label
        Label_29                       matlab.ui.control.Label
        Label_28                       matlab.ui.control.Label
        WheeldiameterLabel             matlab.ui.control.Label
        mmLabel_10                     matlab.ui.control.Label
        Wheel_Diameter                 matlab.ui.control.NumericEditField
        Panel_2                        matlab.ui.container.Panel
        HeadroomfrontLabel_2           matlab.ui.control.Label
        HeadroomrearLabel_2            matlab.ui.control.Label
        LegroomrearLabel               matlab.ui.control.Label
        AcousticsLabel                 matlab.ui.control.Label
        EditField_headroom_front       matlab.ui.control.NumericEditField
        EditField_headroom_rear        matlab.ui.control.NumericEditField
        EditField_legroom_Rear         matlab.ui.control.NumericEditField
        EditField_acoustics            matlab.ui.control.NumericEditField
        ComfortLabel                   matlab.ui.control.Label
        mmLabel_7                      matlab.ui.control.Label
        mmLabel_8                      matlab.ui.control.Label
        mmLabel_9                      matlab.ui.control.Label
        dBLabel                        matlab.ui.control.Label
        Panel_5                        matlab.ui.container.Panel
        CombinedoperationalcostsLabel  matlab.ui.control.Label
        ConsumptionLabel               matlab.ui.control.Label
        AssetcostsLabel                matlab.ui.control.Label
        EditField_combined_costs       matlab.ui.control.NumericEditField
        EditField_consumption          matlab.ui.control.NumericEditField
        EditField_asset_costs          matlab.ui.control.NumericEditField
        CostsLabel                     matlab.ui.control.Label
        kmLabel_3                      matlab.ui.control.Label
        kmLabel_2                      matlab.ui.control.Label
        Label_25                       matlab.ui.control.Label
    end


    properties (Access = public)
        TD
        Pattern
        Weightings
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, TD, Weightings, Pattern)
         %%%%Description: This function assigns the values previously calculated to the vehicle portfolio GUI
                  
            app.TD = TD;
            app.Weightings = Weightings;
            app.Pattern = Pattern;
            
            %% --------------------------------------------Vehicle Dynamics----------------------------------------------------
            
            % Set the Value for the acceleration in the GUI
            app.Acceleration.Limits = [2, 20];
                if app.TD(1) < app.Acceleration.Limits(1,1)
                    app.TD(1) = app.Acceleration.Limits(1,1);
                    
                elseif app.TD(1) > app.Acceleration.Limits(1,2) || isnan(app.TD(1))
                    
                    app.TD(1) = app.Acceleration.Limits(1,2);
                end
            app.Acceleration.Value = app.TD(1);
            
            % Set the Value for the top speed in the GUI
            app.Maximum_Speed.Limits = [40, 350];
                if app.TD(2) < app.Maximum_Speed.Limits(1,1)
                    app.TD(2) = app.Maximum_Speed.Limits(1,1);
                elseif app.TD(2) > app.Maximum_Speed.Limits(1,2) || isnan(app.TD(2))
                    app.TD(2) = app.Maximum_Speed.Limits(1,2);
                end
            app.Maximum_Speed.Value = app.TD(2);
            
            % Set the Value for the turning circle in the GUI
            app.Turning_Cycle.Limits = [3, 14];
                if app.TD(3) < app.Turning_Cycle.Limits(1,1)
                    app.TD(3) = app.Turning_Cycle.Limits(1,1);
                elseif app.TD(3) > app.Turning_Cycle.Limits(1,2) || isnan(app.TD(3))
                    app.TD(3) = app.Turning_Cycle.Limits(1,2);
                end
            app.Turning_Cycle.Value = app.TD(3);

            % Set the Value for the lateral acceleration in the GUI
            app.Lateral_Acceleration.Limits = [1, 2.2];
                if app.TD(27) < app.Lateral_Acceleration.Limits(1,1)
                    app.TD(27) = app.Lateral_Acceleration.Limits(1,1);
                elseif app.TD(27) > app.Lateral_Acceleration.Limits(1,2) || isnan(app.TD(27))
                    app.TD(27) = app.Lateral_Acceleration.Limits(1,2);
                end
            app.Lateral_Acceleration.Value = app.TD(27);

            % Set the Value for the climbing ability in the GUI
            app.Climbing_Ability.Limits = [25.0, 40.0];
                if app.TD(28) < app.Climbing_Ability.Limits(1,1)
                    app.TD(28) = app.Climbing_Ability.Limits(1,1);
                elseif app.TD(28) > app.Climbing_Ability.Limits(1,2) || isnan(app.TD(28))
                    app.TD(28) = app.Climbing_Ability.Limits(1,2);
                end
            app.Climbing_Ability.Value = app.TD(28);
            
            %% --------------------------------------End of Vehicle Dynamics-------------------------------------------------
  
            %% ---------------------------------------------------Comfort-----------------------------------------------------
            % Set the Value for the headroom front in the GUI
            app.EditField_headroom_front.Limits = [700, 1200];
                if app.TD(15) < app.EditField_headroom_front.Limits(1,1)
                    app.TD(15) = app.EditField_headroom_front.Limits(1,1);
                elseif app.TD(15) > app.EditField_headroom_front.Limits(1,2) || isnan(app.TD(15))
                    app.TD(15) = app.EditField_headroom_front.Limits(1,2);
                end
            app.EditField_headroom_front.Value = app.TD(15);
            
            % Set the Value for the headroom rear in the GUI
            app.EditField_headroom_rear.Limits = [0, 1200];
                if app.TD(16) < app.EditField_headroom_rear.Limits(1,1)
                    app.TD(16) = app.EditField_headroom_rear.Limits(1,1);
                elseif app.TD(16) > app.EditField_headroom_rear.Limits(1,2) || isnan(app.TD(16))
                    app.TD(16) = app.EditField_headroom_rear.Limits(1,2);
                end
            app.EditField_headroom_rear.Value = app.TD(16);
            
            % Set the Value for the legroom in the GUI
            app.EditField_legroom_Rear.Limits = [0, 1200];
            if app.TD(17) < app.EditField_legroom_Rear.Limits(1,1)
                app.TD(17) = app.EditField_legroom_Rear.Limits(1,1);
            elseif app.TD(17) > app.EditField_legroom_Rear.Limits(1,2) || isnan(app.TD(17))
                app.TD(17) = app.EditField_legroom_Rear.Limits(1,2);
            end
            app.EditField_legroom_Rear.Value = app.TD(17);
            
            % EditField_12 Acoustics
            app.EditField_acoustics.Limits = [0, 120];
                if app.TD(18) < app.EditField_acoustics.Limits(1,1)
                    app.TD(18) = app.EditField_acoustics.Limits(1,1);
                elseif app.TD(18) > app.EditField_acoustics.Limits(1,2) || isnan(app.TD(18))
                    app.TD(18) = app.EditField_acoustics.Limits(1,2);
                end
            app.EditField_acoustics.Value = app.TD(18);
            
            %% -------------------------------------------- End of Comfort-----------------------------------------------------
            
            %% -----------------------------------------------------Costs------------------------------------------------------
            % Set the Value for the combined operational costs in the GUI
            app.EditField_combined_costs.Limits = [7.6, 67.68];
                if app.TD(19) < app.EditField_combined_costs.Limits(1,1)
                    app.TD(19) = app.EditField_combined_costs.Limits(1,1);
                elseif app.TD(19) > app.EditField_combined_costs.Limits(1,2) || isnan(app.TD(19))
                    app.TD(19) = app.EditField_combined_costs.Limits(1,2);
                end
            app.EditField_combined_costs.Value = app.TD(19);
            
            % Set the Value for the consumption in the GUI
            app.EditField_consumption.Limits = [0, 15];
                if app.TD(20) < app.EditField_consumption.Limits(1,1)
                    app.TD(20) = app.EditField_consumption.Limits(1,1);
                elseif app.TD(20) > app.EditField_consumption.Limits(1,2) || isnan(app.TD(20))
                    app.TD(20) = app.EditField_consumption.Limits(1,2);
                end
            app.EditField_consumption.Value = app.TD(20);
            
            % Set the Value for the asset costs in the GUI
            app.EditField_asset_costs.Limits = [6500, 500000];
                if app.TD(21) < app.EditField_asset_costs.Limits(1,1) || isnan(app.TD(21))
                    app.TD(21) = app.EditField_asset_costs.Limits(1,1);
                elseif app.TD(21) > app.EditField_asset_costs.Limits(1,2) 
                    app.TD(21) = app.EditField_asset_costs.Limits(1,2);
                end
            app.EditField_asset_costs.Value = app.TD(21);
             %% ------------------------------------------------End of Costs----------------------------------------------------
             
             
             %% ---------------------------------------------------Suitability------------------------------------------------------
            % Set the Value for the trunk volume in the GUI
            app.Trunk_volume.Limits = [10, 900];
                if app.TD(11) < app.Trunk_volume.Limits(1,1)
                    app.TD(11) = app.Trunk_volume.Limits(1,1);
                elseif app.TD(11) > app.Trunk_volume.Limits(1,2) || isnan(app.TD(11))
                    app.TD(11) = app.Trunk_volume.Limits(1,2);
                end
            app.Trunk_volume.Value = app.TD(11);
            
            % Set the Value for the range in the GUI
            app.EditField_Range.Limits = [0, 1500];
                if app.TD(12) < app.EditField_Range.Limits(1,1)
                    app.TD(12) = app.EditField_Range.Limits(1,1);
                elseif app.TD(12) > app.EditField_Range.Limits(1,2) || isnan(app.TD(12))
                    app.TD(12) = app.EditField_Range.Limits(1,2);
                end
            app.EditField_Range.Value = app.TD(12);
            
            % Set the Value for the number of seats in the GUI
            app.EditField_noseat.Limits = [1, 9];
                if app.TD(13) < app.EditField_noseat.Limits(1,1)
                    app.TD(13) = app.EditField_noseat.Limits(1,1);
                elseif app.TD(13) > app.EditField_noseat.Limits(1,2) || isnan(app.TD(13))
                    app.TD(13) = app.EditField_noseat.Limits(1,2);
                end
            app.EditField_noseat.Value = app.TD(13);
            
            % Set the Value for the payload in the GUI
            app.EditField_payload.Limits = [100, 1300];
                if app.TD(14) < app.EditField_payload.Limits(1,1)
                    app.TD(14) = app.EditField_payload.Limits(1,1);
                elseif app.TD(14) > app.EditField_payload.Limits(1,2) || isnan(app.TD(14))
                    app.TD(14) = app.EditField_payload.Limits(1,2);
                end
            app.EditField_payload.Value = app.TD(14);
            
             %% -----------------------------------------------End of Suitability------------------------------------------------------
             
             
              %% ---------------------------------------------------Design------------------------------------------------------
            
            % Set the Value for the Length/Height ratio in the GUI
            app.EditField_L_vs_H.Value = app.TD(22);
            
            % Set the Value for the Height/Width ratio in the GUI
            app.EditField_H_vs_W.Value = app.TD(23);

            % Set the Value for the Overhang front/Wheelbase ratio in the GUI
            app.EditField_Of_vs_W.Value = app.TD(24);
            
            % Set the Value for the Overhang rear/Wheelbase ratio in the GUI
            app.EditField_Or_vs_W.Value = app.TD(25);
            
            % Set the Value for the Ratio Wheelbase/Wheel diameter
            app.EditField_W_vs_Dw.Value = app.TD(26);

            % Set the Value for the Wheel diameter
            app.Wheel_Diameter.Value = app.TD(10);
            
            %% ------------------------------------------------End of Design------------------------------------------------------


        end

        % Value changed function: EditField_headroom_front
        function EditField_headroom_frontValueChanged(app, event)
            value = app.EditField_headroom_front.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COCAVehiclePortfolioUIFigure = uifigure;
            app.COCAVehiclePortfolioUIFigure.Name = 'COCA - Vehicle Portfolio';
            app.MeasurementValuesLabel = uilabel(app.COCAVehiclePortfolioUIFigure);
            app.MeasurementValuesLabel.Text = 'Measurement Values';
            app.Panel_Vehicle_Dynamics = uipanel(app.COCAVehiclePortfolioUIFigure);
            app.Turning_Cycle = uieditfield(app.Panel_Vehicle_Dynamics, 'numeric');
            app.Turning_Cycle.ValueDisplayFormat = '%.1f';
            app.Maximum_Speed = uieditfield(app.Panel_Vehicle_Dynamics, 'numeric');
            app.Maximum_Speed.ValueDisplayFormat = '%.0f';
            app.Acceleration = uieditfield(app.Panel_Vehicle_Dynamics, 'numeric');
            app.Acceleration.ValueDisplayFormat = '%.1f';
            app.Acceleration_Label = uilabel(app.Panel_Vehicle_Dynamics);
            app.Acceleration_Label.Text = 'Acceleration (0-100)';
            app.TurningCycle_Label = uilabel(app.Panel_Vehicle_Dynamics);
            app.TurningCycle_Label.Text = 'Turning Cycle';
            app.Maximumspeed_Label = uilabel(app.Panel_Vehicle_Dynamics);
            app.Maximumspeed_Label.Text = 'Maximum speed';
            app.VehicleDynamicsLabel = uilabel(app.Panel_Vehicle_Dynamics);
            app.VehicleDynamicsLabel.Text = 'Vehicle Dynamics';
            app.sLabel = uilabel(app.Panel_Vehicle_Dynamics);
            app.sLabel.Text = 's';
            app.kmhLabel = uilabel(app.Panel_Vehicle_Dynamics);
            app.kmhLabel.Text = 'km/h';
            app.mLabel = uilabel(app.Panel_Vehicle_Dynamics);
            app.mLabel.Text = 'm';
            app.LateralAcceleration_Label = uilabel(app.Panel_Vehicle_Dynamics);
            app.LateralAcceleration_Label.Text = 'Lateral acceleration';
            app.Lateral_Acceleration = uieditfield(app.Panel_Vehicle_Dynamics, 'numeric');
            app.Lateral_Acceleration.ValueDisplayFormat = '%.1f';
            app.gLabel = uilabel(app.Panel_Vehicle_Dynamics);
            app.gLabel.Text = 'g';
            app.ClimbingAbility_Label = uilabel(app.Panel_Vehicle_Dynamics);
            app.ClimbingAbility_Label.Text = 'Climbing ability';
            app.Climbing_Ability = uieditfield(app.Panel_Vehicle_Dynamics, 'numeric');
            app.Climbing_Ability.ValueDisplayFormat = '%.1f';
            app.Label_31 = uilabel(app.Panel_Vehicle_Dynamics);
            app.Label_31.Text = '%';
            app.Panel_Suitability = uipanel(app.COCAVehiclePortfolioUIFigure);
            app.TrunkvolumeLabel = uilabel(app.Panel_Suitability);
            app.TrunkvolumeLabel.Text = 'Trunk volume';
            app.RangeLabel = uilabel(app.Panel_Suitability);
            app.RangeLabel.Text = 'Range';
            app.NumberofseatsLabel = uilabel(app.Panel_Suitability);
            app.NumberofseatsLabel.Text = 'Number of seats';
            app.PayloadLabel = uilabel(app.Panel_Suitability);
            app.PayloadLabel.Text = 'Payload';
            app.Trunk_volume = uieditfield(app.Panel_Suitability, 'numeric');
            app.Trunk_volume.ValueDisplayFormat = '%.0f';
            app.EditField_Range = uieditfield(app.Panel_Suitability, 'numeric');
            app.EditField_Range.ValueDisplayFormat = '%.0f';
            app.EditField_noseat = uieditfield(app.Panel_Suitability, 'numeric');
            app.EditField_noseat.ValueDisplayFormat = '%.0f';
            app.EditField_payload = uieditfield(app.Panel_Suitability, 'numeric');
            app.EditField_payload.ValueDisplayFormat = '%.0f';
            app.SuitabilityLabel = uilabel(app.Panel_Suitability);
            app.SuitabilityLabel.Text = 'Suitability';
            app.LiterLabel = uilabel(app.Panel_Suitability);
            app.LiterLabel.Text = 'Liter';
            app.kmLabel = uilabel(app.Panel_Suitability);
            app.kmLabel.Text = 'km';
            app.Label_17 = uilabel(app.Panel_Suitability);
            app.Label_17.Text = '-';
            app.kgLabel = uilabel(app.Panel_Suitability);
            app.kgLabel.Text = 'kg';
            app.Panel_3 = uipanel(app.COCAVehiclePortfolioUIFigure);
            app.LengthHeightLabel = uilabel(app.Panel_3);
            app.LengthHeightLabel.Text = 'Length/Height';
            app.HeightWidthLabel = uilabel(app.Panel_3);
            app.HeightWidthLabel.Text = 'Height/Width';
            app.Overhang_fWheelbaseLabel = uilabel(app.Panel_3);
            app.Overhang_fWheelbaseLabel.Text = 'Overhang_f/Wheelbase';
            app.Overhang_rWheelbaseLabel = uilabel(app.Panel_3);
            app.Overhang_rWheelbaseLabel.Text = 'Overhang_r/Wheelbase';
            app.WheelbaseWheeldiamLabel = uilabel(app.Panel_3);
            app.WheelbaseWheeldiamLabel.Text = 'Wheelbase/Wheel diam';
            app.EditField_L_vs_H = uieditfield(app.Panel_3, 'numeric');
            app.EditField_L_vs_H.ValueDisplayFormat = '%.3f';
            app.EditField_H_vs_W = uieditfield(app.Panel_3, 'numeric');
            app.EditField_H_vs_W.ValueDisplayFormat = '%.3f';
            app.EditField_Of_vs_W = uieditfield(app.Panel_3, 'numeric');
            app.EditField_Of_vs_W.ValueDisplayFormat = '%.3f';
            app.EditField_Or_vs_W = uieditfield(app.Panel_3, 'numeric');
            app.EditField_Or_vs_W.ValueDisplayFormat = '%.3f';
            app.EditField_W_vs_Dw = uieditfield(app.Panel_3, 'numeric');
            app.EditField_W_vs_Dw.ValueDisplayFormat = '%.3f';
            app.DesignLabel = uilabel(app.Panel_3);
            app.DesignLabel.Text = 'Design';
            app.Label_26 = uilabel(app.Panel_3);
            app.Label_26.Text = '-';
            app.Label_27 = uilabel(app.Panel_3);
            app.Label_27.Text = '-';
            app.Label_30 = uilabel(app.Panel_3);
            app.Label_30.Text = '-';
            app.Label_29 = uilabel(app.Panel_3);
            app.Label_29.Text = '-';
            app.Label_28 = uilabel(app.Panel_3);
            app.Label_28.Text = '-';
            app.WheeldiameterLabel = uilabel(app.Panel_3);
            app.WheeldiameterLabel.Text = 'Wheel diameter';
            app.mmLabel_10 = uilabel(app.Panel_3);
            app.mmLabel_10.Text = 'mm';
            app.Wheel_Diameter = uieditfield(app.Panel_3, 'numeric');
            app.Wheel_Diameter.ValueDisplayFormat = '%.0f';
            app.Panel_2 = uipanel(app.COCAVehiclePortfolioUIFigure);
            app.HeadroomfrontLabel_2 = uilabel(app.Panel_2);
            app.HeadroomfrontLabel_2.Text = 'Headroom front';
            app.HeadroomrearLabel_2 = uilabel(app.Panel_2);
            app.HeadroomrearLabel_2.Text = 'Headroom rear';
            app.LegroomrearLabel = uilabel(app.Panel_2);
            app.LegroomrearLabel.Text = 'Legroom rear';
            app.AcousticsLabel = uilabel(app.Panel_2);
            app.AcousticsLabel.Text = 'Acoustics';
            app.EditField_headroom_front = uieditfield(app.Panel_2, 'numeric');
            app.EditField_headroom_front.ValueDisplayFormat = '%.0f';
            app.EditField_headroom_front.ValueChangedFcn = createCallbackFcn(app, @EditField_headroom_frontValueChanged, true);
            app.EditField_headroom_rear = uieditfield(app.Panel_2, 'numeric');
            app.EditField_headroom_rear.ValueDisplayFormat = '%.0f';
            app.EditField_legroom_Rear = uieditfield(app.Panel_2, 'numeric');
            app.EditField_legroom_Rear.ValueDisplayFormat = '%.0f';
            app.EditField_acoustics = uieditfield(app.Panel_2, 'numeric');
            app.EditField_acoustics.ValueDisplayFormat = '%.1f';
            app.ComfortLabel = uilabel(app.Panel_2);
            app.ComfortLabel.Text = 'Comfort';
            app.mmLabel_7 = uilabel(app.Panel_2);
            app.mmLabel_7.Text = 'mm';
            app.mmLabel_8 = uilabel(app.Panel_2);
            app.mmLabel_8.Text = 'mm';
            app.mmLabel_9 = uilabel(app.Panel_2);
            app.mmLabel_9.Text = 'mm';
            app.dBLabel = uilabel(app.Panel_2);
            app.dBLabel.Text = 'dB';
            app.Panel_5 = uipanel(app.COCAVehiclePortfolioUIFigure);
            app.CombinedoperationalcostsLabel = uilabel(app.Panel_5);
            app.CombinedoperationalcostsLabel.Text = 'Combined operational costs';
            app.ConsumptionLabel = uilabel(app.Panel_5);
            app.ConsumptionLabel.Text = 'Consumption';
            app.AssetcostsLabel = uilabel(app.Panel_5);
            app.AssetcostsLabel.Text = 'Asset costs';
            app.EditField_combined_costs = uieditfield(app.Panel_5, 'numeric');
            app.EditField_combined_costs.ValueDisplayFormat = '%.0f';
            app.EditField_consumption = uieditfield(app.Panel_5, 'numeric');
            app.EditField_consumption.Limits = [0 Inf];
            app.EditField_consumption.ValueDisplayFormat = '%.1f';
            app.EditField_asset_costs = uieditfield(app.Panel_5, 'numeric');
            app.EditField_asset_costs.ValueDisplayFormat = '%.0f';
            app.CostsLabel = uilabel(app.Panel_5);
            app.CostsLabel.Text = 'Costs';
            app.kmLabel_3 = uilabel(app.Panel_5);
            app.kmLabel_3.Text = '€/100 km';
            app.kmLabel_2 = uilabel(app.Panel_5);
            app.kmLabel_2.Text = '€/100 km';
            app.Label_25 = uilabel(app.Panel_5);
            app.Label_25.Text = '€';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Vehicle_Portfolio(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COCAVehiclePortfolioUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COCAVehiclePortfolioUIFigure)
        end
    end
end