classdef Mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COCATool                        matlab.ui.Figure
        ExportDataButton                matlab.ui.control.Button
        ImportDataButton                matlab.ui.control.Button
        SollLabel_Sicherheit            matlab.ui.control.Label
        ISLabel_2                       matlab.ui.control.Label
        Slider_Safety                   matlab.ui.control.Slider
        Gauge_Safety                    matlab.ui.control.LinearGauge
        SliderButton_Safety             matlab.ui.control.Button
        EditField_Sicherheit            matlab.ui.control.NumericEditField
        SHOULD_5                        matlab.ui.control.Label
        SHOULDLabel                     matlab.ui.control.Label
        SHOULDLabel_2                   matlab.ui.control.Label
        SHOULD_2                        matlab.ui.control.Label
        SHOULD_1                        matlab.ui.control.Label
        ISLabel                         matlab.ui.control.Label
        ISLabel_6                       matlab.ui.control.Label
        ISLabel_4                       matlab.ui.control.Label
        ISLabel_3                       matlab.ui.control.Label
        ISLabel_5                       matlab.ui.control.Label
        Slider_Design                   matlab.ui.control.Slider
        Gauge_Design                    matlab.ui.control.LinearGauge
        Slider_Suitability              matlab.ui.control.Slider
        Gauge_Suitability               matlab.ui.control.LinearGauge
        Slider_Comfort                  matlab.ui.control.Slider
        Gauge_Comfort                   matlab.ui.control.LinearGauge
        Slider_Costs                    matlab.ui.control.Slider
        Gauge_Costs                     matlab.ui.control.LinearGauge
        Slider_Vehicle_dynamics         matlab.ui.control.Slider
        Gauge_Vehicle_Dynamics          matlab.ui.control.LinearGauge
        GenerateVehiclePortfolioButton  matlab.ui.control.Button
        Lamp_V3                         matlab.ui.control.Lamp
        Lamp_V2                         matlab.ui.control.Lamp
        Lamp_V1                         matlab.ui.control.Lamp
        ComparevehiclesPanel            matlab.ui.container.Panel
        Vehicle3DropDown                matlab.ui.control.DropDown
        Vehicle3DropDownLabel           matlab.ui.control.Label
        Vehicle2DropDown                matlab.ui.control.DropDown
        Vehicle2DropDownLabel           matlab.ui.control.Label
        Vehicle1DropDown                matlab.ui.control.DropDown
        Vehicle1DropDownLabel           matlab.ui.control.Label
        SliderButton_Design             matlab.ui.control.Button
        SliderButton_Suitability        matlab.ui.control.Button
        SliderButton_Comfort            matlab.ui.control.Button
        SliderButton_Costs              matlab.ui.control.Button
        SliderButton_VehicleDynamics    matlab.ui.control.Button
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_1                     matlab.ui.control.NumericEditField
        COCALabel                       matlab.ui.control.Label
        Spider_UIAxes                   matlab.ui.control.UIAxes
    end

%Public Proprieties of the variable app (we can save this fields directly in the app variable)
properties (Access = public)
    
    EV
    rawTD
    Pattern
    TD
    Weightings
    CV
    NEV
    First_level_is
    
end

%Public and Private methods for the variable app
methods (Access = public)
    
function  app = Set(app)
    
    %%%%Description: This function set the sliders according to the proprieties Pattern
    %Authors: Lorenzo Nicoletti, Maximilian Bayerlein
    
    %Set the slider Values
    app.Slider_Vehicle_dynamics.Value = app.Pattern(1,1);
    app.Slider_Costs.Value = app.Pattern(1,2);
    app.Slider_Comfort.Value = app.Pattern(1,3);
    app.Slider_Suitability.Value = app.Pattern(1,4);
    app.Slider_Design.Value = app.Pattern(1,5);
    app.Slider_Safety.Value = app.Pattern(1,6);
    
    %Set the corresponding Edit field Values
    app.EditField_1.Value = app.Slider_Vehicle_dynamics.Value;
    app.EditField_2.Value = app.Slider_Costs.Value;
    app.EditField_3.Value = app.Slider_Comfort.Value;
    app.EditField_4.Value = app.Slider_Suitability.Value;
    app.EditField_5.Value = app.Slider_Design.Value;
    app.EditField_Sicherheit.Value =app.Slider_Safety.Value;
    
    %Run the spider App to generate the Spider diagramm
    runSpiderAPP(app, app.EV);
    
end

end

methods (Access = private)
    
function   Save(app)

    app.Pattern(1,1) = app.Slider_Vehicle_dynamics.Value;
    app.Pattern(1,2) = app.Slider_Costs.Value;
    app.Pattern(1,3) = app.Slider_Comfort.Value;
    app.Pattern(1,4) = app.Slider_Suitability.Value;
    app.Pattern(1,5) = app.Slider_Design.Value;
    app.Pattern(1,6) = app.Slider_Safety.Value;

end
        
function Lamps(app)
    
    %%% Description: This function regulates the Lamp colors for the "Compare Vehicle" Panels
    %% Authors: Lorenzo Nicoletti, Maximilian Bayerlein
    
    if ~isequal(app.Vehicle1DropDown.Value,'Please choose a vehicle')     
        
%         app.Lamp_V1.Color = [0 1 1];
        app.Lamp_V1.Color = [162 173 0]./255;
    end
    
    if ~isequal(app.Vehicle2DropDown.Value,'Please choose a vehicle')
       
%         app.Lamp_V2.Color = [0 1 0]; 
        app.Lamp_V2.Color = [227 114 34]./255; 
    end
    
    if ~isequal(app.Vehicle3DropDown.Value,'Please choose a vehicle')
        
%         app.Lamp_V3.Color = [1 1 0];  
        app.Lamp_V3.Color = [152 198 234]./255;  
    end                
end
        
function NameLable(app)
    
    %%% Description: This function initializes the List of the vehcle List, which can be used for the vehicle characteristics comparison
    % Authors: Lorenzo Nicoletti, Maximilian Bayerlein

    %Create the List containing the name of the vehicles in the database
    List=app.EV(1,3:end);
    List{1,1}='Please choose a vehicle';

    %Assign the List to the Drop down menus
    app.Vehicle1DropDown.Items = List;
    app.Vehicle2DropDown.Items = List;
    app.Vehicle3DropDown.Items = List;  
    
 end

function [EV, NEV] = createEV(app)
    
    %%%Description: This function loads the vehicle data and teh custormer properties from the excel tabel. Subsequently, the script evaluates each custormer properties
    % using the avaiable vehicle data. The result is a matrix with the evaluated values of the first and second level czustormer propertes.
    %In this script we use following defonitions
    % A) Properties of the second level: These are the propreties required to build a propriety of the first level. Examples: Vehicle Costs, Weight and Consumption
    % B) Properties of the first level: Are build through evlauation of the properties of the second level. For Example the propertiey "COsts" Which is calculated through evlauation of the properties listed in point A)
    
    % Authors: Lorenzo Nicoletti, Maximilian Bayerlein
    
    %% Implementation:
    %1) Initialize the variables and generate the EV (Evaluated Vehicles) variable
    %2) Compile EV with the vehicle data coming from the EXCEL Table
    %3) Modify some variables before the evaluation of the vehicle data
    %4) Evaluate (with the range from 4 to 10) the vehicle data of the properties of the second level
    %5) Evaluate (with the range from 4 to 10) the vehicle data of the properties of the first level    
    
    %.............................................................................
    %1) Initialize the variables and generate the EV (Evaluated Vehicles) variable
    
    CV=app.CV;         %Contains the vehicle properties names exported from the EXCEL Table
    
    contains_NAN=find(cellfun(@(C) any(isnan(C(:))), app.rawTD(:,1),'UniformOutput',true));
    %Now you can check at which position the different KW are in rawKW -> And then take the rows and convert them in number thus generatintg B
    app.rawTD(contains_NAN,1)={''};
    
    %EV is the structure containing the evaluated properties with values raging from 4 to 10 -> It will be compiled in the next steps
    EV=app.CV;
    coldiff = size(app.rawTD, 2) - size(CV, 2);
    EV(:, end+1:end+coldiff) = {''};
    
    %...............................................................
    %2) Compile EV with the vehicle data coming from the EXCEL Table
    
    for i=1:size(EV,1) 
    
        if ~isempty(CV{i,1}) %Assure that the selected cell is not empty
        
            idx=find(ismember(app.rawTD(:,1),CV(i,1))); %Identify the idx in rawTD containing the selected element of CV -> i.e. the selected custormer property
            
            if ~isempty(idx)
                try
                %Compile the rows of rawTD containing the properties listed in CV
                EV(i,:)=app.rawTD(idx,:);
                catch
                    disp('AA')
                end
                
            end
        end
    end

    %Save the original EV (containing the vehicle data, still not evaluated) it will be required later
    NEV=EV;
    
    
    %..................................................................
    %3) Modify some variables before the evaluation of the vehicle data
    
    %Load the Custormer properties list and limits
    [Wiki, ~] = wiki();        
    go = 10; % obere Grenze
    gu = 4; % untere Grenze
    
    %The variable costs has to be converted from euro to 1000*euro
    idx=find(ismember(EV(:,1),'Asset Costs'));
    for i=4:size(EV,2)
        EV{idx,i}= EV{idx,i}/1000;
    end
    
    %..................................................................
    %4) Evaluate (with the range from 4 to 10) the vehicle data of the properties of the second level
    
    for col=4:size(EV,2)  %For evevery column of EV (i.e. for every vehicle contained in EV)     
    
        for row=1:size(EV,1)
        
            idx=find(ismember(Wiki(:,1),EV(row,1))); %Assign the propertiy contained in the row of EV to the Wiki variable
            if ~isempty(idx)
            
                g1=Wiki{idx,4}; %upper limit of the property value
                g2=Wiki{idx,5}; %Lower limit of the property value
                w = (g1-g2)/2;  %Mean value
                s = Wiki{idx,6}*(100/(g1-g2))*0.12; %slope value
                
                %Calculate the resulting value (from 4 to 10) using S curves
                EV{row,col}=(go-gu)/(1+exp(-s*(EV{row,col}-w-g2)))+gu;  
                
                if EV{row,col}==0 %The values must range between 4 and 10
                    EV(row,col)=4;
                end
            end
        end   
    end

    %For the number of seat proprety, a special conversion needs to be applied
    for col = 4:size(EV,2) %for each vehicle (column) of EV
        
        idx=find(ismember(NEV(:,1),'Number of seats')); %Find the row in id containinf the number of seat property
        
        num_seats=NEV{idx,col};
    
        switch num_seats   %num of seat of the vehicle contained in the selected col of EV
            case 9
                EV{idx,col}=10;
            case 8
                EV{idx,col}=9;
            case 7
               EV{idx,col}=8;
            case 5
                EV{idx,col}=7;
            case 4
                EV{idx,col}=6;
            case 2
                EV{idx,col}=5;
            case 1
                EV{idx,col}=4;
        end
    end

    %..................................................................
    %5) Evaluate (with the range from 4 to 10) the vehicle data of the properties of the first level
    
    for col=4:size(EV,2)      %for each vehicle (column) of EV  
        
        for i=1:6 %For all the 6 first level properties: Vehicle Dynamics, Costs, Suitability, Safety, Design, Comfort
        
            %Weight factors for the second level properties, required to calculate the i-te first level property
            weighting=app.Weightings(i,:);
            
            %Retrieve the evaluated values (from 4 to 10) of all the second level properties, which build up the i-te first level property
            idx=find(ismember(EV(:,1),weighting{1,1}));
            idx_start=idx+1;
            idx_end=idx_start+numel( weighting{1,2})-1;
            val=cell2mat(EV(idx_start:idx_end,col));

            %Calculate the resulting i-te first level property and assign it to EV
            %res=sum((weighting{1,2}').*val)/numel(weighting{1,2});
            res=sum((weighting{1,2}').*val)/sum(weighting{1,2});
            EV{idx,col}=res;
        
        end
    end

end 
 
function runSpiderAPP(app, EV)
    %%% Description: This function Plots the spider Diagram in the Gui for the choosen vehicle concept and the chosen comparion vehicles
    %% Author: Lorenzo Nicoletti, Maximilian Bayerlein

    % The labels for the spider diagramm
    lbl =[app.Weightings(1,1),app.Weightings(2,1),app.Weightings(3,1),app.Weightings(4,1),app.Weightings(5,1),app.Weightings(6,1)]';
    
    % The values for the spider diagramm
    A = [app.Slider_Vehicle_dynamics.Value app.Slider_Costs.Value app.Slider_Comfort.Value app.Slider_Suitability.Value app.Slider_Design.Value app.Slider_Safety.Value]';
    
    %If any Compare vehicles are chosen, retrieve their value
    [V1, V2, V3, app] = Compare_Vehicles(app,EV);
    data = [V1 V2 V3 A];
    
    ax = app.Spider_UIAxes;
    hold on
    
    % plot spider (of the original vehicle concept)
    spiderAPP(data,lbl,ax)
    
    %Plot the Spider if the properties have been changed (in another window for examople)
    ax = app.Spider_UIAxes;
    hold on
    SpiderAPP_prop_changed(app.First_level_is',ax)

end

function [V1, V2, V3, app] = Compare_Vehicles(app,num)
    %%% Description: This function adds the plots of the Comparison vehicles on the spider Diagramm
    %Author: Lorenzo Nicoletti, Maximilian Bayerlein
    
    %First level properties
    first_level_prop=app.Weightings(:,1);
    
    %Find the rows of the first level properties in the variable app.EV
    for i=1:numel(first_level_prop)
        
        idex_first_level(i)=find(ismember(num(:,1),first_level_prop{i,1}));
    end
    
    %Plot the first Comparision vehicle (if selected)
    for ii = 4:size(num,2)
        
        if isequal(app.Vehicle1DropDown.Value, 'Please choose a vehicle')
                V1 = [4 4 4 4 4 4]';
                break;
                
        elseif isequal(app.Vehicle1DropDown.Value, num{1,ii})
                     V1 = cell2mat(num(idex_first_level,ii));
                     break;
        end
    end

    %Plot the second Comparision vehicle (if selected)
    for ii = 4:size(num,2)
        
        if isequal(app.Vehicle2DropDown.Value, 'Please choose a vehicle')
                V2 = [4 4 4 4 4 4]';
                break;
                
        elseif  isequal(app.Vehicle2DropDown.Value, num{1,ii})
                     V2 = cell2mat(num(idex_first_level,ii));
                     break;
        end
    end
    
    %Plot the third Comparision vehicle (if selected)
    for ii = 4:size(num,2)
        
        if isequal(app.Vehicle3DropDown.Value, 'Please choose a vehicle')
                V3 = [4 4 4 4 4 4]';
                break;
                
        elseif isequal(app.Vehicle3DropDown.Value, num{1,ii})
                     V3 = cell2mat(num(idex_first_level,ii));
                     break;
        end
    end   
    
end 

function [TD] = Generatevehicleportfolio(app, Pattern)
    %%%% Description: This function translate the chosen Vehicle Concept (described with scala from 4 to 10) in a real vehicle described by
    %the property contained in Excel Table. I.e. The function converts the scala 4-10 back to the original values

    [Wiki, ~] = wiki();
    
    Grenze= cell2mat(Wiki(:,4:5));
    Wendepunkt= (Grenze(:,1)-Grenze(:,2))./2;
    Steigung=cell2mat(Wiki(:,6));
    Steigung = Steigung.*(100./(Grenze(:,1)-Grenze(:,2))).*0.12;
    
    go = 10; %Upper limit of the conversion range   
    gu = 4;  %Lower Limit for the conversion range

    %Calculate the combined operational costs
    Operational_costs_combined =  (log((go-gu)/(Pattern(3,2)-gu)-1)/-Steigung(7))+Wendepunkt(7)+Grenze(7,2); %in euro/100 km

    %Calculate Vehicle Dimensions
    L101 = (log((go-gu)/(Pattern(4,5)-gu)-1)/-Steigung(19))+Wendepunkt(19)+Grenze(19,2);       % Wheelbase in mm
    L104 = (log((go-gu)/(Pattern(2,5)-gu)-1)/-Steigung(17))+Wendepunkt(17)+Grenze(17,2);       % Front Overhang in mm
    L105 = (log((go-gu)/(Pattern(3,5)-gu)-1)/-Steigung(18))+Wendepunkt(18)+Grenze(18,2);       % Rear Overhang in mm

    H_B = (log((go-gu)/(Pattern(5,5)-gu)-1)/-Steigung(20))+Wendepunkt(20)+Grenze(20,2);        % Ratio Height to Width
    L_H = (log((go-gu)/(Pattern(6,5)-gu)-1)/-Steigung(21))+Wendepunkt(21)+Grenze(21,2);        % Ratio Length to Height
    D_w = (log((go-gu)/(Pattern(7,5)-gu)-1)/-Steigung(22))+Wendepunkt(22)+Grenze(22,2);        %Diameter wheel in mm
    UfW = L104/L101;                                                                           %Ratio Front overhang wheelbase
    UrW = L105/L101;                                                                           %Ratio rear Overhang wheelbase
    WDw = L101/D_w;                                                                            %Ratio Wheelbase wheel diameter

    Total_length = L101 + L104 + L105;                                                         %Total vehicle length in mm
    Total_height = Total_length / L_H;                                                         %Total Vehicle height in mm
    Total_width = Total_height / H_B;                                                          %Total vehicle width in mm

    % Calculate vehicle Performance
    Acceleration = (log((go-gu)/(Pattern(2,1)-gu)-1)/-Steigung(1))+Wendepunkt(1)+Grenze(1,2);  % 0 - 100 in s
    Acceleration=max(Acceleration,2);                                                          %-> Accept only a minimum value of 2 m/s^2
    vmax = (log((go-gu)/(Pattern(3,1)-gu)-1)/-Steigung(2))+Wendepunkt(2)+Grenze(2,2);          % Max speed in m/s
    Turning_Cycle = (log((go-gu)/(Pattern(6,1)-gu)-1)/-Steigung(5))+Wendepunkt(5)+Grenze(5,2); %Turning cycle in m
    Lateral_Acceleration = (log((go-gu)/(Pattern(5,1)-gu)-1)/-Steigung(4))+Wendepunkt(4)+Grenze(4,2); %Lateral Acceleration in g
    Climbing_Ability = (log((go-gu)/(Pattern(4,1)-gu)-1)/-Steigung(3))+Wendepunkt(3)+Grenze(3,2); %Turning cycle in m
    
    % Calculate Suitability
    Trunk_volume = (log((go-gu)/(Pattern(2,4)-gu)-1)/-Steigung(13))+Wendepunkt(13)+Grenze(13,2); %Tank volume in Liter
    Range = (log((go-gu)/(Pattern(5,4)-gu)-1)/-Steigung(16))+Wendepunkt(16)+Grenze(16,2);        %Max vehicle Range in km
    Payload = (log((go-gu)/(Pattern(3,4)-gu)-1)/-Steigung(14))+Wendepunkt(14)+Grenze(14,2);    %Maximum Payload in kg

    % Komfort
    Headroom_f = (log((go-gu)/(Pattern(2,3)-gu)-1)/-Steigung(9))+Wendepunkt(9)+Grenze(9,2);      %Headroom front in mm
    Headroom_r = (log((go-gu)/(Pattern(4,3)-gu)-1)/-Steigung(11))+Wendepunkt(11)+Grenze(11,2);   %Headroom rear in mm
    Legroom_r = (log((go-gu)/(Pattern(3,3)-gu)-1)/-Steigung(10))+Wendepunkt(10)+Grenze(10,2);    %Legroom rear in mm
    Acoustics = (log((go-gu)/(Pattern(5,3)-gu)-1)/-Steigung(12))+Wendepunkt(12)+Grenze(12,2);    %Acoustics of the vehicle

    % Costs
    Consumption = (log((go-gu)/(Pattern(4,2)-gu)-1)/-Steigung(8))+Wendepunkt(8)+Grenze(8,2);     %Consumption of the vehicle
    Costs = (log((go-gu)/(Pattern(2,2)-gu)-1)/-Steigung(6))+Wendepunkt(6)+Grenze(6,2);           %Asset Costs in 1000 euro
    Costs = Costs*1000;                                                                          %Asset Costs in 1000 euro

    Consumption = round(Consumption,2);
    if Pattern(4,2) == 10
        Consumption = 0;
    end
    
    %Adjust the number of seats:
    
    if Pattern(4,4) <= 4.3
    Sitzezahl = 1;           
    elseif Pattern(4,4) <= 5.6           
    Sitzezahl = 2;           
    elseif Pattern(4,4) <= 6.6           
    Sitzezahl = 4;            
    elseif Pattern(4,4) <= 7.6
    Sitzezahl = 5;           
    elseif Pattern(4,4)  <= 8.6
    Sitzezahl = 7;            
    elseif Pattern(4,4)  <= 9.6
    Sitzezahl = 8;           
    else 
    Sitzezahl = 9;
    end

    %Output to compile the vehicle Portfolio
    TD = [Acceleration;
        vmax;
        Turning_Cycle;
        Total_length;
        Total_width;
        Total_height;
        L101;   
        L104;
        L105;
        D_w;
        Trunk_volume;
        Range;
        Sitzezahl;
        Payload;
        Headroom_f;
        Headroom_r;
        Legroom_r;
        Acoustics;
        Operational_costs_combined;
        Consumption;
        Costs;
        L_H;
        H_B;
        UfW;
        UrW;
        WDw;
        Lateral_Acceleration;
        Climbing_Ability];

end

function app=Weighting_Pattern(app)
    
    %%%%Descripition: This function saves the Evaluation Pattern of the simulated vehicle Concept. The Evaluation Pattern is saved in the varaible Pattern
    %The variable Pattern contains the value from 4 to 10 of each of the second level and first level properties. It is structured as follows:
    %             Vehicle Dynamics             |          Costs                  |  ... |
    % first 2nd lev. Prop of Vehicle Dynamics  |   first 2nd lev. Prop of Costs  | .... |
    % second 2nd lev. Prop of Vehicle Dynamics |   second 2nd lev. Prop of Costs | .... |
    % ........................................ |.................................| .... |
    %The first row contains the evaluated first level properties. The other rows contain the values of the corresponding second level properties
    
    %Author: Lorenzo Nicoletti
    
    if sum(app.Pattern(1,:))==0 %If the Pattern does not exist (first time Mainapp is called) initialize it
        app.Pattern = zeros(7,6)+7.5; 
    end
    
    %The values of the second level properties may have been changed, therefore the first level properties values have to be recalculated
    second_level_pattern=app.Pattern(2:end,:);
            
    %Recalculate the first level Pattern
    for ii = 1:6
        
        prop_values=second_level_pattern(1:length(app.Weightings{ii,2}),ii);
        prop_weightings=app.Weightings{ii,2};
        
        first_level_pattern(ii) = (prop_weightings*prop_values)/sum(prop_weightings);

    end
    
    %Assign the values to the Pattern variable
    app.First_level_is=first_level_pattern;
    
    %Assign the values to the GUI
    app.Gauge_Vehicle_Dynamics.Value = first_level_pattern(1);
    app.Gauge_Costs.Value = first_level_pattern(2);
    app.Gauge_Comfort.Value = first_level_pattern(3);
    app.Gauge_Suitability.Value = first_level_pattern(4);
    app.Gauge_Design.Value = first_level_pattern(5);
    app.Gauge_Safety.Value =first_level_pattern(6); 

    end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Start(app, Pattern, ComparisonVehicle_1, ComparisonVehicle_2, ComparisonVehicle_3, Weightings)
        %%% Description: This is the Main of the function and it is called once the function is started.             
        %Author: Lorenzo Nicoletti, Maximilian Bayerlein
                
            %Add all subfolder to the MATLAB path (the folders containing the database and the subapps)
            fullpath=mfilename('fullpath');
            apppath=fullpath(1:strfind(fullpath,'Mainapp')-1);
            addpath(genpath(apppath));
        
            %Load the Weighting factors for the Custormer Properties
            if ~exist('Weightings')==1 %This is the first time that the function is called: Load the weighting factors from function wiki
                
                [~, app.Weightings] = wiki();
            else %The function has been called from another window. Which means that the weighting factors may have changed. Assign the new weighting factors
                
                app.Weightings = Weightings;
            end
            
            if exist('Pattern')==1 %If the Pattern exists it may have been modified in another window. Assing the pattern with the actual weighting values
                app.Pattern=Pattern;
                if isempty(Pattern)
                    app.Pattern=zeros(7,6);
                end
            else
                app.Pattern=zeros(7,6); %Initialize the variables -> The zeros are used as marker for other function to identify that the variable has not been defined yet
            end
            
            %Weight the Evaluated Properties according to the loaded weighting factors
            app= Weighting_Pattern(app);
            
            
            if isunix==1 %We are sadly on a "Computer"

                %Read the vehicle data from the excel Table and import it in MATLAB
                [~,~, app.rawTD] = xlsread('Vehicle_Data.xlsx','Vehicle Data'); %Read data
                
                %Read the List of the of the vehicle data and how they are organized
                [~, app.CV,~] = xlsread('Vehicle_Data.xlsx', 'Properties'); %Read data
                
                %rawTD=load('rawTD.mat');
                %CV=load('CV.mat');
                
                %app.rawTD=rawTD.rawTD;
                %app.CV=CV.CV;
                
            else %We are on a real Computer
                
                %Read the vehicle data from the excel Table and import it in MATLAB
                [~,~, app.rawTD] = xlsread('Vehicle_Data.xlsx','Vehicle Data'); %Read data
                
                %Read the List of the of the vehicle data and how they are organized
                [~, app.CV,~] = xlsread('Vehicle_Data.xlsx', 'Properties'); %Read data

                
            end     

            
            %FOr each vehicle contained in the database, read its data and evaluate it according to the Custormer proprieties
            [app.EV, app.NEV] = createEV(app);
            
            %Set the Label in the GUI
            NameLable(app);  
            
            if exist('ComparisonVehicle_1')==1
                app.Vehicle1DropDown.Value = ComparisonVehicle_1;
                app.Vehicle2DropDown.Value = ComparisonVehicle_2;
                app.Vehicle3DropDown.Value = ComparisonVehicle_3;
            end
            
            %Set the Slider values and generate the Spider diagramm
            app=Set(app);
            
            %Set the Laps according to the chosen (if any) comparison vehicles
            Lamps(app);
            
        end

        % Value changed function: EditField_1, Slider_Vehicle_dynamics
        function Slider_1ValueChanged(app, event)
            runSpiderAPP(app,app.EV);
            app.EditField_1.Value = app.Slider_Vehicle_dynamics.Value;
        end

        % Value changed function: Slider_Costs
        function Slider_CostsValueChanged(app, event)
            runSpiderAPP(app,app.EV);
            app.EditField_2.Value = app.Slider_Costs.Value;
        end

        % Value changed function: Slider_Comfort
        function Slider_ComfortValueChanged(app, event)
            runSpiderAPP(app,app.EV);
            app.EditField_3.Value = app.Slider_Comfort.Value;
        end

        % Value changed function: Slider_Design
        function Slider_DesignValueChanged(app, event)
            runSpiderAPP(app,app.EV);
            app.EditField_5.Value = app.Slider_Design.Value;
        end

        % Value changed function: Slider_Suitability
        function Slider_SuitabilityValueChanged(app, event)
            runSpiderAPP(app,app.EV);
            app.EditField_4.Value = app.Slider_Suitability.Value;
        end

        % Value changed function: Vehicle1DropDown
        function Vehicle1DropDownValueChanged(app, event)
            app.Vehicle1DropDown.Value;
            Lamps(app);
            runSpiderAPP(app,app.EV);
        end

        % Value changed function: Vehicle2DropDown
        function Vehicle2DropDownValueChanged(app, event)
            app.Vehicle2DropDown.Value;
            runSpiderAPP(app,app.EV);
            Lamps(app);
        end

        % Value changed function: Vehicle3DropDown
        function Vehicle3DropDownValueChanged(app, event)
            app.Vehicle3DropDown.Value;
            runSpiderAPP(app,app.EV);
            Lamps(app); 
        end

        % Button pushed function: SliderButton_VehicleDynamics
        function SliderButton_VehicleDynamicsPushed(app, event)
            Save(app);
            Vehicle_Dynamics(app.EV,app.NEV,app.Vehicle1DropDown.Value, app.Vehicle2DropDown.Value, app.Vehicle3DropDown.Value, app.Pattern, app.Weightings);                       
            delete(app);
        end

        % Button pushed function: SliderButton_Costs
        function SliderButton_CostsPushed(app, event)
            Save(app);
            Costs(app.EV,app.NEV,app.Vehicle1DropDown.Value, app.Vehicle2DropDown.Value, app.Vehicle3DropDown.Value, app.Pattern, app.Weightings);                       
            delete(app);           
        end

        % Button pushed function: SliderButton_Comfort
        function SliderButton_ComfortPushed(app, event)
            Save(app);
            Comfort(app.EV,app.NEV,app.Vehicle1DropDown.Value, app.Vehicle2DropDown.Value, app.Vehicle3DropDown.Value, app.Pattern, app.Weightings);                       
            delete(app);                 
        end

        % Button pushed function: SliderButton_Suitability
        function SliderButton_SuitabilityPushed(app, event)
            Save(app);
            Suitability(app.EV,app.NEV,app.Vehicle1DropDown.Value, app.Vehicle2DropDown.Value, app.Vehicle3DropDown.Value, app.Pattern, app.Weightings);                       
            delete(app);                 
        end

        % Button pushed function: SliderButton_Design
        function SliderButton_DesignPushed(app, event)
            Save(app);
            Design(app.EV,app.NEV,app.Vehicle1DropDown.Value, app.Vehicle2DropDown.Value, app.Vehicle3DropDown.Value, app.Pattern, app.Weightings);                       
            delete(app);                 
        end

        % Button pushed function: GenerateVehiclePortfolioButton
        function GenerateVehiclePortfolioButtonPushed(app, event)
            Save(app);
            app.TD = Generatevehicleportfolio(app, app.Pattern);
            Vehicle_Portfolio(app.TD, app.Weightings, app.Pattern);
        end

        % Button pushed function: SliderButton_Safety
        function SliderButton_SafetyPushed(app, event)
             Save(app);
             Safety(app.EV,app.NEV,app.Vehicle1DropDown.Value, app.Vehicle2DropDown.Value, app.Vehicle3DropDown.Value, app.Pattern, app.Weightings);                               
             delete(app)
        end

        % Value changed function: Slider_Safety
        function Slider_SafetyValueChanged(app, event)
            runSpiderAPP(app,app.EV);
            app.EditField_Sicherheit.Value = app.Slider_Safety.Value;            
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            Data = {app.Pattern; app.Weightings; app.Vehicle1DropDown.Value; app.Vehicle2DropDown.Value; app.Vehicle3DropDown.Value};
            selpath = uigetdir;
            cd(selpath);
            save('Saved Vehicle.mat','Data');
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            [file,path,~] = uigetfile;
            filename = [path file];
            Data = importdata(filename);
            app.Pattern = Data{1,1};
            app.Weightings = Data{2,1};
            app.Vehicle1DropDown.Value = Data{3,1};
            app.Vehicle2DropDown.Value = Data{4,1};
            app.Vehicle3DropDown.Value = Data{5,1};

            %Set the Slider values and generate the Spider diagramm
            app=Set(app);
            
            %Set the Laps according to the chosen (if any) comparison vehicles
            Lamps(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COCATool = uifigure('Visible', 'off');
            app.COCATool.Name = 'COCA - Main';
            app.Spider_UIAxes = uiaxes(app.COCATool);
            title(app.Spider_UIAxes, 'Title')
            xlabel(app.Spider_UIAxes, 'X')
            ylabel(app.Spider_UIAxes, 'Y')
            app.Spider_UIAxes.XLim = [0 1];
            app.Spider_UIAxes.YLim = [0 1];
            app.Spider_UIAxes.ZLim = [0 1];
            app.Spider_UIAxes.CLim = [0 1];
            app.Spider_UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Spider_UIAxes.YTick = [0 0.5 1];
            app.Spider_UIAxes.ZTick = [0 0.5 1];
            app.COCALabel = uilabel(app.COCATool);
            app.COCALabel.Text = 'COCA';
            app.EditField_1 = uieditfield(app.COCATool, 'numeric');
            app.EditField_1.ValueDisplayFormat = '%2.1f';
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.EditField_1.Editable = 'off';
            app.EditField_2 = uieditfield(app.COCATool, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%2.1f';
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.COCATool, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%2.1f';
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.COCATool, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%2.1f';
            app.EditField_4.Editable = 'off';
            app.EditField_5 = uieditfield(app.COCATool, 'numeric');
            app.EditField_5.ValueDisplayFormat = '%2.1f';
            app.EditField_5.Editable = 'off';
            app.SliderButton_VehicleDynamics = uibutton(app.COCATool, 'push');
            app.SliderButton_VehicleDynamics.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_VehicleDynamicsPushed, true);
            app.SliderButton_VehicleDynamics.Text = 'Vehicle dynamics';
            app.SliderButton_Costs = uibutton(app.COCATool, 'push');
            app.SliderButton_Costs.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_CostsPushed, true);
            app.SliderButton_Costs.Text = 'Costs';
            app.SliderButton_Comfort = uibutton(app.COCATool, 'push');
            app.SliderButton_Comfort.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_ComfortPushed, true);
            app.SliderButton_Comfort.Text = 'Comfort';
            app.SliderButton_Suitability = uibutton(app.COCATool, 'push');
            app.SliderButton_Suitability.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_SuitabilityPushed, true);
            app.SliderButton_Suitability.Text = 'Suitability';
            app.SliderButton_Design = uibutton(app.COCATool, 'push');
            app.SliderButton_Design.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_DesignPushed, true);
            app.SliderButton_Design.Text = {'Design'; ''};
            app.ComparevehiclesPanel = uipanel(app.COCATool);
            app.ComparevehiclesPanel.Title = 'Compare vehicles';
            app.Vehicle1DropDownLabel = uilabel(app.ComparevehiclesPanel);
            app.Vehicle1DropDownLabel.Text = {'Vehicle 1'; ''};
            app.Vehicle1DropDown = uidropdown(app.ComparevehiclesPanel);
            app.Vehicle1DropDown.ValueChangedFcn = createCallbackFcn(app, @Vehicle1DropDownValueChanged, true);
            app.Vehicle2DropDownLabel = uilabel(app.ComparevehiclesPanel);
            app.Vehicle2DropDownLabel.Text = 'Vehicle 2';
            app.Vehicle2DropDown = uidropdown(app.ComparevehiclesPanel);
            app.Vehicle2DropDown.ValueChangedFcn = createCallbackFcn(app, @Vehicle2DropDownValueChanged, true);
            app.Vehicle3DropDownLabel = uilabel(app.ComparevehiclesPanel);
            app.Vehicle3DropDownLabel.Text = 'Vehicle 3';
            app.Vehicle3DropDown = uidropdown(app.ComparevehiclesPanel);
            app.Vehicle3DropDown.ValueChangedFcn = createCallbackFcn(app, @Vehicle3DropDownValueChanged, true);
            app.Lamp_V1 = uilamp(app.COCATool);
            app.Lamp_V2 = uilamp(app.COCATool);
            app.Lamp_V3 = uilamp(app.COCATool);
            app.GenerateVehiclePortfolioButton = uibutton(app.COCATool, 'push');
            app.GenerateVehiclePortfolioButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateVehiclePortfolioButtonPushed, true);
            app.GenerateVehiclePortfolioButton.Text = 'Generate Vehicle Portfolio';
            app.Gauge_Vehicle_Dynamics = uigauge(app.COCATool, 'linear');
            app.Gauge_Vehicle_Dynamics.Limits = [4 10];
            app.Gauge_Vehicle_Dynamics.Value = 4;
            app.Slider_Vehicle_dynamics = uislider(app.COCATool);
            app.Slider_Vehicle_dynamics.Limits = [4 10];
            app.Slider_Vehicle_dynamics.MajorTicks = [];
            app.Slider_Vehicle_dynamics.MajorTickLabels = {};
            app.Slider_Vehicle_dynamics.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.Slider_Vehicle_dynamics.MinorTicks = [];
            app.Slider_Vehicle_dynamics.Value = 7.5;
            app.Gauge_Costs = uigauge(app.COCATool, 'linear');
            app.Gauge_Costs.Limits = [4 10];
            app.Gauge_Costs.Value = 4;
            app.Slider_Costs = uislider(app.COCATool);
            app.Slider_Costs.Limits = [4 10];
            app.Slider_Costs.MajorTicks = [];
            app.Slider_Costs.MajorTickLabels = {};
            app.Slider_Costs.ValueChangedFcn = createCallbackFcn(app, @Slider_CostsValueChanged, true);
            app.Slider_Costs.MinorTicks = [];
            app.Slider_Costs.Value = 7.5;
            app.Gauge_Comfort = uigauge(app.COCATool, 'linear');
            app.Gauge_Comfort.Limits = [4 10];
            app.Gauge_Comfort.Value = 4;
            app.Slider_Comfort = uislider(app.COCATool);
            app.Slider_Comfort.Limits = [4 10];
            app.Slider_Comfort.MajorTicks = [];
            app.Slider_Comfort.MajorTickLabels = {};
            app.Slider_Comfort.ValueChangedFcn = createCallbackFcn(app, @Slider_ComfortValueChanged, true);
            app.Slider_Comfort.MinorTicks = [];
            app.Slider_Comfort.Value = 7.5;
            app.Gauge_Suitability = uigauge(app.COCATool, 'linear');
            app.Gauge_Suitability.Limits = [4 10];
            app.Gauge_Suitability.Value = 4;
            app.Slider_Suitability = uislider(app.COCATool);
            app.Slider_Suitability.Limits = [4 10];
            app.Slider_Suitability.MajorTicks = [];
            app.Slider_Suitability.MajorTickLabels = {};
            app.Slider_Suitability.ValueChangedFcn = createCallbackFcn(app, @Slider_SuitabilityValueChanged, true);
            app.Slider_Suitability.MinorTicks = [];
            app.Slider_Suitability.Value = 7.5;
            app.Gauge_Design = uigauge(app.COCATool, 'linear');
            app.Gauge_Design.Limits = [4 10];
            app.Gauge_Design.Value = 4;
            app.Slider_Design = uislider(app.COCATool);
            app.Slider_Design.Limits = [4 10];
            app.Slider_Design.MajorTicks = [];
            app.Slider_Design.MajorTickLabels = {};
            app.Slider_Design.ValueChangedFcn = createCallbackFcn(app, @Slider_DesignValueChanged, true);
            app.Slider_Design.MinorTicks = [];
            app.Slider_Design.Value = 7.5;
            app.ISLabel_5 = uilabel(app.COCATool);
            app.ISLabel_5.Text = 'IS';
            app.ISLabel_3 = uilabel(app.COCATool);
            app.ISLabel_3.Text = 'IS';
            app.ISLabel_4 = uilabel(app.COCATool);
            app.ISLabel_4.Text = 'IS';
            app.ISLabel_6 = uilabel(app.COCATool);
            app.ISLabel_6.Text = 'IS';
            app.ISLabel = uilabel(app.COCATool);
            app.ISLabel.Text = 'IS';
            app.SHOULD_1 = uilabel(app.COCATool);
            app.SHOULD_1.Text = 'SHOULD';
            app.SHOULD_2 = uilabel(app.COCATool);
            app.SHOULD_2.Text = 'SHOULD';
            app.SHOULDLabel_2 = uilabel(app.COCATool);
            app.SHOULDLabel_2.Text = 'SHOULD';
            app.SHOULDLabel = uilabel(app.COCATool);
            app.SHOULDLabel.Text = 'SHOULD';
            app.SHOULD_5 = uilabel(app.COCATool);
            app.SHOULD_5.Text = 'SHOULD';
            app.EditField_Sicherheit = uieditfield(app.COCATool, 'numeric');
            app.EditField_Sicherheit.ValueDisplayFormat = '%2.1f';
            app.EditField_Sicherheit.Editable = 'off';
            app.SliderButton_Safety = uibutton(app.COCATool, 'push');
            app.SliderButton_Safety.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_SafetyPushed, true);
            app.SliderButton_Safety.Text = 'Safety';
            app.Gauge_Safety = uigauge(app.COCATool, 'linear');
            app.Gauge_Safety.Limits = [4 10];
            app.Gauge_Safety.Value = 4;
            app.Slider_Safety = uislider(app.COCATool);
            app.Slider_Safety.Limits = [4 10];
            app.Slider_Safety.MajorTicks = [];
            app.Slider_Safety.MajorTickLabels = {};
            app.Slider_Safety.ValueChangedFcn = createCallbackFcn(app, @Slider_SafetyValueChanged, true);
            app.Slider_Safety.MinorTicks = [];
            app.Slider_Safety.Value = 7.5;
            app.ISLabel_2 = uilabel(app.COCATool);
            app.ISLabel_2.Text = 'IS';
            app.SollLabel_Sicherheit = uilabel(app.COCATool);
            app.SollLabel_Sicherheit.Text = 'SHOULD';
            app.ImportDataButton = uibutton(app.COCATool, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.ExportDataButton = uibutton(app.COCATool, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            % Show the figure after all components are created
            app.COCATool.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mainapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COCATool)

            % Execute the startup function
            runStartupFcn(app, @(app)Start(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COCATool)
        end
    end
end