classdef Suitability < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COCA_Suitability        matlab.ui.Figure
        Spider_UIAxes           matlab.ui.control.UIAxes
        Slider_TV               matlab.ui.control.Slider
        Slider_PL               matlab.ui.control.Slider
        Slider_NS               matlab.ui.control.Slider
        SuitabilityLabel        matlab.ui.control.Label
        EditField_TV            matlab.ui.control.NumericEditField
        EditField_PL            matlab.ui.control.NumericEditField
        EditField_NS            matlab.ui.control.NumericEditField
        SliderButton_TV         matlab.ui.control.Button
        SliderButton_PL         matlab.ui.control.Button
        SliderButton_NS         matlab.ui.control.Button
        TotalSuitability        matlab.ui.control.NumericEditField
        SuitabilitySHOULDLabel  matlab.ui.control.Label
        EditField_VT_2          matlab.ui.control.NumericEditField
        EditField_PL_2          matlab.ui.control.NumericEditField
        EditField_NS_2          matlab.ui.control.NumericEditField
        LabelVT                 matlab.ui.control.Label
        LabelPL                 matlab.ui.control.Label
        LabelNS                 matlab.ui.control.Label
        TotalSuitability_2      matlab.ui.control.NumericEditField
        GoBackButton            matlab.ui.control.Button
        SaveBackButton          matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        Slider_RA               matlab.ui.control.Slider
        EditField_RA            matlab.ui.control.NumericEditField
        SliderButton_RA         matlab.ui.control.Button
        EditField_RA_2          matlab.ui.control.NumericEditField
        LabelRA                 matlab.ui.control.Label
        Slider_CT               matlab.ui.control.Slider
        EditField_CT            matlab.ui.control.NumericEditField
        SliderButton_CT         matlab.ui.control.Button
        EditField_CT_2          matlab.ui.control.NumericEditField
        LabelCT                 matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        Lamp_V1                 matlab.ui.control.Lamp
        Lamp_V2                 matlab.ui.control.Lamp
        Lamp_V3                 matlab.ui.control.Lamp
        Label_V1                matlab.ui.control.Label
        Label_V2                matlab.ui.control.Label
        Label_V3                matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        DropDown_2              matlab.ui.control.DropDown
        DropDown_3              matlab.ui.control.DropDown
        DropDown_4              matlab.ui.control.DropDown
        DropDown_5              matlab.ui.control.DropDown
        UpdateWeightingButton   matlab.ui.control.StateButton
        Gauge                   matlab.ui.control.LinearGauge
        Slider_7                matlab.ui.control.Slider
        ISLabel                 matlab.ui.control.Label
    end

     properties (Access = private)
        CorrelationApp   % Correlation
        SLabel
        SValue
     end
     
     properties (Access = public)
        EV
        NEV
        ComparisonVehicle_1
        ComparisonVehicle_2
        ComparisonVehicle_3
        Slider7
        Value
        Label
        Pattern
        ResetPattern
        Weightings
    end

methods (Access = public)
    
    function UpdatefromCorrelation(app, SValue, SLabel, Pattern,ResetPattern)
    %%%Description: This function retrieves the values changed/corrected in the Correlation app and updates the spider diagramm!
        
    Unblock(app);
    
    
        if SValue == -1
            %It was not requested to update the values!
            
                        
        elseif isequal(SLabel,app.SliderButton_TV.Text)
            app.Slider_TV.Value = SValue;
            runSpiderAPP(app);
            app.EditField_TV.Value = app.Slider_TV.Value;
            app.EditField_VT_2.Value = app.Slider_TV.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
            
        elseif isequal(SLabel,app.SliderButton_PL.Text)
            app.Slider_PL.Value = SValue;
            runSpiderAPP(app);
            app.EditField_PL.Value = app.Slider_PL.Value;
            app.EditField_PL_2.Value = app.Slider_PL.Value;
            Weighting_Pattern(app); 
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
            
        elseif isequal(SLabel,app.SliderButton_NS.Text)
            app.Slider_NS.Value = SValue;
            runSpiderAPP(app);
            app.EditField_NS.Value = app.Slider_NS.Value;
            app.EditField_NS_2.Value = app.Slider_NS.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
            
        elseif isequal(SLabel,app.SliderButton_RA.Text)
            app.Slider_RA.Value = SValue;
            runSpiderAPP(app);
            app.EditField_RA.Value = app.Slider_RA.Value;
            app.EditField_RA_2.Value = app.Slider_RA.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
            
        elseif isequal(SLabel,app.SliderButton_CT.Text)
            app.Slider_CT.Value = SValue;
            runSpiderAPP(app);
            app.EditField_CT.Value = app.Slider_CT.Value;
            app.EditField_CT_2.Value = app.Slider_CT.Value;
            Weighting_Pattern(app);
            app.Pattern = Pattern;
            app.ResetPattern=ResetPattern;
                        
        end
        
    end
        
end

methods (Access = private)
    
function  SaveSuitability(app)
%%%Description: Function to save the results of the properties evaluation

    app.Pattern(2,4) = app.Slider_TV.Value;
    app.Pattern(3,4) = app.Slider_PL.Value;
    app.Pattern(4,4) = app.Slider_NS.Value;
    app.Pattern(5,4) = app.Slider_RA.Value;
    app.Pattern(6,4) = app.Slider_CT.Value;
    app.Pattern(1,4) = app.Slider_7.Value;

end
        
function Block(app)
%%%%Description: Function to block some components during the weighting factors update

    app.Slider_TV.Enable = 'off';
    app.Slider_PL.Enable = 'off';
    app.Slider_NS.Enable = 'off';
    app.Slider_RA.Enable = 'off';
    app.Slider_CT.Enable = 'off';
    app.Slider_7.Enable = 'off';
    
    app.SliderButton_TV.Enable = 'off';
    app.SliderButton_PL.Enable = 'off';
    app.SliderButton_NS.Enable = 'off';
    app.SliderButton_RA.Enable = 'off';
    app.SliderButton_CT.Enable = 'off';
    app.GoBackButton.Enable = 'off';
    app.SaveBackButton.Enable = 'off';
    app.ResetButton.Enable = 'off'; 

end

function Unblock(app)
%%%%Description: Function to unblock some components during the weighting factors update

    app.Slider_TV.Enable = 'on';
    app.Slider_PL.Enable = 'on';
    app.Slider_NS.Enable = 'on';
    app.Slider_RA.Enable = 'on';
    app.Slider_CT.Enable = 'on';
    app.Slider_7.Enable = 'on';
    
    app.SliderButton_TV.Enable = 'on';
    app.SliderButton_PL.Enable = 'on';
    app.SliderButton_NS.Enable = 'on';
    app.SliderButton_RA.Enable = 'on';
    app.SliderButton_CT.Enable = 'on';
    app.GoBackButton.Enable = 'on';
    app.SaveBackButton.Enable = 'on';
    app.ResetButton.Enable = 'on';

end
        
function NameLable(app)
%%% Description: This function regulates the Label name of the Update Weighting Paneö
%% Authors: Lorenzo Nicoletti, Maximilian Bayerlein

    W = app.Weightings{4,2};
    
    app.LabelVT.Text = {['Volume trunk x ', num2str(W(1,1))]};
    app.LabelPL.Text = {['+ payload x ', num2str(W(1,2))]};
    app.LabelNS.Text = {['+ number of seats x ', num2str(W(1,3))]};
    app.LabelRA.Text = {['+ range x ', num2str(W(1,4))]};
    app.LabelCT.Text = {['+ charging time x ', num2str(W(1,5))]};

end
      
function Weighting_Pattern(app)
%%% Description: This function simply updates the weightings
       
    %Retrieve the values of the Gauge (if they have been changed)
    V = [app.Slider_TV.Value; app.Slider_PL.Value; app.Slider_NS.Value; app.Slider_RA.Value; app.Slider_CT.Value];  % Wertevektor
    
    %Recalculate the resulting total value for the "Suitability" properties
    W =app.Weightings{4,2};
    app.TotalSuitability_2.Value = (W*V)/sum(W);
    app.Gauge.Value =  app.TotalSuitability_2.Value;            

end
    
function Lamps(app)
%%% Description: This function regulates the Lamp colors for the "Compare Vehicle" Panels
%% Authors: Lorenzo Nicoletti, Maximilian Bayerlein

    if ~isequal(app.ComparisonVehicle_1,'Please choose a vehicle')     
    
        app.Lamp_V1.Color = [0 1 1];
        app.Label_V1.Text=app.ComparisonVehicle_1;
    end
    
    if ~isequal(app.ComparisonVehicle_2,'Please choose a vehicle')
    
        app.Lamp_V2.Color = [0 1 0]; 
        app.Label_V2.Text=app.ComparisonVehicle_2;
    end
    
    if ~isequal(app.ComparisonVehicle_3,'Please choose a vehicle')
    
        app.Lamp_V3.Color = [1 1 0];       
        app.Label_V3.Text=app.ComparisonVehicle_3;
    end      

end
    
function [data, lbl] = runSpiderAPP(app)
%%% Description: This function Plots the spider Diagram in the Gui for the choosen vehicle concept and the chosen comparion vehicles
%% Author: Lorenzo Nicoletti, Maximilian Bayerlein
    
    num=app.EV;

    % Retrieve the labels for the spider diagramm -> the second level properties for the first level property "COSTS"
    idx=find(ismember(num(:,1),'Suitability'));
    idx_start=idx+1;
    lbl = [num(idx_start,1),num(idx_start+1,1),num(idx_start+2,1),num(idx_start+3,1),num(idx_start+4,1)]';

    % data
    A = [app.Slider_TV.Value app.Slider_PL.Value app.Slider_NS.Value app.Slider_RA.Value app.Slider_CT.Value]';

    %If any Compare vehicles are chosen, retrieve their value
    [V1,V2,V3, app] = Compare_Vehicles(app);
    data =  [V1 V2 V3 A];
    
    ax = app.Spider_UIAxes;
    hold on

    % plot spider diagramm
    spiderAPP(data,lbl,ax)

end

function [V1, V2, V3, app] = Compare_Vehicles(app)
%%% Description: This function adds the plots of the Comparison vehicles on the spider Diagramm
%Author: Lorenzo Nicoletti, Maximilian Bayerlein
    
    num=app.EV;

    %Find the position of the evaluated subproperties of the property "COSTS"
    idx=find(ismember(num(:,1),'Suitability'));
    idx_start=idx+1;
    idx_end=idx_start+numel(app.Weightings{4,2})-1;


    %Retrieve the data for Comparision vehicle number 1
    for ii = 4:size(num,2)
    
        if isequal(app.ComparisonVehicle_1, 'Please choose a vehicle')
            
            V1 =  [4 4 4 4 4]';
            break;
        
        elseif isequal(app.ComparisonVehicle_1, num{1,ii})
            
            V1 = cell2mat(num(idx_start:idx_end,ii));
            break; 
        end
    end

    %Retrieve the data for Comparision vehicle number 2
    for ii = 4:size(num,2)
    
        if isequal(app.ComparisonVehicle_2, 'Please choose a vehicle')
            
            V2 =  [4 4 4 4 4]';
            break;
        
        elseif isequal(app.ComparisonVehicle_2, num{1,ii})
            
            V2 = cell2mat(num(idx_start:idx_end,ii));
            break;
            
        end
    end

    %Retrieve the data for Comparision vehicle number 3
    for ii = 4:size(num,2)
        
        if isequal(app.ComparisonVehicle_3, 'Please choose a vehicle')
            
            V3 =  [4 4 4 4 4]';
            break;
            
        elseif isequal(app.ComparisonVehicle_3, num{1,ii})
            
            V3 = cell2mat(num(idx_start:idx_end,ii));
            break;
        end
    end
   
end 

function UpdateWeightingFactors(app)
%%%Description:This function allows to change the weighting factors of the second level properties of the first level property "COSTS"
%Author: Lorenzo Nicoletti, Maximilian Bayerlein

    %Assign the possible drop down values for the weighting factors
    app.DropDown.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown.Items = {'1', '3', '4', '5', '9'};
    app.DropDown_2.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_2.Items = {'1', '3', '4', '5', '9'};
    app.DropDown_3.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_3.Items = {'1', '3', '4', '5', '9'};
    app.DropDown_4.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_4.Items = {'1', '3', '4', '5', '9'};
    app.DropDown_5.ItemsData = [1, 3, 4, 5, 9];
    app.DropDown_5.Items = {'1', '3', '4', '5', '9'};
    
    if app.UpdateWeightingButton.Value == 0 %Button to change the weighting has been pressed for the second time (or not pressed at all)
    
        app.DropDown.Visible = 'off';
        app.DropDown_2.Visible = 'off';
        app.DropDown_3.Visible = 'off';
        app.DropDown_4.Visible = 'off';
        app.DropDown_5.Visible = 'off';

        %Update the weighting factors
        app.Weightings{4,2} = [app.DropDown.Value, app.DropDown_2.Value, app.DropDown_3.Value, app.DropDown_4.Value, app.DropDown_5.Value];
        
        %Update the Labels
        NameLable(app);
        
        %Reevaluate the custormer properties with the new weighting factors
        Weighting_Pattern(app);
        
        %Unblock the components blocked before (the first time the button was pressed)
        Unblock(app); 
    
    elseif app.UpdateWeightingButton.Value == 1 %Button to change the weighting has been pressed for the first time
    
        app.DropDown.Visible = 'on';
        app.DropDown_2.Visible = 'on';
        app.DropDown_3.Visible = 'on';
        app.DropDown_4.Visible = 'on';
        app.DropDown_5.Visible = 'on';
        
        %Update the Labels with the new weighting factors
        app.LabelVT.Text = {'Trunk volume x '};
        app.LabelPL.Text = {'+ payload x '};
        app.LabelNS.Text = {'+ number of seats x '};
        app.LabelRA.Text = {'+ range x '};
        app.LabelCT.Text = {'+ charging time x '};
        
        %Block the components which are not required -> The User can therefore only change the weighting factors
        Block(app);
    end
end
        
end %End of methods
    

    methods (Access = private)

        % Code that executes after component creation
        function Start(app, EV, NEV, ComparisonVehicle_1, ComparisonVehicle_2, ComparisonVehicle_3, Pattern, Weightings)
        %%%%Description this function will be exectued just after the GUI "COSTS is created. The function takes the values passed by Mainapp
        %and sets Slides and edit fields accordingly to what was chosen in Mainapp
        %Author: Lorenzo Nicoletti, Maximilian Bayerlein

            app.EV = EV;
            app.NEV= NEV;
            app.ComparisonVehicle_1 = ComparisonVehicle_1;
            app.ComparisonVehicle_2 = ComparisonVehicle_2;
            app.ComparisonVehicle_3 = ComparisonVehicle_3;
            app.Pattern = Pattern;
            app.Weightings = Weightings;
            app.ResetPattern=Pattern;

            %Set Slider Values 
            app.Slider_TV.Value = app.Pattern(2,4);
            app.Slider_PL.Value = app.Pattern(3,4);
            app.Slider_NS.Value = app.Pattern(4,4);
            app.Slider_RA.Value = app.Pattern(5,4);
            app.Slider_CT.Value = app.Pattern(6,4);
            app.Slider_7.Value = app.Pattern(1,4);
            
            %Set Corresponding Edit Field Values
            app.EditField_TV.Value = app.Slider_TV.Value;
            app.EditField_PL.Value = app.Slider_PL.Value;
            app.EditField_NS.Value = app.Slider_NS.Value;
            app.EditField_RA.Value = app.Slider_RA.Value;
            app.EditField_CT.Value = app.Slider_CT.Value;
            app.TotalSuitability.Value = app.Slider_7.Value;
            app.EditField_VT_2.Value = app.Slider_TV.Value;
            app.EditField_PL_2.Value = app.Slider_PL.Value;
            app.EditField_NS_2.Value = app.Slider_NS.Value;
            app.EditField_RA_2.Value = app.Slider_RA.Value;
            app.EditField_CT_2.Value = app.Slider_CT.Value;

            %Assign missing Labels
            NameLable(app);
    
            %Runs Spider app and generate Spider Diagramm
            runSpiderAPP(app);
    
            %Weight the Properties
            Weighting_Pattern(app);      
    
            %Set the Lamp colors according to the number of selected vehicles
            Lamps(app);

            %Initialize the Weighting and the Drop down Values of the Panel "Update Weighting Factors"
            app.DropDown.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_2.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_2.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_3.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_3.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_4.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_4.Items = {'1', '3', '4', '5', '9'};
            app.DropDown_5.ItemsData = [1, 3, 4, 5, 9];
            app.DropDown_5.Items = {'1', '3', '4', '5', '9'};

            W = app.Weightings{4,2};
            app.DropDown.Value = W(1); app.DropDown_2.Value = W(2); app.DropDown_3.Value = W(3); app.DropDown_4.Value = W(4); app.DropDown_5.Value = W(5);         
            
            UpdateWeightingFactors(app);
            
        end

        % Value changed function: EditField_TV, Slider_TV
        function Slider_1ValueChanged(app, event)
            runSpiderAPP(app);
            app.EditField_TV.Value = app.Slider_TV.Value;
            app.EditField_VT_2.Value = app.Slider_TV.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_PL
        function Slider_PLValueChanged(app, event)
            runSpiderAPP(app);
            app.EditField_PL.Value = app.Slider_PL.Value;
            app.EditField_PL_2.Value = app.Slider_PL.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_NS
        function Slider_NSValueChanged(app, event)
            runSpiderAPP(app);
            app.EditField_NS.Value = app.Slider_NS.Value;
            app.EditField_NS_2.Value = app.Slider_NS.Value;
            Weighting_Pattern(app);
        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
            runSpiderAPP(app);
            app.TotalSuitability.Value = app.Slider_7.Value;                       
        end

        % Button pushed function: SliderButton_TV
        function SliderButton_TVPushed(app, event)
            app.Value = app.Slider_TV.Value;
            app.Label = app.SliderButton_TV.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: SliderButton_PL
        function SliderButton_PLPushed(app, event)
            app.Value = app.Slider_PL.Value;
            app.Label = app.SliderButton_PL.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: SliderButton_NS
        function SliderButton_NSPushed(app, event)
            app.Value = app.Slider_NS.Value;
            app.Label = app.SliderButton_NS.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            Mainapp(app.Pattern, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Weightings);
            delete(app.CorrelationApp);
            delete(app);
        end

        % Button pushed function: SaveBackButton
        function SaveBackButtonPushed(app, event)
            SaveSuitability(app);
            Mainapp(app.Pattern, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Weightings);
            delete(app.CorrelationApp);
            delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            app.Slider_TV.Value = app.ResetPattern(2,4);
            app.Slider_PL.Value = app.ResetPattern(3,4);
            app.Slider_NS.Value = app.ResetPattern(4,4);
            app.Slider_RA.Value = app.ResetPattern(5,4);
            app.Slider_CT.Value = app.Pattern(6,4);
            app.Slider_7.Value = app.ResetPattern(1,4);
            app.Pattern=app.ResetPattern;
            app.Gauge.Value=app.ResetPattern(1,4);
            app.TotalSuitability_2.Value=app.ResetPattern(1,4);
            
            app.EditField_TV.Value = app.Slider_TV.Value;
            app.EditField_PL.Value = app.Slider_PL.Value;
            app.EditField_NS.Value = app.Slider_NS.Value;
            app.EditField_RA.Value = app.Slider_RA.Value;
            app.EditField_CT.Value = app.Slider_CT.Value;
            app.TotalSuitability.Value = app.Slider_7.Value;
            
            app.EditField_VT_2.Value = app.Slider_TV.Value;
            app.EditField_PL_2.Value = app.Slider_PL.Value;
            app.EditField_NS_2.Value = app.Slider_NS.Value;  
            app.EditField_RA_2.Value = app.Slider_RA.Value;  
            app.EditField_CT_2.Value = app.Slider_CT.Value;  
            
            runSpiderAPP(app);
            Weighting_Pattern(app);
        end

        % Close request function: COCA_Suitability
        function COCA_SuitabilityCloseRequest(app, event)
            delete(app.CorrelationApp);
            delete(app);           
        end

        % Value changed function: Slider_RA
        function Slider_RAValueChanged(app, event)
            runSpiderAPP(app);
            app.EditField_RA.Value = app.Slider_RA.Value;
            app.EditField_RA_2.Value = app.Slider_RA.Value;
            Weighting_Pattern(app);
        end

        % Button pushed function: SliderButton_RA
        function SliderButton_RAPushed(app, event)
            app.Value = app.Slider_RA.Value;
            app.Label = app.SliderButton_RA.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Value changed function: Slider_CT
        function Slider_CTValueChanged(app, event)
            runSpiderAPP(app);
            app.EditField_CT.Value = app.Slider_CT.Value;
            app.EditField_CT_2.Value = app.Slider_CT.Value;
            Weighting_Pattern(app);         
        end

        % Button pushed function: SliderButton_CT
        function SliderButton_CTPushed(app, event)
            app.Value = app.Slider_CT.Value;
            app.Label = app.SliderButton_CT.Text;
            Block(app);
            app.CorrelationApp = Correlation(app, app.EV,app.NEV, app.ComparisonVehicle_1, app.ComparisonVehicle_2, app.ComparisonVehicle_3, app.Value, app.Label, app.Pattern,app.ResetPattern);
        end

        % Value changed function: UpdateWeightingButton
        function UpdateWeightingButtonValueChanged(app, event)
            UpdateWeightingFactors(app);             
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COCA_Suitability = uifigure;
            app.COCA_Suitability.Name = 'UI Figure';
            app.COCA_Suitability.CloseRequestFcn = createCallbackFcn(app, @COCA_SuitabilityCloseRequest, true);
            app.Spider_UIAxes = uiaxes(app.COCA_Suitability);
            title(app.Spider_UIAxes, 'Title')
            xlabel(app.Spider_UIAxes, 'X')
            ylabel(app.Spider_UIAxes, 'Y')
            app.Spider_UIAxes.XLim = [0 1];
            app.Spider_UIAxes.YLim = [0 1];
            app.Spider_UIAxes.ZLim = [0 1];
            app.Spider_UIAxes.CLim = [0 1];
            app.Spider_UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Spider_UIAxes.YTick = [0 0.5 1];
            app.Spider_UIAxes.ZTick = [0 0.5 1];
            app.Slider_TV = uislider(app.COCA_Suitability);
            app.Slider_TV.Limits = [4 10];
            app.Slider_TV.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_TV.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.Slider_TV.Value = 7.5;
            app.Slider_PL = uislider(app.COCA_Suitability);
            app.Slider_PL.Limits = [4 10];
            app.Slider_PL.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_PL.ValueChangedFcn = createCallbackFcn(app, @Slider_PLValueChanged, true);
            app.Slider_PL.Value = 7.5;
            app.Slider_NS = uislider(app.COCA_Suitability);
            app.Slider_NS.Limits = [4 10];
            app.Slider_NS.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_NS.ValueChangedFcn = createCallbackFcn(app, @Slider_NSValueChanged, true);
            app.Slider_NS.Value = 7.5;
            app.SuitabilityLabel = uilabel(app.COCA_Suitability);
            app.SuitabilityLabel.Tooltip = {'KOKEP - Alltagstauglichkeit'};
            app.SuitabilityLabel.Text = 'Suitability';
            app.EditField_TV = uieditfield(app.COCA_Suitability, 'numeric');
            app.EditField_TV.ValueDisplayFormat = '%2.1f';
            app.EditField_TV.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.EditField_TV.Editable = 'off';
            app.EditField_PL = uieditfield(app.COCA_Suitability, 'numeric');
            app.EditField_PL.ValueDisplayFormat = '%2.1f';
            app.EditField_PL.Editable = 'off';
            app.EditField_NS = uieditfield(app.COCA_Suitability, 'numeric');
            app.EditField_NS.ValueDisplayFormat = '%2.1f';
            app.EditField_NS.Editable = 'off';
            app.SliderButton_TV = uibutton(app.COCA_Suitability, 'push');
            app.SliderButton_TV.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_TVPushed, true);
            app.SliderButton_TV.Text = 'Trunk volume';
            app.SliderButton_PL = uibutton(app.COCA_Suitability, 'push');
            app.SliderButton_PL.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_PLPushed, true);
            app.SliderButton_PL.Text = 'Payload';
            app.SliderButton_NS = uibutton(app.COCA_Suitability, 'push');
            app.SliderButton_NS.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_NSPushed, true);
            app.SliderButton_NS.Text = 'Number of seats';
            app.TotalSuitability = uieditfield(app.COCA_Suitability, 'numeric');
            app.TotalSuitability.ValueDisplayFormat = '%2.1f';
            app.TotalSuitability.Editable = 'off';
            app.SuitabilitySHOULDLabel = uilabel(app.COCA_Suitability);
            app.SuitabilitySHOULDLabel.Text = 'Suitability -SHOULD';
            app.EditField_VT_2 = uieditfield(app.COCA_Suitability, 'numeric');
            app.EditField_VT_2.ValueDisplayFormat = '%2.1f';
            app.EditField_VT_2.Editable = 'off';
            app.EditField_PL_2 = uieditfield(app.COCA_Suitability, 'numeric');
            app.EditField_PL_2.ValueDisplayFormat = '%2.1f';
            app.EditField_PL_2.Editable = 'off';
            app.EditField_NS_2 = uieditfield(app.COCA_Suitability, 'numeric');
            app.EditField_NS_2.ValueDisplayFormat = '%2.1f';
            app.EditField_NS_2.Editable = 'off';
            app.LabelVT = uilabel(app.COCA_Suitability);
            app.LabelVT.Text = 'Label2';
            app.LabelPL = uilabel(app.COCA_Suitability);
            app.LabelPL.Text = 'Label2';
            app.LabelNS = uilabel(app.COCA_Suitability);
            app.LabelNS.Text = 'Label2';
            app.TotalSuitability_2 = uieditfield(app.COCA_Suitability, 'numeric');
            app.TotalSuitability_2.ValueDisplayFormat = '%2.2f';
            app.TotalSuitability_2.Editable = 'off';
            app.GoBackButton = uibutton(app.COCA_Suitability, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.SaveBackButton = uibutton(app.COCA_Suitability, 'push');
            app.SaveBackButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBackButtonPushed, true);
            app.SaveBackButton.Text = 'Save & Back';
            app.ResetButton = uibutton(app.COCA_Suitability, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Slider_RA = uislider(app.COCA_Suitability);
            app.Slider_RA.Limits = [4 10];
            app.Slider_RA.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_RA.ValueChangedFcn = createCallbackFcn(app, @Slider_RAValueChanged, true);
            app.Slider_RA.Value = 7.5;
            app.EditField_RA = uieditfield(app.COCA_Suitability, 'numeric');
            app.EditField_RA.ValueDisplayFormat = '%2.1f';
            app.EditField_RA.Editable = 'off';
            app.SliderButton_RA = uibutton(app.COCA_Suitability, 'push');
            app.SliderButton_RA.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_RAPushed, true);
            app.SliderButton_RA.Text = 'Range';
            app.EditField_RA_2 = uieditfield(app.COCA_Suitability, 'numeric');
            app.EditField_RA_2.ValueDisplayFormat = '%2.1f';
            app.EditField_RA_2.Editable = 'off';
            app.LabelRA = uilabel(app.COCA_Suitability);
            app.LabelRA.Text = 'Label2';
            app.Slider_CT = uislider(app.COCA_Suitability);
            app.Slider_CT.Limits = [4 10];
            app.Slider_CT.MajorTickLabels = {'4', '5', '6', '7', '8', '9', '10'};
            app.Slider_CT.ValueChangedFcn = createCallbackFcn(app, @Slider_CTValueChanged, true);
            app.Slider_CT.Value = 7.5;
            app.EditField_CT = uieditfield(app.COCA_Suitability, 'numeric');
            app.EditField_CT.ValueDisplayFormat = '%2.1f';
            app.EditField_CT.Editable = 'off';
            app.SliderButton_CT = uibutton(app.COCA_Suitability, 'push');
            app.SliderButton_CT.ButtonPushedFcn = createCallbackFcn(app, @SliderButton_CTPushed, true);
            app.SliderButton_CT.Text = 'Charging time';
            app.EditField_CT_2 = uieditfield(app.COCA_Suitability, 'numeric');
            app.EditField_CT_2.ValueDisplayFormat = '%2.1f';
            app.EditField_CT_2.Editable = 'off';
            app.LabelCT = uilabel(app.COCA_Suitability);
            app.LabelCT.Text = 'Label2';
            app.Panel = uipanel(app.COCA_Suitability);
            app.Lamp_V1 = uilamp(app.Panel);
            app.Lamp_V2 = uilamp(app.Panel);
            app.Lamp_V3 = uilamp(app.Panel);
            app.Label_V1 = uilabel(app.Panel);
            app.Label_V1.Text = 'Label3';
            app.Label_V2 = uilabel(app.Panel);
            app.Label_V2.Text = 'Label3';
            app.Label_V3 = uilabel(app.Panel);
            app.Label_V3.Text = 'Label3';
            app.DropDown = uidropdown(app.COCA_Suitability);
            app.DropDown_2 = uidropdown(app.COCA_Suitability);
            app.DropDown_3 = uidropdown(app.COCA_Suitability);
            app.DropDown_4 = uidropdown(app.COCA_Suitability);
            app.DropDown_5 = uidropdown(app.COCA_Suitability);
            app.UpdateWeightingButton = uibutton(app.COCA_Suitability, 'state');
            app.UpdateWeightingButton.ValueChangedFcn = createCallbackFcn(app, @UpdateWeightingButtonValueChanged, true);
            app.UpdateWeightingButton.Text = 'Update Weighting';
            app.Gauge = uigauge(app.COCA_Suitability, 'linear');
            app.Gauge.Limits = [4 10];
            app.Gauge.Value = 4;
            app.Slider_7 = uislider(app.COCA_Suitability);
            app.Slider_7.Limits = [4 10];
            app.Slider_7.MajorTicks = [];
            app.Slider_7.MajorTickLabels = {};
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.Slider_7.MinorTicks = [];
            app.Slider_7.Value = 7.5;
            app.ISLabel = uilabel(app.COCA_Suitability);
            app.ISLabel.Text = '-IS';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Suitability(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COCA_Suitability)

            % Execute the startup function
            runStartupFcn(app, @(app)Start(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COCA_Suitability)
        end
    end
end